classdef imaging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImagingmethodsPanel             matlab.ui.container.Panel
        DOTreconstructionPanel          matlab.ui.container.Panel
        EPSEditField                    matlab.ui.control.NumericEditField
        EPSEditFieldLabel               matlab.ui.control.Label
        MaxIterationEditField           matlab.ui.control.NumericEditField
        MaxIterationEditFieldLabel      matlab.ui.control.Label
        ReconEvaluationButton           matlab.ui.control.Button
        L1Button                        matlab.ui.control.Button
        AutoEncoderButton               matlab.ui.control.Button
        CGDREGButton                    matlab.ui.control.Button
        CGDButton                       matlab.ui.control.Button
        ShowabsorptionCheckBox          matlab.ui.control.CheckBox
        LargeCheckBox                   matlab.ui.control.CheckBox
        FlipMatricesCheckBox            matlab.ui.control.CheckBox
        SegmentationsinglemearsurementclassificationPanel  matlab.ui.container.Panel
        MeasureUSButton                 matlab.ui.control.Button
        SemisegmentButton               matlab.ui.control.Button
        WithHistoclassificationButton   matlab.ui.control.Button
        TargetprocessButton             matlab.ui.control.Button
        WithReconclassificationButton   matlab.ui.control.Button
        USCropButton                    matlab.ui.control.Button
        TartoPertButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AbsorbscatterTab                matlab.ui.container.Tab
        AbsorptionScatteringCoefficientsPanel  matlab.ui.container.Panel
        ELPconfidenceintervalEditField  matlab.ui.control.NumericEditField
        ELPconfidenceintervalEditFieldLabel  matlab.ui.control.Label
        refmuspTextArea                 matlab.ui.control.TextArea
        muspLabel                       matlab.ui.control.Label
        refmuaTextArea                  matlab.ui.control.TextArea
        muaTextAreaLabel                matlab.ui.control.Label
        ELPRemovalCheckBox              matlab.ui.control.CheckBox
        LOFRemovalCheckBox              matlab.ui.control.CheckBox
        KmeansOutliersRemovalCheckBox   matlab.ui.control.CheckBox
        RefcoefficientPanel             matlab.ui.container.Panel
        musp830                         matlab.ui.control.TextArea
        musp808                         matlab.ui.control.TextArea
        musp780                         matlab.ui.control.TextArea
        musp740                         matlab.ui.control.TextArea
        mua830                          matlab.ui.control.TextArea
        mua808                          matlab.ui.control.TextArea
        mua780                          matlab.ui.control.TextArea
        mua740                          matlab.ui.control.TextArea
        MuaLabel                        matlab.ui.control.Label
        MuspLabel                       matlab.ui.control.Label
        TarabsorptionPanel              matlab.ui.container.Panel
        meanab830                       matlab.ui.control.TextArea
        meanab808                       matlab.ui.control.TextArea
        meanab780                       matlab.ui.control.TextArea
        meanab740                       matlab.ui.control.TextArea
        maxab830                        matlab.ui.control.TextArea
        maxab808                        matlab.ui.control.TextArea
        maxab780                        matlab.ui.control.TextArea
        maxab740                        matlab.ui.control.TextArea
        nmLabel_4                       matlab.ui.control.Label
        nmLabel_3                       matlab.ui.control.Label
        nmLabel_2                       matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        MaxLabel_2                      matlab.ui.control.Label
        MeanLabel_2                     matlab.ui.control.Label
        meanhb                          matlab.ui.control.TextArea
        maxhb                           matlab.ui.control.TextArea
        MaxLabel_3                      matlab.ui.control.Label
        MeanLabel_3                     matlab.ui.control.Label
        HemoglobinLabel                 matlab.ui.control.Label
        TotalTab                        matlab.ui.container.Tab
        TotalhemoglobinPanel            matlab.ui.container.Panel
        OxyTab                          matlab.ui.container.Tab
        OxyhemoglobinPanel              matlab.ui.container.Panel
        DeoxyTab                        matlab.ui.container.Tab
        DeoxyhemoglobinPanel            matlab.ui.container.Panel
        LesionDimensionsPanel           matlab.ui.container.Panel
        ndepthEditField                 matlab.ui.control.NumericEditField
        ndepthEditFieldLabel            matlab.ui.control.Label
        xygridsizeEditField             matlab.ui.control.NumericEditField
        xygridsizeEditFieldLabel        matlab.ui.control.Label
        centerdepthEditField            matlab.ui.control.NumericEditField
        centerdepthEditFieldLabel       matlab.ui.control.Label
        ycenterposEditField             matlab.ui.control.NumericEditField
        ycenterposEditFieldLabel        matlab.ui.control.Label
        xcenterposEditField             matlab.ui.control.NumericEditField
        xcenterposEditFieldLabel        matlab.ui.control.Label
        ztargetradiusEditField          matlab.ui.control.NumericEditField
        ztargetradiusLabel              matlab.ui.control.Label
        yradiusEditField                matlab.ui.control.NumericEditField
        yradiusEditFieldLabel           matlab.ui.control.Label
        xradiusEditField                matlab.ui.control.NumericEditField
        xradiusEditFieldLabel           matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        MeshLabel                       matlab.ui.control.Label
        PerturbationdisplayPanel        matlab.ui.container.Panel
        SelectPointButton               matlab.ui.control.Button
        DeletePointButton               matlab.ui.control.Button
        UndoLastButton                  matlab.ui.control.Button
        UndoAllButton                   matlab.ui.control.Button
        numofundo                       matlab.ui.control.TextArea
        FittingUnwrapButton             matlab.ui.control.Button
        FittingDeleteButton             matlab.ui.control.Button
        ClusterUnwrapButton             matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        Realpart                        matlab.ui.control.UIAxes
        Imagpart                        matlab.ui.control.UIAxes
        pert                            matlab.ui.control.UIAxes
        AutoimagingButton               matlab.ui.control.Button
        RefTextArea                     matlab.ui.control.TextArea
        RefTextAreaLabel                matlab.ui.control.Label
        SourceWavelengthsButtonGroup    matlab.ui.container.ButtonGroup
        nm830Button                     matlab.ui.control.RadioButton
        nm808Button                     matlab.ui.control.RadioButton
        nm780Button                     matlab.ui.control.RadioButton
        nm740Button                     matlab.ui.control.RadioButton
        TarDropDown                     matlab.ui.control.DropDown
        TarDropDownLabel                matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp
        us_features
        z_radius
        target_depth
        width1
        width2
        width3
        width4
        width5
        volume74
        volume78
        volume80
        volume83
        hemo
        abs74
        abs78
        abs80
        abs83
        mua074
        mua078
        mua080
        mua083
        mus074
        mus078
        mus080
        mus083
        us_predict
    end 
    
    properties (Access = private)
        MeasureApp
        nvx
        nvy
        vx
        vy
        v_tot
        Nmeas74
        Nmeas78
        Nmeas80
        Nmeas83
        selected_axis
        xbounds
        ybounds
        undoStructCell
        undoSeq
        count740
        count780
        count808
        count830
        initial_targetFileStruct
        output_pert74
        output_pert78
        output_pert80
        output_pert83
        cali_tardata
        targetFileStruct
        pairs
        source
        detect
        s_geom
        d_geom
        sddist_matrix
        sddist
        sddist74
        sddist78
        sddist80
        sddist83
        pairs74
        pairs78
        pairs80
        pairs83
        Measmnt74
        Measmnt78
        Measmnt80
        Measmnt83
        pert74
        pert78
        pert80
        pert83
        data74
        data78
        data80
        data83
        tarmode
        prob74
        prob78
        prob80
        prob83
        origdata
        img_path
        img_crop
        
        histo_predict
        histo_features
        first_pred
        recon_pred
        recon_features
        final_pred
    end
    
    
    methods (Access = private)
        
        function plot_result(app,axes,x,y,axes_title,color)
            cla(axes);
            pos = get(axes,'InnerPosition');
            plot(axes,x,y,color);
            grid(axes,'on')
            xlim(axes,[floor(min(x)) ceil(max(x))])
            title(axes,axes_title)
            set(axes,'InnerPosition',pos);
        end
        
        function circle(app,axes_handle,x,y,r)
        %x and y are the coordinates of the center of the circle
        %r is the radius of the circle
        %0.01 is the angle step, bigger values will draw the circle faster but
        %you might notice imperfections (not very smooth)
        	ang = 0:0.01:2*pi; 
        	xp = r*cos(ang);
        	yp = r*sin(ang);
        	plot(axes_handle,x+xp,y+yp);
        end

        function [undo_struct] = getCurrentDisplayInfo(app,targetFileStruct)

            undo_struct = struct(); % will have 7 file info parameters, targetFileSelected, outliersRemoval, wavelength, pert, sddist, measmnt and index
            
            targetFileSelected = 1;
            outliersRemoval = app.KmeansOutliersRemovalCheckBox.Value;
            LOFRemoval = app.LOFRemovalCheckBox.Value;
            ELPRemoval = app.ELPRemovalCheckBox.Value;
            undo_struct.index = [app.count740, app.count780, app.count808, app.count830];
            undo_struct.targetFileSelected = targetFileSelected;
            undo_struct.outliersRemoval = outliersRemoval;
            undo_struct.LOFRemoval = LOFRemoval;
            undo_struct.ELPRemoval = ELPRemoval;
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            end
            if app.nm740Button.Value
                undo_struct.wavelength = 740;
                if outliersRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert740;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist740;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt740;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs740;
                elseif LOFRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert740L;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist740L;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt740L;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs740L;
                elseif ELPRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert740E;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist740E;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt740E;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs740E;
                else
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert740A;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist740A;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt740A;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs740A;
                end
            elseif app.nm780Button.Value
                undo_struct.wavelength = 780;
                if outliersRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert780;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist780;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt780;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs780;
                elseif LOFRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert780L;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist780L;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt780L;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs780L;
                elseif ELPRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert780E;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist780E;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt780E;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs780E;
                else
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert780A;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist780A;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt780A;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs780A;
                end
            elseif app.nm808Button.Value
                undo_struct.wavelength = 808;
                if outliersRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert808;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist808;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt808;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs808;
                elseif LOFRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert808L;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist808L;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt808L;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs808L;
                elseif ELPRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert780E;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist780E;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt780E;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs808E;
                else
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert808A;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist808A;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt808A;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs808A;
                end
            elseif app.nm830Button.Value
                undo_struct.wavelength = 830;
                if outliersRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert830;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist830;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt830;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs830;
                elseif LOFRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert830L;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist830L;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt830L;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs830L;
                elseif ELPRemoval == 1
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert830E;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist830E;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt830E;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs830E;
                else
                    undo_struct.pert = targetFileStruct(targetFileSelected).pert830A;
                    undo_struct.sddist = targetFileStruct(targetFileSelected).sddist830A;
                    undo_struct.Measmnt = targetFileStruct(targetFileSelected).Measmnt830A;
                    undo_struct.pairs = targetFileStruct(targetFileSelected).pairs830A;
                end
            end 
        end
        
        function plotterShowClusters(app,axes_handle,x,y,label,x_lab,y_lab)

            cla(axes_handle);
            axes(axes_handle);  %Plots data on the axes with the input argument axes_handle
            
            % these condition should match the ones in applyOutlierKMean7.m
            k_value = 7;                % k-mean cluster numbers
            dist_value_inner = 0.7;     % distance from (0, 0)
            dist_value_outer = 1.1;     % distance from (0, 0)
            c_value = 10;               % member threshold to eliminate
            
            data = [transpose(x),transpose(y)];             % data = [real(pert)',imag(pert)'];
            [idx,C] = kmeans(data,k_value);
            hold(axes_handle,'on')
            
            plot(axes_handle,data(idx==1,1),data(idx==1,2),'r*','MarkerSize',3);
            plot(axes_handle,data(idx==2,1),data(idx==2,2),'b*','MarkerSize',3);
            plot(axes_handle,data(idx==3,1),data(idx==3,2),'c*','MarkerSize',3);
            plot(axes_handle,data(idx==4,1),data(idx==4,2),'ro','MarkerSize',3);
            plot(axes_handle,data(idx==5,1),data(idx==5,2),'bo','MarkerSize',3);
            plot(axes_handle,data(idx==6,1),data(idx==6,2),'co','MarkerSize',3);
            plot(axes_handle,data(idx==7,1),data(idx==7,2),'g*','MarkerSize',3);
            
            circle(app,axes_handle,0,0,0.5);
            circle(app,axes_handle,0,0,1);
            title(axes_handle,label)
            xlabel(axes_handle,x_lab);
            ylabel(axes_handle,y_lab);
            %	legend('Cluster1','Cluster2','Cluster3','Cluster4','Cluster5','Cluster6','Cluster7');
            
            if ~isempty(strfind(label,'Im(Pert) vs. Re(Pert)'))
                set(axes_handle, 'NextPlot', 'add');    % "hold on" *before* plot!
                circle(app,axes_handle,0,0,1);
            end
            hold(axes_handle,'off')
        end
        
        function [targetFileStruct] = setCurrentDisplayInfo(app,info_struct)
            % info_struct has 7 file info targetFileSelected,
            % outliersRemoval, wavelength, pert, sddist, measmnt and index
            
            targetFileStruct = app.targetFileStruct;   % targetFileStruct array
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            end
            if info_struct.wavelength == 740
                if info_struct.outliersRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert740 = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist740 = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt740 = info_struct.Measmnt;
                elseif info_struct.LOFRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert740L = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist740L = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt740L = info_struct.Measmnt;
                elseif info_struct.ELPRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert740E = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist740E = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt740E = info_struct.Measmnt;
                else
                    targetFileStruct(info_struct.targetFileSelected).pert740A = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist740A = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt740A = info_struct.Measmnt;
                end
            elseif info_struct.wavelength == 780
                if info_struct.outliersRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert780 = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist780 = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt780 = info_struct.Measmnt;
                elseif info_struct.LOFRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert780L = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist780L = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt780L = info_struct.Measmnt;
                elseif info_struct.ELPRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert780E = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist780E = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt780E = info_struct.Measmnt;
                else
                    targetFileStruct(info_struct.targetFileSelected).pert780A = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist780A = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt780A = info_struct.Measmnt;
                end
            elseif info_struct.wavelength == 808
                if info_struct.outliersRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert808 = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist808 = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt808 = info_struct.Measmnt;
                elseif info_struct.LOFRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert808L = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist808L = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt808L = info_struct.Measmnt;
                elseif info_struct.ELPRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert808E = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist808E = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt808E = info_struct.Measmnt;
                else
                    targetFileStruct(info_struct.targetFileSelected).pert808A = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist808A = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt808A = info_struct.Measmnt;
                end
            elseif info_struct.wavelength == 830
                if info_struct.outliersRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert830 = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist830 = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt830 = info_struct.Measmnt;
                elseif info_struct.LOFRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert830L = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist830L = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt830L = info_struct.Measmnt;
                elseif info_struct.ELPRemoval == 1
                    targetFileStruct(info_struct.targetFileSelected).pert830E = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist830E = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt830E = info_struct.Measmnt;
                else
                    targetFileStruct(info_struct.targetFileSelected).pert830A = info_struct.pert;
                    targetFileStruct(info_struct.targetFileSelected).sddist830A = info_struct.sddist;
                    targetFileStruct(info_struct.targetFileSelected).Measmnt830A = info_struct.Measmnt;
                end
            end
        end
        
        function  [flip_state,large_mapping,two_layer,layerDepth,mua1,mua2,musp1,musp2,xy_x_radius,xy_y_radius,...
                z_radius,depth,n_depth,xy_grid,xcenter_position,ycenter_position,outliers_removal,LOFRemoval,ELPRemoval,itmax,epsPercent,show_absorb] = getOnscreenParameters(app)
            flip_state = app.FlipMatricesCheckBox.Value;
            outliers_removal = app.KmeansOutliersRemovalCheckBox.Value;
            LOFRemoval = app.LOFRemovalCheckBox.Value;
            ELPRemoval = app.ELPRemovalCheckBox.Value;
            large_mapping = app.LargeCheckBox.Value;
            show_absorb = app.ShowabsorptionCheckBox.Value;
            two_layer = 0;
            layerDepth = 2;
            mua1 = 0.02;
            mua2 = 0.06;
            musp1 = 6;
            musp2 = 10;
            xy_x_radius = app.xradiusEditField.Value;
            xy_y_radius = app.yradiusEditField.Value;
            z_radius = app.ztargetradiusEditField.Value;
            depth = app.centerdepthEditField.Value;
            n_depth = app.ndepthEditField.Value;
            xy_grid = app.xygridsizeEditField.Value;
            xcenter_position = app.xcenterposEditField.Value;
            ycenter_position = app.ycenterposEditField.Value;
            
            itmax = app.MaxIterationEditField.Value;
%             if itmax > 5.0 || itmax < 3.0
%                 itmax = 4.0;
%             end
            epsPercent = app.EPSEditField.Value;
            
        end
        
        function draw_pert(app,targetFileStruct)
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            end
            if app.nm740Button.Value
                name = '740nm';
                app.refmuaTextArea.Value = num2str(app.mua074);
                app.refmuspTextArea.Value = num2str(app.mus074);
                if app.KmeansOutliersRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist740;
                    real_pert = real(targetFileStruct(1).pert740);
                    imag_pert = imag(targetFileStruct(1).pert740);
                elseif app.LOFRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist740L;
                    real_pert = real(targetFileStruct(1).pert740L);
                    imag_pert = imag(targetFileStruct(1).pert740L);
                elseif app.ELPRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist740E;
                    real_pert = real(targetFileStruct(1).pert740E);
                    imag_pert = imag(targetFileStruct(1).pert740E);
                else
                    sddist = targetFileStruct(1).sddist740A;
                    real_pert = real(targetFileStruct(1).pert740A);
                    imag_pert = imag(targetFileStruct(1).pert740A);
                end
            elseif app.nm780Button.Value
                name = '780nm';
                app.refmuaTextArea.Value = num2str(app.mua078);
                app.refmuspTextArea.Value = num2str(app.mus078);
                if app.KmeansOutliersRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist780;
                    real_pert = real(targetFileStruct(1).pert780);
                    imag_pert = imag(targetFileStruct(1).pert780);
                elseif app.LOFRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist780L;
                    real_pert = real(targetFileStruct(1).pert780L);
                    imag_pert = imag(targetFileStruct(1).pert780L);
                elseif app.ELPRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist780E;
                    real_pert = real(targetFileStruct(1).pert780E);
                    imag_pert = imag(targetFileStruct(1).pert780E);
                else
                    sddist = targetFileStruct(1).sddist780A;
                    real_pert = real(targetFileStruct(1).pert780A);
                    imag_pert = imag(targetFileStruct(1).pert780A);
                end
            elseif app.nm808Button.Value
                name = '808nm';
                app.refmuaTextArea.Value = num2str(app.mua080);
                app.refmuspTextArea.Value = num2str(app.mus080);
                if app.KmeansOutliersRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist808;
                    real_pert = real(targetFileStruct(1).pert808);
                    imag_pert = imag(targetFileStruct(1).pert808);
                elseif app.LOFRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist808L;
                    real_pert = real(targetFileStruct(1).pert808L);
                    imag_pert = imag(targetFileStruct(1).pert808L);
                elseif app.ELPRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist808E;
                    real_pert = real(targetFileStruct(1).pert808E);
                    imag_pert = imag(targetFileStruct(1).pert808E);
                else
                    sddist = targetFileStruct(1).sddist808A;
                    real_pert = real(targetFileStruct(1).pert808A);
                    imag_pert = imag(targetFileStruct(1).pert808A);
                end
            else
                name = '830nm';
                app.refmuaTextArea.Value = num2str(app.mua083);
                app.refmuspTextArea.Value = num2str(app.mus083);
                if app.KmeansOutliersRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist830;
                    real_pert = real(targetFileStruct(1).pert830);
                    imag_pert = imag(targetFileStruct(1).pert830);
                elseif app.LOFRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist830L;
                    real_pert = real(targetFileStruct(1).pert830L);
                    imag_pert = imag(targetFileStruct(1).pert830L);
                elseif app.ELPRemovalCheckBox.Value
                    sddist = targetFileStruct(1).sddist830E;
                    real_pert = real(targetFileStruct(1).pert830E);
                    imag_pert = imag(targetFileStruct(1).pert830E);
                else
                    sddist = targetFileStruct(1).sddist830A;
                    real_pert = real(targetFileStruct(1).pert830A);
                    imag_pert = imag(targetFileStruct(1).pert830A);
                end
            end
            plot_result(app,app.Realpart,sddist,real_pert,['Re(Pert) vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Imagpart,sddist,imag_pert,['Im(Pert) vs. Source-Detector Distance', ': ', name],'bo')
%             plotterShowClusters(app,app.pert,real_pert,imag_pert,['Im(Pert) vs. Re(Pert): ', name],'Real','Imag');
            plotterShowsddist(app,app.pert,real_pert,imag_pert,['Im(Pert) vs. Re(Pert): ', name],'Real','Imag',sddist);
        end
        
        function draw_tar2pert(app)
            source = app.source;
            detect = app.detect;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            sddist_matrix = app.sddist_matrix;
            if s_geom(1,2) == 4.012 
                sddist =  sddist_matrix([3 5 7 9 2 4 6 8 1],[5 1 8 12 6 2 14 9 7 10 3 13 4 11]);  
                sddist = reshape(sddist.',[1,Nmeas]);  
            else
                sddist = reshape(sddist_matrix.',[1,Nmeas]);  
            end 
            if app.nm740Button.Value
                name = '740nm';
                app.refmuaTextArea.Value = num2str(app.mua074);
                app.refmuspTextArea.Value = num2str(app.mus074);
                real_pert = app.output_pert74(1:Nmeas);
                imag_pert = app.output_pert74(Nmeas+1:end);
            elseif app.nm780Button.Value
                name = '780nm';
                app.refmuaTextArea.Value = num2str(app.mua078);
                app.refmuspTextArea.Value = num2str(app.mus078);
                real_pert = app.output_pert78(1:Nmeas);
                imag_pert = app.output_pert78(Nmeas+1:end);
            elseif app.nm808Button.Value
                name = '808nm';
                app.refmuaTextArea.Value = num2str(app.mua080);
                app.refmuspTextArea.Value = num2str(app.mus080);
                real_pert = app.output_pert80(1:Nmeas);
                imag_pert = app.output_pert80(Nmeas+1:end);
            else
                name = '830nm';
                app.refmuaTextArea.Value = num2str(app.mua083);
                app.refmuspTextArea.Value = num2str(app.mus083);
                real_pert = app.output_pert83(1:Nmeas);
                imag_pert = app.output_pert83(Nmeas+1:end);
            end
            plot_result(app,app.Realpart,sddist,real_pert,['Re(Pert) vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Imagpart,sddist,imag_pert,['Im(Pert) vs. Source-Detector Distance', ': ', name],'bo')
            plotterShowClusters(app,app.pert,real_pert,imag_pert,['Im(Pert) vs. Re(Pert): ', name],'Real','Imag');
        end
        
        function [data74,data78,data80,data83,Measmnt74,Measmnt78,Measmnt80,Measmnt83,Nmeas74,Nmeas78,Nmeas80,Nmeas83,pairs74,pairs78,pairs80,pairs83,pert74,pert78,pert80,pert83,sddist74,sddist78,sddist80,sddist83] = GetDataFromTargetFileStructure(app)
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            end
            targetFileSelected = 1;   % defined in getCurrentDisplayInfo.m
            targetFileStruct = app.targetFileStruct;           % cell array
            
            if app.KmeansOutliersRemovalCheckBox.Value
                data74 = [real(targetFileStruct(targetFileSelected).pert740) imag(targetFileStruct(targetFileSelected).pert740)];
                data78 = [real(targetFileStruct(targetFileSelected).pert780) imag(targetFileStruct(targetFileSelected).pert780)];
                data80 = [real(targetFileStruct(targetFileSelected).pert808) imag(targetFileStruct(targetFileSelected).pert808)];
                data83 = [real(targetFileStruct(targetFileSelected).pert830) imag(targetFileStruct(targetFileSelected).pert830)];
                Measmnt74 = targetFileStruct(targetFileSelected).Measmnt740;
                Nmeas74 = size(targetFileStruct(targetFileSelected).Measmnt740,1);
                Measmnt78 = targetFileStruct(targetFileSelected).Measmnt780;
                Nmeas78 = size(targetFileStruct(targetFileSelected).Measmnt780,1);
                Measmnt80 = targetFileStruct(targetFileSelected).Measmnt808;
                Nmeas80 = size(targetFileStruct(targetFileSelected).Measmnt808,1);
                Measmnt83 = targetFileStruct(targetFileSelected).Measmnt830;
                Nmeas83 = size(targetFileStruct(targetFileSelected).Measmnt830,1);
                pairs74 = targetFileStruct(targetFileSelected).pairs740;
                pairs78 = targetFileStruct(targetFileSelected).pairs780;
                pairs80 = targetFileStruct(targetFileSelected).pairs808;
                pairs83 = targetFileStruct(targetFileSelected).pairs830;
                pert74 = targetFileStruct(targetFileSelected).pert740;
                pert78 = targetFileStruct(targetFileSelected).pert780;
                pert80 = targetFileStruct(targetFileSelected).pert808;
                pert83 = targetFileStruct(targetFileSelected).pert830;
                sddist74 = targetFileStruct(targetFileSelected).sddist740;
                sddist78 = targetFileStruct(targetFileSelected).sddist780;
                sddist80 = targetFileStruct(targetFileSelected).sddist808;
                sddist83 = targetFileStruct(targetFileSelected).sddist830;
            elseif app.LOFRemovalCheckBox.Value
                data74 = [real(targetFileStruct(targetFileSelected).pert740L) imag(targetFileStruct(targetFileSelected).pert740L)];
                data78 = [real(targetFileStruct(targetFileSelected).pert780L) imag(targetFileStruct(targetFileSelected).pert780L)];
                data80 = [real(targetFileStruct(targetFileSelected).pert808L) imag(targetFileStruct(targetFileSelected).pert808L)];
                data83 = [real(targetFileStruct(targetFileSelected).pert830L) imag(targetFileStruct(targetFileSelected).pert830L)];
                Measmnt74 = targetFileStruct(targetFileSelected).Measmnt740L;
                Nmeas74 = size(targetFileStruct(targetFileSelected).Measmnt740L,1);
                Measmnt78 = targetFileStruct(targetFileSelected).Measmnt780L;
                Nmeas78 = size(targetFileStruct(targetFileSelected).Measmnt780L,1);
                Measmnt80 = targetFileStruct(targetFileSelected).Measmnt808L;
                Nmeas80 = size(targetFileStruct(targetFileSelected).Measmnt808L,1);
                Measmnt83 = targetFileStruct(targetFileSelected).Measmnt830L;
                Nmeas83 = size(targetFileStruct(targetFileSelected).Measmnt830L,1);
                pairs74 = targetFileStruct(targetFileSelected).pairs740L;
                pairs78 = targetFileStruct(targetFileSelected).pairs780L;
                pairs80 = targetFileStruct(targetFileSelected).pairs808L;
                pairs83 = targetFileStruct(targetFileSelected).pairs830L;
                pert74 = targetFileStruct(targetFileSelected).pert740L;
                pert78 = targetFileStruct(targetFileSelected).pert780L;
                pert80 = targetFileStruct(targetFileSelected).pert808L;
                pert83 = targetFileStruct(targetFileSelected).pert830L;
                sddist74 = targetFileStruct(targetFileSelected).sddist740L;
                sddist78 = targetFileStruct(targetFileSelected).sddist780L;
                sddist80 = targetFileStruct(targetFileSelected).sddist808L;
                sddist83 = targetFileStruct(targetFileSelected).sddist830L;
            elseif app.ELPRemovalCheckBox.Value
                data74 = [real(targetFileStruct(targetFileSelected).pert740E) imag(targetFileStruct(targetFileSelected).pert740E)];
                data78 = [real(targetFileStruct(targetFileSelected).pert780E) imag(targetFileStruct(targetFileSelected).pert780E)];
                data80 = [real(targetFileStruct(targetFileSelected).pert808E) imag(targetFileStruct(targetFileSelected).pert808E)];
                data83 = [real(targetFileStruct(targetFileSelected).pert830E) imag(targetFileStruct(targetFileSelected).pert830E)];
                Measmnt74 = targetFileStruct(targetFileSelected).Measmnt740E;
                Nmeas74 = size(targetFileStruct(targetFileSelected).Measmnt740E,1);
                Measmnt78 = targetFileStruct(targetFileSelected).Measmnt780E;
                Nmeas78 = size(targetFileStruct(targetFileSelected).Measmnt780E,1);
                Measmnt80 = targetFileStruct(targetFileSelected).Measmnt808E;
                Nmeas80 = size(targetFileStruct(targetFileSelected).Measmnt808E,1);
                Measmnt83 = targetFileStruct(targetFileSelected).Measmnt830E;
                Nmeas83 = size(targetFileStruct(targetFileSelected).Measmnt830E,1);
                pairs74 = targetFileStruct(targetFileSelected).pairs740E;
                pairs78 = targetFileStruct(targetFileSelected).pairs780E;
                pairs80 = targetFileStruct(targetFileSelected).pairs808E;
                pairs83 = targetFileStruct(targetFileSelected).pairs830E;
                pert74 = targetFileStruct(targetFileSelected).pert740E;
                pert78 = targetFileStruct(targetFileSelected).pert780E;
                pert80 = targetFileStruct(targetFileSelected).pert808E;
                pert83 = targetFileStruct(targetFileSelected).pert830E;
                sddist74 = targetFileStruct(targetFileSelected).sddist740E;
                sddist78 = targetFileStruct(targetFileSelected).sddist780E;
                sddist80 = targetFileStruct(targetFileSelected).sddist808E;
                sddist83 = targetFileStruct(targetFileSelected).sddist830E;
            else
                data74 = [real(targetFileStruct(targetFileSelected).pert740A) imag(targetFileStruct(targetFileSelected).pert740A)];
                data78 = [real(targetFileStruct(targetFileSelected).pert780A) imag(targetFileStruct(targetFileSelected).pert780A)];
                data80 = [real(targetFileStruct(targetFileSelected).pert808A) imag(targetFileStruct(targetFileSelected).pert808A)];
                data83 = [real(targetFileStruct(targetFileSelected).pert830A) imag(targetFileStruct(targetFileSelected).pert830A)];
                Measmnt74 = targetFileStruct(targetFileSelected).Measmnt740A;
                Nmeas74 = size(targetFileStruct(targetFileSelected).Measmnt740A,1);
                Measmnt78 = targetFileStruct(targetFileSelected).Measmnt780A;
                Nmeas78 = size(targetFileStruct(targetFileSelected).Measmnt780A,1);
                Measmnt80 = targetFileStruct(targetFileSelected).Measmnt808A;
                Nmeas80 = size(targetFileStruct(targetFileSelected).Measmnt808A,1);
                Measmnt83 = targetFileStruct(targetFileSelected).Measmnt830A;
                Nmeas83 = size(targetFileStruct(targetFileSelected).Measmnt830A,1);
                pairs74 = targetFileStruct(targetFileSelected).pairs740A;
                pairs78 = targetFileStruct(targetFileSelected).pairs780A;
                pairs80 = targetFileStruct(targetFileSelected).pairs808A;
                pairs83 = targetFileStruct(targetFileSelected).pairs830A;
                pert74 = targetFileStruct(targetFileSelected).pert740A;
                pert78 = targetFileStruct(targetFileSelected).pert780A;
                pert80 = targetFileStruct(targetFileSelected).pert808A;
                pert83 = targetFileStruct(targetFileSelected).pert830A;
                sddist74 = targetFileStruct(targetFileSelected).sddist740A;
                sddist78 = targetFileStruct(targetFileSelected).sddist780A;
                sddist80 = targetFileStruct(targetFileSelected).sddist808A;
                sddist83 = targetFileStruct(targetFileSelected).sddist830A;
            end
        end
        
        function imagemethod(app,actionFlag)
            mua074 = app.mua074;
            mua078 = app.mua078;
            mua080 = app.mua080;
            mua083 = app.mua083;
            
            mus074 = app.mus074;
            mus078 = app.mus078;
            mus080 = app.mus080;
            mus083 = app.mus083;
            
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83] = app.CallingApp.cali_data{9:12,1:2};
            
            [flip_state,large_mapping,two_layer,layerDepth,mua1,mua2,musp1,musp2,...
            xy_x_radius,xy_y_radius,z_radius,depth,n_depth,xy_grid,xcenter_position,ycenter_position,...
        	outliers_removal,LOFRemoval,ELPRemoval,itmax,epsPercent,show_absorb] = getOnscreenParameters(app);
%             if n_depth <= 0.5
%                 n_depth = depth;
%                 if z_radius < 0.5
%                     while n_depth >= 0.5
%                         n_depth = n_depth - 0.5;
%                     end
%                 elseif z_radius > 0.5 && z_radius < 0.75
%                     n_depth = n_depth - 0.25;
%                     while n_depth > 0.5
%                         n_depth = n_depth - 0.5;
%                     end
%                 elseif z_radius >= 0.75 && z_radius < 1.25
%                     while n_depth > 0.5
%                         n_depth = n_depth - 0.5;
%                     end   
%                 elseif z_radius >= 1.25
%                     n_depth = n_depth - 0.25;
%                     while n_depth > 0.5
%                         n_depth = n_depth - 0.5;
%                     end   
%                 else
%                     while n_depth > 0.5
%                         n_depth = n_depth - 0.5;
%                     end   
%                 end
%             end
            if mod(z_radius,0.5) == 0.49
                t_radius = z_radius+0.01; %%0.49/0.99/1.49 --> 0.5,1,1.5
            else
                t_radius = z_radius+0.05; %%0.7 --> 0.75
            end
            
            n_depth= rem(depth - t_radius,0.5);  %%% depth of the first layer
            if n_depth < 0.2
                n_depth = 0.5+n_depth;
            end
            
            if n_depth == depth
                n_depth = depth - 0.5;
            end
            
            targetFileStruct = app.targetFileStruct;
            draw_pert(app,targetFileStruct)
            [data74,data78,data80,data83,Measmnt74,Measmnt78,Measmnt80,Measmnt83,Nmeas74,Nmeas78,Nmeas80,Nmeas83,pairs74,pairs78,pairs80,pairs83,pert74,pert78,pert80,pert83,sddist74,sddist78,sddist80,sddist83] = GetDataFromTargetFileStructure(app);
            
            if isempty(data74)
                return;
            end
            
            switch actionFlag
            case 0 
                'conj_TLS_all';
            case 1
                'conj_TLS_regularized';
            case 2
                'Auto Encoder test';
            end
            
            probe_x = 4.5; 
            probe_y = 4.5;
            probe_z = 3.0;
            z_step_value = 0.5;
            if actionFlag == 2
                lx = probe_x;           % 4.5 cm in x as default
                ly = probe_y;           % 4.5 cm in y as default
                vy = -ly:xy_grid:ly;
                vx = -lx:xy_grid:lx;
                nvx = length(vx);       % number of voxels in x
                nvy = length(vy);       % number of voxels in y
                
                if nvx == 37
                    f = waitbar(0,'Please wait for DOT ML results ...');
                    pause(0.5)      
                    waitbar(.05,f,'Please wait for DOT ML results ...');

                    DOT_ML_recon_mainV3;    % this script will set volume74,volume78,volume80,volume83 and absMax74,absMean74 etc.
                    waitbar(.8,f,'Please wait for DOT ML plotting...');
                    DOT_ML_recon_mainV3_figures;
%                     hemo = oxy_map_func(volume74,volume78,volume80,volume83,vx,vy,nvx,nvy,0);
                    if show_absorb == 1
                        hemo = oxy_map_func(volume74,volume78,volume80,volume83,vx,vy,nvx,nvy,0);      % included the source code which will generate the three figures with their subplots
                    else
                        W_740_780_808_830=2.303*[1.3029 0.4383; 1.1050 0.7360; 0.8040 0.9164; 0.7804 1.0507];
                        W_square=W_740_780_808_830'*W_740_780_808_830;
                        KK=W_square\W_740_780_808_830';
                        K=sum(KK);
                        
                        numOfSubplots = size(volume74, 3);
                        app.TotalhemoglobinPanel.AutoResizeChildren = 'off';
                        app.OxyhemoglobinPanel.AutoResizeChildren = 'off';
                        app.DeoxyhemoglobinPanel.AutoResizeChildren = 'off';
                        for p=1:numOfSubplots
                            for i=1:nvx
                                for j=1:nvy
                                    total_oxy(j,i,p)=K(1)*volume74(j,i,p)+K(2)*volume78(j,i,p)+K(3)*volume80(j,i,p)+K(4)*volume83(j,i,p);
                                    deoxy(j,i,p)=KK(1,1)*volume74(j,i,p)+KK(1,2)*volume78(j,i,p)+KK(1,3)*volume80(j,i,p)+KK(1,4)*volume83(j,i,p);
                                    oxy(j,i,p)=KK(2,1)*volume74(j,i,p)+KK(2,2)*volume78(j,i,p)+KK(2,3)*volume80(j,i,p)+KK(2,4)*volume83(j,i,p);
                                end
                            end
                        end
                        
                        hemo=(total_oxy)*1000;
                        deoxy=deoxy*1000;
                        oxy=oxy*1000;
                        
                        for p=1:numOfSubplots
                            ax1 = subplot(3,3,p,'Parent',app.TotalhemoglobinPanel);
                            if flip_state==1 		%Flip matrices before plotting
                                imagesc(ax1,vy,vx,fliplr(hemo(:,:,p)'),[0 100]);
                                set(ax1,'YDir','Normal');
                                colormap(jet);
                                axis image;
                            else
                                h = imagesc(ax1,vy,vx,hemo(:,:,p)',[0 100]);
                                set(ax1,'YDir','Normal');
                                colormap(ax1,jet);
                        	    axis image;
                            end
                            colorbar(ax1,'location','EastOutside');
                            title(ax1,['Slice ' num2str(p)]);
                            set(ax1,'HitTest','off');
    
                        end
                        
                        for p=1:numOfSubplots
                            ax2 = subplot(3,3,p,'Parent',app.OxyhemoglobinPanel);
                            if flip_state==1 		%Flip matrices before plotting
                                imagesc(ax2,vy,vx,fliplr(oxy(:,:,p)'),[0 100]);
                                set(ax2,'YDir','Normal');
                                colormap(jet);
                                axis image;
                            else
                                h = imagesc(ax2,vy,vx,oxy(:,:,p)',[0 100]);
                                set(ax2,'YDir','Normal');
                                colormap(ax2,jet);
                        	    axis image;
                            end
                            colorbar(ax2,'location','EastOutside');
                            title(ax2,['Slice ' num2str(p)]);
                            set(ax2,'HitTest','off');
    
                        end
                        
                        for p=1:numOfSubplots
                            ax3 = subplot(3,3,p,'Parent',app.DeoxyhemoglobinPanel);
                            if flip_state==1 		%Flip matrices before plotting
                                imagesc(ax3,vy,vx,fliplr(deoxy(:,:,p)'),[0 100]);
                                set(ax3,'YDir','Normal');
                                colormap(jet);
                                axis image;
                            else
                                h = imagesc(ax3,vy,vx,deoxy(:,:,p)',[0 100]);
                                set(ax3,'YDir','Normal');
                                colormap(ax3,jet);
                        	    axis image;
                            end
                            colorbar(ax3,'location','EastOutside');
                            title(ax3,['Slice ' num2str(p)]);
                            set(ax3,'HitTest','off');
    
                        end
                    end
                    % Compute total hemoglobin 
                    [absMaxhemo, meanFWhemo, ~] = absorp_tot(hemo(:),37*37*7,0);
                    app.maxhb.Value = num2str(absMaxhemo);
                    app.meanhb.Value = num2str(meanFWhemo);
                    waitbar(1,f,'Finished');
                    close(f);
                    
                    app.hemo = hemo;
                    app.volume74 = volume74;
                    app.volume78 = volume78;
                    app.volume80 = volume80;
                    app.volume83 = volume83;
                else
                    errordlg('nvx is not equal to 37!!','ERROR');
                    return
                end
            else
                f = waitbar(0,'Please wait...');
                pause(0.5)      
                waitbar(.15,f,'Please wait 740nm results ...');
                % just use the first target file info to display and continue
                [volume74,vx,vy,nvx,nvy,absMax74,absMean74,v_tot,abs74,absMax74_delta,absMean74_delta] = absorption_mappingSingleTarget(app,xcenter_position,ycenter_position,...
                    xy_x_radius,xy_y_radius,z_radius,xy_grid,depth,n_depth,Nmeas74,Measmnt74,s_geom,d_geom,...
                    mua074,mus074,data74,740,flip_state,large_mapping,...
                    mua1,mua2,musp1,musp2,layerDepth,two_layer,probe_x,probe_y,probe_z,z_step_value,actionFlag,itmax,epsPercent,show_absorb);
                waitbar(.35,f,'Please wait 780nm results ...');
                [volume78,vx,vy,nvx,nvy,absMax78,absMean78,v_tot,abs78,absMax78_delta,absMean78_delta] = absorption_mappingSingleTarget(app,xcenter_position,ycenter_position,...
                    xy_x_radius,xy_y_radius,z_radius,xy_grid,depth,n_depth,Nmeas78,Measmnt78,s_geom,d_geom,...
                    mua078,mus078,data78,780,flip_state,large_mapping,...
                    mua1,mua2,musp1,musp2,layerDepth,two_layer,probe_x,probe_y,probe_z,z_step_value,actionFlag,itmax,epsPercent,show_absorb);
                waitbar(.55,f,'Please wait 808nm results ...');
                [volume80,vx,vy,nvx,nvy,absMax80,absMean80,v_tot,abs80,absMax80_delta,absMean80_delta] = absorption_mappingSingleTarget(app,xcenter_position,ycenter_position,...
                    xy_x_radius,xy_y_radius,z_radius,xy_grid,depth,n_depth,Nmeas80,Measmnt80,s_geom,d_geom,...
                    mua080,mus080,data80,808,flip_state,large_mapping,...
                    mua1,mua2,musp1,musp2,layerDepth,two_layer,probe_x,probe_y,probe_z,z_step_value,actionFlag,itmax,epsPercent,show_absorb);
                waitbar(.75,f,'Please wait 830nm results ...');
                [volume83,vx,vy,nvx,nvy,absMax83,absMean83,v_tot,abs83,absMax83_delta,absMean83_delta] = absorption_mappingSingleTarget(app,xcenter_position,ycenter_position,...
                    xy_x_radius,xy_y_radius,z_radius,xy_grid,depth,n_depth,Nmeas83,Measmnt83,s_geom,d_geom,...
                    mua083,mus083,data83,830,flip_state,large_mapping,...
                    mua1,mua2,musp1,musp2,layerDepth,two_layer,probe_x,probe_y,probe_z,z_step_value,actionFlag,itmax,epsPercent,show_absorb);
            
                app.maxab740.Value = num2str(absMax74,'%2.3f');
                app.maxab780.Value = num2str(absMax78,'%2.3f');
                app.maxab808.Value = num2str(absMax80,'%2.3f');
                app.maxab830.Value = num2str(absMax83,'%2.3f');
                app.meanab740.Value = num2str(absMean74,'%2.3f');
                app.meanab780.Value = num2str(absMean78,'%2.3f');
                app.meanab808.Value = num2str(absMean80,'%2.3f');
                app.meanab830.Value = num2str(absMean83,'%2.3f');
            
                app.nvx = nvx;
                app.nvy = nvy;
                app.vx = vx;
                app.vy = vy;
                app.v_tot = v_tot;
            
                app.volume74 = volume74;
                app.Nmeas74 = Nmeas74;
                app.abs74 = abs74;
                app.volume78 = volume78;
                app.Nmeas78 = Nmeas78;
                app.abs78 = abs78;
                app.volume80 = volume80;
                app.Nmeas80 = Nmeas80;
                app.abs80 = abs80;
                app.volume83 = volume83;
                app.Nmeas83 = Nmeas83;
                app.abs83 = abs83;
                
%                 set(handles.hemo_map,'Enable','on');
                waitbar(.9,f,'Please wait hemoglobin results ...');
                if show_absorb == 1
                    oxy_map_func(volume74,volume78,volume80,volume83,vx,vy,nvx,nvy,flip_state);      % included the source code which will generate the three figures with their subplots
                else
                    W_740_780_808_830=2.303*[1.3029 0.4383; 1.1050 0.7360; 0.8040 0.9164; 0.7804 1.0507];
                    W_square=W_740_780_808_830'*W_740_780_808_830;
                    KK=W_square\W_740_780_808_830';
                    K=sum(KK);
                    
                    numOfSubplots = size(volume74, 3);
                    app.TotalhemoglobinPanel.AutoResizeChildren = 'off';
                    app.OxyhemoglobinPanel.AutoResizeChildren = 'off';
                    app.DeoxyhemoglobinPanel.AutoResizeChildren = 'off';
                    for p=1:numOfSubplots
                        for i=1:nvx
                            for j=1:nvy
                                total_oxy(j,i,p)=K(1)*volume74(j,i,p)+K(2)*volume78(j,i,p)+K(3)*volume80(j,i,p)+K(4)*volume83(j,i,p);
                                deoxy(j,i,p)=KK(1,1)*volume74(j,i,p)+KK(1,2)*volume78(j,i,p)+KK(1,3)*volume80(j,i,p)+KK(1,4)*volume83(j,i,p);
                                oxy(j,i,p)=KK(2,1)*volume74(j,i,p)+KK(2,2)*volume78(j,i,p)+KK(2,3)*volume80(j,i,p)+KK(2,4)*volume83(j,i,p);
                            end
                        end
                    end
                    
                    hemo=(total_oxy)*1000;
                    deoxy=deoxy*1000;
                    oxy=oxy*1000;
                    
                    for p=1:numOfSubplots
                        ax1 = subplot(3,3,p,'Parent',app.TotalhemoglobinPanel);
                        if flip_state==1 		%Flip matrices before plotting
                            imagesc(ax1,vy,vx,fliplr(hemo(:,:,p)'),[0 100]);
                            set(ax1,'YDir','Normal');
                            colormap(jet);
                            axis image;
                        else
                            h = imagesc(ax1,vy,vx,hemo(:,:,p)',[0 100]);
                            set(ax1,'YDir','Normal');
                            colormap(ax1,jet);
                    	axis image;
                        end
                        colorbar(ax1,'location','EastOutside');
                        title(ax1,['Slice ' num2str(p)]);
                        set(ax1,'HitTest','off');

                    end
                    for p=1:numOfSubplots
                        ax2 = subplot(3,3,p,'Parent',app.OxyhemoglobinPanel);
                        if flip_state==1 		%Flip matrices before plotting
                            imagesc(ax2,vy,vx,fliplr(oxy(:,:,p)'),[0 100]);
                            set(ax2,'YDir','Normal');
                            colormap(jet);
                            axis image;
                        else
                            h = imagesc(ax2,vy,vx,oxy(:,:,p)',[0 100]);
                            set(ax2,'YDir','Normal');
                            colormap(ax2,jet);
                    	    axis image;
                        end
                        colorbar(ax2,'location','EastOutside');
                        title(ax2,['Slice ' num2str(p)]);
                        set(ax2,'HitTest','off');

                    end
                    
                    for p=1:numOfSubplots
                        ax3 = subplot(3,3,p,'Parent',app.DeoxyhemoglobinPanel);
                        if flip_state==1 		%Flip matrices before plotting
                            imagesc(ax3,vy,vx,fliplr(deoxy(:,:,p)'),[0 100]);
                            set(ax3,'YDir','Normal');
                            colormap(jet);
                            axis image;
                        else
                            h = imagesc(ax3,vy,vx,deoxy(:,:,p)',[0 100]);
                            set(ax3,'YDir','Normal');
                            colormap(ax3,jet);
                    	    axis image;
                        end
                        colorbar(ax3,'location','EastOutside');
                        title(ax3,['Slice ' num2str(p)]);
                        set(ax3,'HitTest','off');

                    end
                end
                [P_oxy,P] = oxy_tot_func(abs74,abs78,abs80,abs83,v_tot,mua074,mua078,mua080,mua083);   % oxy_tot is changed to a function 6/28/15 
            
                app.maxhb.Value = num2str(P_oxy(1));
                app.meanhb.Value = num2str(P_oxy(2));
                app.hemo = hemo;
                waitbar(1,f,'Finished');
                close(f);
            end
            app.ReconEvaluationButton.Enable = true;
            if ~isempty(app.us_features) && ~isempty(app.histo_features) 
                app.WithReconclassificationButton.Enable = true;
            end
        end
        
        function [amp,phase] = process_tar2pert(app,s_geom,source,detect,sddist_matrix,pairs,bga,bgp,Measmnt)
            if ~iscell(pairs)         % pairs was filtered(set to 0) before by smoothing phase(smooth & smooth2)
                pairs = {pairs};
            end
            amp = [];
            phase = [];
            sddist = [];
            
            mi=0;
            for si=1:source
                for di=1:detect
                    if pairs{end}(si,di)
                        mi=mi+1;
                        amp(si,di)=bga(mi);
                        phase(si,di)=bgp(mi);
%                         sddist(si,di)=sddist_matrix(si,di);
                    end
                end
            end
            
            if s_geom(1,2) == 4.012 
                amp =  amp([3 5 7 9 2 4 6 8 1],[5 1 8 12 6 2 14 9 7 10 3 13 4 11]);  
                amp = reshape(amp.',[1,126]);  
                phase =  phase([3 5 7 9 2 4 6 8 1],[5 1 8 12 6 2 14 9 7 10 3 13 4 11]);  
                phase = reshape(phase.',[1,126]);  
            else
                amp = reshape(amp.',[1,126]); 
                phase = reshape(phase.',[1,126]); 
            end 
        end
        
        
        function plotterShowsddist(app,axes_handle,x,y,label,x_lab,y_lab,sddist)

            cla(axes_handle);
            axes(axes_handle);  %Plots data on the axes with the input argument axes_handle
            
%             % these condition should match the ones in applyOutlierKMean7.m
%             k_value = 7;                % k-mean cluster numbers
%             dist_value_inner = 0.7;     % distance from (0, 0)
%             dist_value_outer = 1.1;     % distance from (0, 0)
%             c_value = 10;               % member threshold to eliminate
%             
            data = [transpose(x),transpose(y)];             % data = [real(pert)',imag(pert)'];
%             [idx,C] = kmeans(data,k_value);
            hold(axes_handle,'on')
            
            p1=plot(axes_handle,data(sddist < 4,1),data(sddist < 4,2),'r*','MarkerSize',3);
            p2=plot(axes_handle,data(sddist<5 & sddist>=4,1),data(sddist<5 & sddist>=4,2),'b*','MarkerSize',3);
            p3=plot(axes_handle,data(sddist<6 & sddist>=5,1),data(sddist<6 & sddist>=5,2),'c*','MarkerSize',3);
            p4=plot(axes_handle,data(sddist<7 & sddist>=6,1),data(sddist<7 & sddist>=6,2),'ro','MarkerSize',3);
            p5=plot(axes_handle,data(sddist >= 7 ,1),data(sddist >= 7,2),'bo','MarkerSize',3);
            
            circle(app,axes_handle,0,0,0.5);
            circle(app,axes_handle,0,0,1);
            title(axes_handle,label)
            xlabel(axes_handle,x_lab);
            ylabel(axes_handle,y_lab);
            
            if ~isempty(strfind(label,'Im(Pert) vs. Re(Pert)'))
                set(axes_handle, 'NextPlot', 'add');    % "hold on" *before* plot!
                circle(app,axes_handle,0,0,1);
            end
            legend(axes_handle,[p1,p2,p3,p4,p5],{'(-inf, 4)','[4, 5)','[5, 6)','[6, 7)','[7, inf)'});
            hold(axes_handle,'off')
        end
        
    end
    
    methods (Access = public)
        
        function update_USinfo(app,us_features,width1,width2,width3,width4,width5,z_radius,target_depth,us_predict) 
            app.us_features = us_features;
            app.width1 = width1;
            app.width2 = width2;
            app.width3 = width3;
            app.width4 = width4;
            app.width5 = width5;
            app.z_radius = z_radius;
            app.target_depth = target_depth;
            app.us_predict = us_predict;
            if ~isempty(target_depth) && ~isempty(z_radius)
                app.centerdepthEditField.Value = target_depth + z_radius;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            app.mua074 = app.CallingApp.mua074;
            app.mua078 = app.CallingApp.mua078;
            app.mua080 = app.CallingApp.mua080;
            app.mua083 = app.CallingApp.mua083;
            app.mus074 = app.CallingApp.mus074;
            app.mus078 = app.CallingApp.mus078;
            app.mus080 = app.CallingApp.mus080;
            app.mus083 = app.CallingApp.mus083;
            app.pairs = app.CallingApp.pairs;
            app.source = app.CallingApp.Ns;
            app.detect = app.CallingApp.Nd;
            app.s_geom = app.CallingApp.s_geom;
            app.d_geom = app.CallingApp.d_geom;
            app.sddist_matrix = app.CallingApp.sddist_matrix;
            app.sddist = app.CallingApp.sddist;
            app.sddist74 = app.CallingApp.sddist74;
            app.sddist78 = app.CallingApp.sddist78;
            app.sddist80 = app.CallingApp.sddist80;
            app.sddist83 = app.CallingApp.sddist83;
            app.pairs74 = app.CallingApp.pairs74;
            app.pairs78 = app.CallingApp.pairs78;
            app.pairs80 = app.CallingApp.pairs80;
            app.pairs83 = app.CallingApp.pairs83;
            app.Measmnt74 = app.CallingApp.Measmnt74;
            app.Measmnt78 = app.CallingApp.Measmnt78;
            app.Measmnt80 = app.CallingApp.Measmnt80;
            app.Measmnt83 = app.CallingApp.Measmnt83;
            
            app.RefTextArea.Value = app.CallingApp.reference;
            [~, tar_file] = fileparts(app.CallingApp.tar_name);
            app.TarDropDown.Items = tar_file;
            app.WithReconclassificationButton.Enable = false;
            app.WithHistoclassificationButton.Enable = false;
            app.ReconEvaluationButton.Enable = false;
            app.TartoPertButton.Enable = false;
            app.UndoAllButton.Enable = false;
            app.FittingUnwrapButton.Enable = false;
            app.FittingDeleteButton.Enable = false;
            app.ClusterUnwrapButton.Enable = false;
            app.count740 = 0;
            app.count780 = 0;
            app.count808 = 0;
            app.count830 = 0;
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            sddist_matrix = app.sddist_matrix;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            Nmeas = source*detect;
            if ~iscell(pairs74)			% should have the same action for all four wavelength
                	pairs74 = {pairs74};
                	pairs78 = {pairs78};
                	pairs80 = {pairs80};
                	pairs83 = {pairs83};
            end    
            if ~iscell(Measmnt74)	% should have the same action for all four wavelength
            	Measmnt74 = {Measmnt74};
            	Measmnt78 = {Measmnt78};
            	Measmnt80 = {Measmnt80};
            	Measmnt83 = {Measmnt83};
            end
                
            disableDefaultInteractivity(app.Realpart)
            disableDefaultInteractivity(app.Imagpart)
            disableDefaultInteractivity(app.pert)
                

            [tar_size, ~] = size(app.CallingApp.tar_alldata{1});
            tar_total_data = cell(tar_size*3,4);
            for i = 1:tar_size
                tar_total_data(3*i-2,:) = app.CallingApp.tar_alldata{1}(i,:);
                tar_total_data(3*i-1,:) = app.CallingApp.tar_alldata{2}(i,:);
                tar_total_data(3*i,:) = app.CallingApp.tar_alldata{3}(i,:);
            end
            file_idx=[2:3:tar_size*3];
            random_select_tar = randsample(file_idx,1);
            app.TarDropDown.Value = app.TarDropDown.Items(random_select_tar);
            tar_value = app.TarDropDown.Value;
            tar_name = strfind(app.CallingApp.tar_name,tar_value);
            tar_file_idx = find(~cellfun(@isempty, tar_name));
            aba74 = reshape(tar_total_data{tar_file_idx,1}(1:Nmeas),source,detect);
            aba78 = reshape(tar_total_data{tar_file_idx,2}(1:Nmeas),source,detect);
            aba80 = reshape(tar_total_data{tar_file_idx,3}(1:Nmeas),source,detect);
            aba83 = reshape(tar_total_data{tar_file_idx,4}(1:Nmeas),source,detect);
            abp74 = reshape(tar_total_data{tar_file_idx,1}(Nmeas+1:end),source,detect);
            abp78 = reshape(tar_total_data{tar_file_idx,2}(Nmeas+1:end),source,detect);
            abp80 = reshape(tar_total_data{tar_file_idx,3}(Nmeas+1:end),source,detect);
            abp83 = reshape(tar_total_data{tar_file_idx,4}(Nmeas+1:end),source,detect);
            
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83] = app.CallingApp.cali_data{9:12,1:2};

            % empty structure which is defined in targetFileStruct.m
            len740 = size(Measmnt74,1);
            len780 = size(Measmnt78,1);
            len808 = size(Measmnt80,1);
            len830 = size(Measmnt83,1);
            targetFileStruct = getTargetFileStruct(len740,len780,len808,len830);
            
            numOfDataFiles = 1;                 % keep it consistence with multiple file load
            pert740_2d = {numOfDataFiles};      % build cell array for pert on each file and will be used by outliers removal
            pert780_2d = {numOfDataFiles}; 
            pert808_2d = {numOfDataFiles};
            pert830_2d = {numOfDataFiles};
            Measmnt740_2d = {numOfDataFiles};   % build cell array for Measmnt on each file and will be used by outliers removal
            Measmnt780_2d = {numOfDataFiles}; 
            Measmnt808_2d = {numOfDataFiles};
            Measmnt830_2d = {numOfDataFiles};
            sddist740_2d = {numOfDataFiles};
            sddist780_2d = {numOfDataFiles};
            sddist808_2d = {numOfDataFiles};
            sddist830_2d = {numOfDataFiles};

            % will eliminate the data which has real(pert) < -1.1
            [pert74,data74,Nmeas74new,Measmnt74new,sddist74new,pairs74New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs74,aba74,abp74,aba_r74,abp_r74,Measmnt74{end});  % both pert and data are cells
            [pert78,data78,Nmeas78new,Measmnt78new,sddist78new,pairs78New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs78,aba78,abp78,aba_r78,abp_r78,Measmnt78{end});
            [pert80,data80,Nmeas80new,Measmnt80new,sddist80new,pairs80New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs80,aba80,abp80,aba_r80,abp_r80,Measmnt80{end});
            [pert83,data83,Nmeas83new,Measmnt83new,sddist83new,pairs83New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs83,aba83,abp83,aba_r83,abp_r83,Measmnt83{end});
            
            app.origdata = {data74,data78,data80,data83;
                            pert74,pert78,pert80,pert83;
                            {app.sddist74{end}},{app.sddist78{end}},{app.sddist80{end}},{app.sddist83{end}};
                            {app.pairs74{end}},{app.pairs78{end}},{app.pairs80{end}},{app.pairs83{end}};
                            {app.Measmnt74{end}},{app.Measmnt78{end}},{app.Measmnt80{end}},{app.Measmnt83{end}};};

            pert740_2d{1} = pert74{end};  % build cell array for pert on each file and will be used by outliers removal
            pert780_2d{1} = pert78{end}; 
            pert808_2d{1} = pert80{end};
            pert830_2d{1} = pert83{end};
            Measmnt740_2d{1} = Measmnt74new{end};
            Measmnt780_2d{1} = Measmnt78new{end};
            Measmnt808_2d{1} = Measmnt80new{end};
            Measmnt830_2d{1} = Measmnt83new{end};
            sddist740_2d{1} = sddist74new{end};
            sddist780_2d{1} = sddist78new{end};
            sddist808_2d{1} = sddist80new{end};
            sddist830_2d{1} = sddist83new{end};
            pairs740_2d{1} = pairs74New{end};
            pairs780_2d{1} = pairs78New{end};
            pairs808_2d{1} = pairs80New{end};
            pairs830_2d{1} = pairs83New{end};
            
            [pert740_cell,Measmnt740_cell,sddist740_cell,pairs740_cell] = applyOutlierKMean7(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_cell,Measmnt780_cell,sddist780_cell,pairs780_cell] = applyOutlierKMean7(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_cell,Measmnt808_cell,sddist808_cell,pairs808_cell] = applyOutlierKMean7(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_cell,Measmnt830_cell,sddist830_cell,pairs830_cell] = applyOutlierKMean7(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            
            targetFileStruct(1).fileName = tar_value;
            targetFileStruct(1).fileSeq = 1; 
            targetFileStruct(1).pert740 = pert740_cell{1};      % the ones which have gone through -1.1 filter AND outliers removal
            targetFileStruct(1).pert780 = pert780_cell{1};
            targetFileStruct(1).pert808 = pert808_cell{1};
            targetFileStruct(1).pert830 = pert830_cell{1};
            targetFileStruct(1).sddist740 = sddist740_cell{1};
            targetFileStruct(1).sddist780 = sddist780_cell{1};
            targetFileStruct(1).sddist808 = sddist808_cell{1};
            targetFileStruct(1).sddist830 = sddist830_cell{1};
            targetFileStruct(1).Measmnt740 = Measmnt740_cell{1};
            targetFileStruct(1).Measmnt780 = Measmnt780_cell{1};
            targetFileStruct(1).Measmnt808 = Measmnt808_cell{1};
            targetFileStruct(1).Measmnt830 = Measmnt830_cell{1};
            targetFileStruct(1).pairs740 = pairs740_cell{1};
            targetFileStruct(1).pairs780 = pairs780_cell{1};
            targetFileStruct(1).pairs808 = pairs808_cell{1};
            targetFileStruct(1).pairs830 = pairs830_cell{1};
                
            targetFileStruct(1).pert740A = pert740_2d{1};       % the ones which DO NOT go through outliers removal, just -1.1 filter
            targetFileStruct(1).pert780A = pert780_2d{1};
            targetFileStruct(1).pert808A = pert808_2d{1};
            targetFileStruct(1).pert830A = pert830_2d{1};
            targetFileStruct(1).sddist740A = sddist740_2d{1};
            targetFileStruct(1).sddist780A = sddist780_2d{1};
            targetFileStruct(1).sddist808A = sddist808_2d{1};
            targetFileStruct(1).sddist830A = sddist830_2d{1};
            targetFileStruct(1).Measmnt740A = Measmnt740_2d{1};
            targetFileStruct(1).Measmnt780A = Measmnt780_2d{1};
            targetFileStruct(1).Measmnt808A = Measmnt808_2d{1};
            targetFileStruct(1).Measmnt830A = Measmnt830_2d{1};
            targetFileStruct(1).pairs740A = pairs740_2d{1};
            targetFileStruct(1).pairs780A = pairs780_2d{1};
            targetFileStruct(1).pairs808A = pairs808_2d{1};
            targetFileStruct(1).pairs830A = pairs830_2d{1};
            
            [pert740_lof,Measmnt740_lof,sddist740_lof,pairs740_lof] = LOFmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_lof,Measmnt780_lof,sddist780_lof,pairs780_lof] = LOFmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_lof,Measmnt808_lof,sddist808_lof,pairs808_lof] = LOFmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_lof,Measmnt830_lof,sddist830_lof,pairs830_lof] = LOFmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            targetFileStruct(1).pert740L = pert740_lof{1};       % the ones which go through LOF outliers removal
            targetFileStruct(1).pert780L = pert780_lof{1};
            targetFileStruct(1).pert808L = pert808_lof{1};
            targetFileStruct(1).pert830L = pert830_lof{1};
            targetFileStruct(1).sddist740L = sddist740_lof{1};
            targetFileStruct(1).sddist780L = sddist780_lof{1};
            targetFileStruct(1).sddist808L = sddist808_lof{1};
            targetFileStruct(1).sddist830L = sddist830_lof{1};
            targetFileStruct(1).Measmnt740L = Measmnt740_lof{1};
            targetFileStruct(1).Measmnt780L = Measmnt780_lof{1};
            targetFileStruct(1).Measmnt808L = Measmnt808_lof{1};
            targetFileStruct(1).Measmnt830L = Measmnt830_lof{1};
            targetFileStruct(1).pairs740L = pairs740_lof{1};
            targetFileStruct(1).pairs780L = pairs780_lof{1};
            targetFileStruct(1).pairs808L = pairs808_lof{1};
            targetFileStruct(1).pairs830L = pairs830_lof{1};
            
            range = app.ELPconfidenceintervalEditField.Value;
            [pert740_elp,Measmnt740_elp,sddist740_elp,pairs740_elp] = ELPmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect,range);
            [pert780_elp,Measmnt780_elp,sddist780_elp,pairs780_elp] = ELPmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect,range);
            [pert808_elp,Measmnt808_elp,sddist808_elp,pairs808_elp] = ELPmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect,range);
            [pert830_elp,Measmnt830_elp,sddist830_elp,pairs830_elp] = ELPmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect,range);
            targetFileStruct(1).pert740E = pert740_elp{1};       % the ones which go through elp outliers removal
            targetFileStruct(1).pert780E = pert780_elp{1};
            targetFileStruct(1).pert808E = pert808_elp{1};
            targetFileStruct(1).pert830E = pert830_elp{1};
            targetFileStruct(1).sddist740E = sddist740_elp{1};
            targetFileStruct(1).sddist780E = sddist780_elp{1};
            targetFileStruct(1).sddist808E = sddist808_elp{1};
            targetFileStruct(1).sddist830E = sddist830_elp{1};
            targetFileStruct(1).Measmnt740E = Measmnt740_elp{1};
            targetFileStruct(1).Measmnt780E = Measmnt780_elp{1};
            targetFileStruct(1).Measmnt808E = Measmnt808_elp{1};
            targetFileStruct(1).Measmnt830E = Measmnt830_elp{1};
            targetFileStruct(1).pairs740E = pairs740_elp{1};
            targetFileStruct(1).pairs780E = pairs780_elp{1};
            targetFileStruct(1).pairs808E = pairs808_elp{1};
            targetFileStruct(1).pairs830E = pairs830_elp{1};
            
            app.targetFileStruct = targetFileStruct;
            if app.KmeansOutliersRemovalCheckBox.Value
                app.pairs74 = {targetFileStruct(1).pairs740};
                app.pairs78 = {targetFileStruct(1).pairs780};
                app.pairs80 = {targetFileStruct(1).pairs808};
                app.pairs83 = {targetFileStruct(1).pairs830};
                app.sddist74 = {targetFileStruct(1).sddist740}; % make it into cell array because of undo
                app.sddist78 = {targetFileStruct(1).sddist780};
                app.sddist80 = {targetFileStruct(1).sddist808};
                app.sddist83 = {targetFileStruct(1).sddist830};
                app.Measmnt74 = Measmnt740_cell{1};
                app.Measmnt78 = Measmnt780_cell{1};
                app.Measmnt80 = Measmnt808_cell{1};
                app.Measmnt83 = Measmnt830_cell{1};
                if ~iscell(app.Measmnt74)
                    app.Measmnt74 = {app.Measmnt74};
                    app.Measmnt78 = {app.Measmnt78};
                    app.Measmnt80 = {app.Measmnt80};
                    app.Measmnt83 = {app.Measmnt83};
                end
                app.pert74 = {targetFileStruct(1).pert740};
                app.pert78 = {targetFileStruct(1).pert780};
                app.pert80 = {targetFileStruct(1).pert808};
                app.pert83 = {targetFileStruct(1).pert830};
                app.data74 = {[real(targetFileStruct(1).pert740) imag(targetFileStruct(1).pert740)]};  %can't have any transportation because they are complex numbers
                app.data78 = {[real(targetFileStruct(1).pert780) imag(targetFileStruct(1).pert780)]};
                app.data80 = {[real(targetFileStruct(1).pert808) imag(targetFileStruct(1).pert808)]};
                app.data83 = {[real(targetFileStruct(1).pert830) imag(targetFileStruct(1).pert830)]};
            elseif app.LOFRemovalCheckBox.Value
                app.pairs74 = {targetFileStruct(1).pairs740L};
                app.pairs78 = {targetFileStruct(1).pairs780L};
                app.pairs80 = {targetFileStruct(1).pairs808L};
                app.pairs83 = {targetFileStruct(1).pairs830L};
                app.sddist74 = {targetFileStruct(1).sddist740L}; % make it into cell array because of undo
                app.sddist78 = {targetFileStruct(1).sddist780L};
                app.sddist80 = {targetFileStruct(1).sddist808L};
                app.sddist83 = {targetFileStruct(1).sddist830L};
                app.Measmnt74 = Measmnt740_lof{1};
                app.Measmnt78 = Measmnt780_lof{1};
                app.Measmnt80 = Measmnt808_lof{1};
                app.Measmnt83 = Measmnt830_lof{1};
                if ~iscell(app.Measmnt74)
                    app.Measmnt74 = {app.Measmnt74};
                    app.Measmnt78 = {app.Measmnt78};
                    app.Measmnt80 = {app.Measmnt80};
                    app.Measmnt83 = {app.Measmnt83};
                end
                app.pert74 = {targetFileStruct(1).pert740L};
                app.pert78 = {targetFileStruct(1).pert780L};
                app.pert80 = {targetFileStruct(1).pert808L};
                app.pert83 = {targetFileStruct(1).pert830L};
                app.data74 = {[real(targetFileStruct(1).pert740L) imag(targetFileStruct(1).pert740L)]};  %can't have any transportation because they are complex numbers
                app.data78 = {[real(targetFileStruct(1).pert780L) imag(targetFileStruct(1).pert780L)]};
                app.data80 = {[real(targetFileStruct(1).pert808L) imag(targetFileStruct(1).pert808L)]};
                app.data83 = {[real(targetFileStruct(1).pert830L) imag(targetFileStruct(1).pert830L)]};
            elseif app.ELPRemovalCheckBox.Value
                app.pairs74 = {targetFileStruct(1).pairs740E};
                app.pairs78 = {targetFileStruct(1).pairs780E};
                app.pairs80 = {targetFileStruct(1).pairs808E};
                app.pairs83 = {targetFileStruct(1).pairs830E};
                app.sddist74 = {targetFileStruct(1).sddist740E}; % make it into cell array because of undo
                app.sddist78 = {targetFileStruct(1).sddist780E};
                app.sddist80 = {targetFileStruct(1).sddist808E};
                app.sddist83 = {targetFileStruct(1).sddist830E};
                app.Measmnt74 = Measmnt740_elp{1};
                app.Measmnt78 = Measmnt780_elp{1};
                app.Measmnt80 = Measmnt808_elp{1};
                app.Measmnt83 = Measmnt830_elp{1};
                if ~iscell(app.Measmnt74)
                    app.Measmnt74 = {app.Measmnt74};
                    app.Measmnt78 = {app.Measmnt78};
                    app.Measmnt80 = {app.Measmnt80};
                    app.Measmnt83 = {app.Measmnt83};
                end
                app.pert74 = {targetFileStruct(1).pert740E};
                app.pert78 = {targetFileStruct(1).pert780E};
                app.pert80 = {targetFileStruct(1).pert808E};
                app.pert83 = {targetFileStruct(1).pert830E};
                app.data74 = {[real(targetFileStruct(1).pert740E) imag(targetFileStruct(1).pert740E)]};  %can't have any transportation because they are complex numbers
                app.data78 = {[real(targetFileStruct(1).pert780E) imag(targetFileStruct(1).pert780E)]};
                app.data80 = {[real(targetFileStruct(1).pert808E) imag(targetFileStruct(1).pert808E)]};
                app.data83 = {[real(targetFileStruct(1).pert830E) imag(targetFileStruct(1).pert830E)]};
            else
                app.pairs74 = {targetFileStruct(1).pairs740A};
                app.pairs78 = {targetFileStruct(1).pairs780A};
                app.pairs80 = {targetFileStruct(1).pairs808A};
                app.pairs83 = {targetFileStruct(1).pairs830A};
                app.sddist74 = {targetFileStruct(1).sddist740A}; % make it into cell array because of undo
                app.sddist78 = {targetFileStruct(1).sddist780A};
                app.sddist80 = {targetFileStruct(1).sddist808A};
                app.sddist83 = {targetFileStruct(1).sddist830A};
                app.Measmnt74 = Measmnt740_2d{1};
                app.Measmnt78 = Measmnt780_2d{1};
                app.Measmnt80 = Measmnt808_2d{1};
                app.Measmnt83 = Measmnt830_2d{1};
                if ~iscell(app.Measmnt74)
                    app.Measmnt74 = {app.Measmnt74};
                    app.Measmnt78 = {app.Measmnt78};
                    app.Measmnt80 = {app.Measmnt80};
                    app.Measmnt83 = {app.Measmnt83};
                end
                app.pert74 = {targetFileStruct(1).pert740A};
                app.pert78 = {targetFileStruct(1).pert780A};
                app.pert80 = {targetFileStruct(1).pert808A};
                app.pert83 = {targetFileStruct(1).pert830A};
                app.data74 = {[real(targetFileStruct(1).pert740A) imag(targetFileStruct(1).pert740A)]};  %can't have any transportation because they are complex numbers
                app.data78 = {[real(targetFileStruct(1).pert780A) imag(targetFileStruct(1).pert780A)]};
                app.data80 = {[real(targetFileStruct(1).pert808A) imag(targetFileStruct(1).pert808A)]};
                app.data83 = {[real(targetFileStruct(1).pert830A) imag(targetFileStruct(1).pert830A)]};
            end
            
            app.sddist_matrix = sddist_matrix;

            app.CallingApp.bga74 = aba_r74;    % get from load reference file on the imger_4wvl
            app.CallingApp.bgp74 = abp_r74;
            app.CallingApp.bga78 = aba_r78;
            app.CallingApp.bgp78 = abp_r78;
            app.CallingApp.bga80 = aba_r80;
            app.CallingApp.bgp80 = abp_r80;
            app.CallingApp.bga83 = aba_r83;
            app.CallingApp.bgp83 = abp_r83;
            
            app.CallingApp.aba74 = aba74;
            app.CallingApp.aba78 = aba78;
            app.CallingApp.aba80 = aba80;
            app.CallingApp.aba83 = aba83;
            app.CallingApp.abp74 = abp74;
            app.CallingApp.abp78 = abp78;
            app.CallingApp.abp80 = abp80;
            app.CallingApp.abp83 = abp83;

            targetFileStruct = app.targetFileStruct;
            app.initial_targetFileStruct = targetFileStruct;
            draw_pert(app,targetFileStruct)
            
            undoStruct = getCurrentDisplayInfo(app,targetFileStruct);
            app.undoStructCell{1}{1} = undoStruct;  % initial value
            
            app.undoSeq = 0;                        % initial setting for single target file
            app.numofundo.Value = num2str(app.undoSeq);
            
            app.mua740.Value = num2str(app.mua074,'%2.3f');
            app.mua780.Value = num2str(app.mua078,'%2.3f');
            app.mua808.Value = num2str(app.mua080,'%2.3f');
            app.mua830.Value = num2str(app.mua083,'%2.3f');
            app.musp740.Value = num2str(app.mus074,'%2.3f');
            app.musp780.Value = num2str(app.mus078,'%2.3f');
            app.musp808.Value = num2str(app.mus080,'%2.3f');
            app.musp830.Value = num2str(app.mus083,'%2.3f');
            if app.CallingApp.tar2pertmode == 1
%                 app.TartoPertButton.Enable = true;
                TargetprocessButtonPushed(app, 1)
                msgbox(['Due to the low quality of all references, target to perturbation methods is applied.']);
            end
        end

        % Selection changed function: SourceWavelengthsButtonGroup
        function SourceWavelengthsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceWavelengthsButtonGroup.SelectedObject;
            
            if app.CallingApp.tar2pertmode == 1
                if app.tarmode
                    if app.nm740Button.Value
                        name = '740nm';
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        sddist = app.sddist74;
                        taramp = app.cali_tardata{5,3}{end};
                        tarphase = app.cali_tardata{5,4}{end};
                    elseif app.nm780Button.Value
                        name = '780nm';
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        sddist = app.sddist78;
                        taramp = app.cali_tardata{6,3}{end};
                        tarphase = app.cali_tardata{6,4}{end};
                    elseif app.nm808Button.Value
                        name = '808nm';
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        sddist = app.sddist80;
                        taramp = app.cali_tardata{7,3}{end};
                        tarphase = app.cali_tardata{7,4}{end};
                    else
                        name = '830nm';
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        sddist = app.sddist83;
                        taramp = app.cali_tardata{8,3}{end};
                        tarphase = app.cali_tardata{8,4}{end};
                    end
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
               
                else
%                     draw_tar2pert(app)
                    targetFileStruct = app.targetFileStruct;
                    draw_pert(app,targetFileStruct)
                end
                
            else
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
            end
        end

        % Value changed function: TarDropDown
        function TarDropDownValueChanged(app, event)
            app.CallingApp.tar2pertmode = 0;
            app.TartoPertButton.Enable = false;
            app.RefTextArea.Enable = true;
            app.RefTextArea.Value = app.CallingApp.reference;
            source = app.CallingApp.Ns;
            detect = app.CallingApp.Nd;
            s_geom = app.CallingApp.s_geom;
            d_geom = app.CallingApp.d_geom;
            sddist_matrix = app.CallingApp.sddist_matrix;
            pairs74 = app.CallingApp.pairs74;
            pairs78 = app.CallingApp.pairs78;
            pairs80 = app.CallingApp.pairs80;
            pairs83 = app.CallingApp.pairs83;
            Measmnt74 = app.CallingApp.Measmnt74;
            Measmnt78 = app.CallingApp.Measmnt78;
            Measmnt80 = app.CallingApp.Measmnt80;
            Measmnt83 = app.CallingApp.Measmnt83;
            Nmeas = source*detect;
            
            [tar_size, ~] = size(app.CallingApp.tar_alldata{1});
            tar_total_data = cell(tar_size*3,4);
            for i = 1:tar_size
                tar_total_data(3*i-2,:) = app.CallingApp.tar_alldata{1}(i,:);
                tar_total_data(3*i-1,:) = app.CallingApp.tar_alldata{2}(i,:);
                tar_total_data(3*i,:) = app.CallingApp.tar_alldata{3}(i,:);
            end
            tar_value = app.TarDropDown.Value;
            tar_name = strfind(app.CallingApp.tar_name,tar_value);
            tar_file_idx = find(~cellfun(@isempty, tar_name));
            aba74 = reshape(tar_total_data{tar_file_idx,1}(1:Nmeas),source,detect);
            aba78 = reshape(tar_total_data{tar_file_idx,2}(1:Nmeas),source,detect);
            aba80 = reshape(tar_total_data{tar_file_idx,3}(1:Nmeas),source,detect);
            aba83 = reshape(tar_total_data{tar_file_idx,4}(1:Nmeas),source,detect);
            abp74 = reshape(tar_total_data{tar_file_idx,1}(Nmeas+1:end),source,detect);
            abp78 = reshape(tar_total_data{tar_file_idx,2}(Nmeas+1:end),source,detect);
            abp80 = reshape(tar_total_data{tar_file_idx,3}(Nmeas+1:end),source,detect);
            abp83 = reshape(tar_total_data{tar_file_idx,4}(Nmeas+1:end),source,detect);
            
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83] = app.CallingApp.cali_data{9:12,1:2};

            % empty structure which is defined in targetFileStruct.m
            len740 = size(Measmnt74,1);
            len780 = size(Measmnt78,1);
            len808 = size(Measmnt80,1);
            len830 = size(Measmnt83,1);
            targetFileStruct = getTargetFileStruct(len740,len780,len808,len830);
            
            if ~iscell(pairs74)			% should have the same action for all four wavelength
            	pairs74 = {pairs74};
            	pairs78 = {pairs78};
            	pairs80 = {pairs80};
            	pairs83 = {pairs83};
            end    
            if ~iscell(Measmnt74)	% should have the same action for all four wavelength
            	Measmnt74 = {Measmnt74};
            	Measmnt78 = {Measmnt78};
            	Measmnt80 = {Measmnt80};
            	Measmnt83 = {Measmnt83};
            end
            
            numOfDataFiles = 1;                 % keep it consistence with multiple file load
            pert740_2d = {numOfDataFiles};      % build cell array for pert on each file and will be used by outliers removal
            pert780_2d = {numOfDataFiles}; 
            pert808_2d = {numOfDataFiles};
            pert830_2d = {numOfDataFiles};
            Measmnt740_2d = {numOfDataFiles};   % build cell array for Measmnt on each file and will be used by outliers removal
            Measmnt780_2d = {numOfDataFiles}; 
            Measmnt808_2d = {numOfDataFiles};
            Measmnt830_2d = {numOfDataFiles};
            sddist740_2d = {numOfDataFiles};
            sddist780_2d = {numOfDataFiles};
            sddist808_2d = {numOfDataFiles};
            sddist830_2d = {numOfDataFiles};

            % will eliminate the data which has real(pert) < -1.1
            [pert74,data74,Nmeas74new,Measmnt74new,sddist74new,pairs74New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs74,aba74,abp74,aba_r74,abp_r74,Measmnt74{end});  % both pert and data are cells
            [pert78,data78,Nmeas78new,Measmnt78new,sddist78new,pairs78New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs78,aba78,abp78,aba_r78,abp_r78,Measmnt78{end});
            [pert80,data80,Nmeas80new,Measmnt80new,sddist80new,pairs80New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs80,aba80,abp80,aba_r80,abp_r80,Measmnt80{end});
            [pert83,data83,Nmeas83new,Measmnt83new,sddist83new,pairs83New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs83,aba83,abp83,aba_r83,abp_r83,Measmnt83{end});
            
            app.origdata = {data74,data78,data80,data83;
                            pert74,pert78,pert80,pert83;
                            {app.sddist74{end}},{app.sddist78{end}},{app.sddist80{end}},{app.sddist83{end}};
                            {app.pairs74{end}},{app.pairs78{end}},{app.pairs80{end}},{app.pairs83{end}};
                            {app.Measmnt74{end}},{app.Measmnt78{end}},{app.Measmnt80{end}},{app.Measmnt83{end}};};
            
            pert740_2d{1} = pert74{end};  % build cell array for pert on each file and will be used by outliers removal
            pert780_2d{1} = pert78{end}; 
            pert808_2d{1} = pert80{end};
            pert830_2d{1} = pert83{end};
            Measmnt740_2d{1} = Measmnt74new{end};
            Measmnt780_2d{1} = Measmnt78new{end};
            Measmnt808_2d{1} = Measmnt80new{end};
            Measmnt830_2d{1} = Measmnt83new{end};
            sddist740_2d{1} = sddist74new{end};
            sddist780_2d{1} = sddist78new{end};
            sddist808_2d{1} = sddist80new{end};
            sddist830_2d{1} = sddist83new{end};
            pairs740_2d{1} = pairs74New{end};
            pairs780_2d{1} = pairs78New{end};
            pairs808_2d{1} = pairs80New{end};
            pairs830_2d{1} = pairs83New{end};
            
            [pert740_cell,Measmnt740_cell,sddist740_cell,pairs740_cell] = applyOutlierKMean7(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_cell,Measmnt780_cell,sddist780_cell,pairs780_cell] = applyOutlierKMean7(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_cell,Measmnt808_cell,sddist808_cell,pairs808_cell] = applyOutlierKMean7(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_cell,Measmnt830_cell,sddist830_cell,pairs830_cell] = applyOutlierKMean7(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            
            targetFileStruct(1).fileName = tar_value;
            targetFileStruct(1).fileSeq = 1; 
            targetFileStruct(1).pert740 = pert740_cell{1};      % the ones which have gone through -1.1 filter AND outliers removal
            targetFileStruct(1).pert780 = pert780_cell{1};
            targetFileStruct(1).pert808 = pert808_cell{1};
            targetFileStruct(1).pert830 = pert830_cell{1};
            targetFileStruct(1).sddist740 = sddist740_cell{1};
            targetFileStruct(1).sddist780 = sddist780_cell{1};
            targetFileStruct(1).sddist808 = sddist808_cell{1};
            targetFileStruct(1).sddist830 = sddist830_cell{1};
            targetFileStruct(1).Measmnt740 = Measmnt740_cell{1};
            targetFileStruct(1).Measmnt780 = Measmnt780_cell{1};
            targetFileStruct(1).Measmnt808 = Measmnt808_cell{1};
            targetFileStruct(1).Measmnt830 = Measmnt830_cell{1};
            targetFileStruct(1).pairs740 = pairs740_cell{1};
            targetFileStruct(1).pairs780 = pairs780_cell{1};
            targetFileStruct(1).pairs808 = pairs808_cell{1};
            targetFileStruct(1).pairs830 = pairs830_cell{1};
                
            targetFileStruct(1).pert740A = pert740_2d{1};       % the ones which DO NOT go through outliers removal, just -1.1 filter
            targetFileStruct(1).pert780A = pert780_2d{1};
            targetFileStruct(1).pert808A = pert808_2d{1};
            targetFileStruct(1).pert830A = pert830_2d{1};
            targetFileStruct(1).sddist740A = sddist740_2d{1};
            targetFileStruct(1).sddist780A = sddist780_2d{1};
            targetFileStruct(1).sddist808A = sddist808_2d{1};
            targetFileStruct(1).sddist830A = sddist830_2d{1};
            targetFileStruct(1).Measmnt740A = Measmnt740_2d{1};
            targetFileStruct(1).Measmnt780A = Measmnt780_2d{1};
            targetFileStruct(1).Measmnt808A = Measmnt808_2d{1};
            targetFileStruct(1).Measmnt830A = Measmnt830_2d{1};
            targetFileStruct(1).pairs740A = pairs740_2d{1};
            targetFileStruct(1).pairs780A = pairs780_2d{1};
            targetFileStruct(1).pairs808A = pairs808_2d{1};
            targetFileStruct(1).pairs830A = pairs830_2d{1};
            
            [pert740_lof,Measmnt740_lof,sddist740_lof,pairs740_lof] = LOFmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_lof,Measmnt780_lof,sddist780_lof,pairs780_lof] = LOFmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_lof,Measmnt808_lof,sddist808_lof,pairs808_lof] = LOFmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_lof,Measmnt830_lof,sddist830_lof,pairs830_lof] = LOFmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            targetFileStruct(1).pert740L = pert740_lof{1};       % the ones which go through LOF outliers removal
            targetFileStruct(1).pert780L = pert780_lof{1};
            targetFileStruct(1).pert808L = pert808_lof{1};
            targetFileStruct(1).pert830L = pert830_lof{1};
            targetFileStruct(1).sddist740L = sddist740_lof{1};
            targetFileStruct(1).sddist780L = sddist780_lof{1};
            targetFileStruct(1).sddist808L = sddist808_lof{1};
            targetFileStruct(1).sddist830L = sddist830_lof{1};
            targetFileStruct(1).Measmnt740L = Measmnt740_lof{1};
            targetFileStruct(1).Measmnt780L = Measmnt780_lof{1};
            targetFileStruct(1).Measmnt808L = Measmnt808_lof{1};
            targetFileStruct(1).Measmnt830L = Measmnt830_lof{1};
            targetFileStruct(1).pairs740L = pairs740_lof{1};
            targetFileStruct(1).pairs780L = pairs780_lof{1};
            targetFileStruct(1).pairs808L = pairs808_lof{1};
            targetFileStruct(1).pairs830L = pairs830_lof{1};
            range = app.ELPconfidenceintervalEditField.Value;
            [pert740_elp,Measmnt740_elp,sddist740_elp,pairs740_elp] = ELPmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect,range);
            [pert780_elp,Measmnt780_elp,sddist780_elp,pairs780_elp] = ELPmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect,range);
            [pert808_elp,Measmnt808_elp,sddist808_elp,pairs808_elp] = ELPmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect,range);
            [pert830_elp,Measmnt830_elp,sddist830_elp,pairs830_elp] = ELPmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect,range);
            targetFileStruct(1).pert740E = pert740_elp{1};       % the ones which go through elp outliers removal
            targetFileStruct(1).pert780E = pert780_elp{1};
            targetFileStruct(1).pert808E = pert808_elp{1};
            targetFileStruct(1).pert830E = pert830_elp{1};
            targetFileStruct(1).sddist740E = sddist740_elp{1};
            targetFileStruct(1).sddist780E = sddist780_elp{1};
            targetFileStruct(1).sddist808E = sddist808_elp{1};
            targetFileStruct(1).sddist830E = sddist830_elp{1};
            targetFileStruct(1).Measmnt740E = Measmnt740_elp{1};
            targetFileStruct(1).Measmnt780E = Measmnt780_elp{1};
            targetFileStruct(1).Measmnt808E = Measmnt808_elp{1};
            targetFileStruct(1).Measmnt830E = Measmnt830_elp{1};
            targetFileStruct(1).pairs740E = pairs740_elp{1};
            targetFileStruct(1).pairs780E = pairs780_elp{1};
            targetFileStruct(1).pairs808E = pairs808_elp{1};
            targetFileStruct(1).pairs830E = pairs830_elp{1};
                
            app.targetFileStruct = targetFileStruct;
            app.pairs74 = {targetFileStruct(1).pairs740};
            app.pairs78 = {targetFileStruct(1).pairs780};
            app.pairs80 = {targetFileStruct(1).pairs808};
            app.pairs83 = {targetFileStruct(1).pairs830};
            app.sddist_matrix = sddist_matrix;
            app.sddist74 = {targetFileStruct(1).sddist740}; % make it into cell array because of undo
            app.sddist78 = {targetFileStruct(1).sddist780};
            app.sddist80 = {targetFileStruct(1).sddist808};
            app.sddist83 = {targetFileStruct(1).sddist830};
            
            app.Measmnt74 = Measmnt740_cell{1};
            app.Measmnt78 = Measmnt780_cell{1};
            app.Measmnt80 = Measmnt808_cell{1};
            app.Measmnt83 = Measmnt830_cell{1};
            if ~iscell(app.Measmnt74)
                app.Measmnt74 = {app.Measmnt74};
                app.Measmnt78 = {app.Measmnt78};
                app.Measmnt80 = {app.Measmnt80};
                app.Measmnt83 = {app.Measmnt83};
            end
            
            app.pert74 = {targetFileStruct(1).pert740};
            app.pert78 = {targetFileStruct(1).pert780};
            app.pert80 = {targetFileStruct(1).pert808};
            app.pert83 = {targetFileStruct(1).pert830};
            
            app.data74 = {[real(targetFileStruct(1).pert740) imag(targetFileStruct(1).pert740)]};  %can't have any transportation because they are complex numbers
            app.data78 = {[real(targetFileStruct(1).pert780) imag(targetFileStruct(1).pert780)]};
            app.data80 = {[real(targetFileStruct(1).pert808) imag(targetFileStruct(1).pert808)]};
            app.data83 = {[real(targetFileStruct(1).pert830) imag(targetFileStruct(1).pert830)]};
            
            app.CallingApp.bga74 = aba_r74;    % get from load reference file on the imger_4wvl
            app.CallingApp.bgp74 = abp_r74;
            app.CallingApp.bga78 = aba_r78;
            app.CallingApp.bgp78 = abp_r78;
            app.CallingApp.bga80 = aba_r80;
            app.CallingApp.bgp80 = abp_r80;
            app.CallingApp.bga83 = aba_r83;
            app.CallingApp.bgp83 = abp_r83;
            
            app.CallingApp.aba74 = aba74;
            app.CallingApp.aba78 = aba78;
            app.CallingApp.aba80 = aba80;
            app.CallingApp.aba83 = aba83;
            app.CallingApp.abp74 = abp74;
            app.CallingApp.abp78 = abp78;
            app.CallingApp.abp80 = abp80;
            app.CallingApp.abp83 = abp83;
            
            targetFileStruct = app.targetFileStruct;
            app.initial_targetFileStruct = targetFileStruct;
            draw_pert(app,targetFileStruct)
            
            undoStruct = getCurrentDisplayInfo(app,targetFileStruct);
            app.undoStructCell{1}{1} = undoStruct;  % initial value
            
            app.undoSeq = 0;                        % initial setting for single target file
            app.numofundo.Value = num2str(app.undoSeq);
            
        end

        % Value changed function: KmeansOutliersRemovalCheckBox
        function KmeansOutliersRemovalCheckBoxValueChanged(app, event)
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            else
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
            end          
        end

        % Button pushed function: SelectPointButton
        function SelectPointButtonPushed(app, event)
            %Delete box outlining selected points if there is already one there
            lines = findobj(gcbf,'Tag','L');
            delete(lines);
            
            app.UIFigure.HandleVisibility = 'on';
            
            %User selects region from which points should be removed
            k = waitforbuttonpress;
            bound = rbbox;
            
            selected_axis = gca; %Determine which data set is being edited (phase or amplitude for intralipid or ref)
            
            %All objects need to be measured in the same units
            set(selected_axis,'Units','pixels');
            
            P = get(selected_axis, 'InnerPosition'); %Of axes, within the figure window
            
            %Get limits of plot
            xlimit = get(selected_axis, 'Xlim');
            ylimit = get(selected_axis, 'Ylim');
            
            % Get the coordinates of the lower left corner of the box,
            % its height and width, and the X-Y coordinates of the 4 corners
            deltax = xlimit(2)-xlimit(1);
            deltay = ylimit(2)-ylimit(1);
            left_dist = bound(1)-P(1); %Distance of rbbox from left boundary of axes
            up_dist = bound(2)-P(2);%Distance from lower boundary of axes
            
            %Convert dimensions of rbbox to x and y coordinates
            xlow = xlimit(1)+deltax*left_dist/P(3);
            xhigh = xlimit(1)+deltax*(left_dist+bound(3))/P(3);
            ylow = ylimit(1)+deltay*up_dist/P(4);
            yhigh = ylimit(1)+deltay*(up_dist+bound(4))/P(4);
            
            %X-Y information about the corners of the RBBOX
            xbounds = [xlow, xhigh];
            ybounds = [ylow, yhigh];
            
            %Create lines outlining the selected area
            r1 = rectangle(selected_axis,'Position',[xlow,ylow,xhigh-xlow,yhigh-ylow]);
            set(r1,'Tag','L');
            
%             app.UIFigure.HandleVisibility = 'off';
            app.selected_axis = selected_axis;
            app.xbounds = xbounds;
            app.ybounds = ybounds;
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            if isempty(app.selected_axis) || isempty(app.xbounds)
                return
            end
            
            selected_axis = app.selected_axis;
            xbounds = app.xbounds;
            ybounds = app.ybounds;
            if app.nm740Button.Value
                app.count740 = app.count740 + 1;
            elseif app.nm780Button.Value
                app.count780 = app.count780 + 1;
            elseif app.nm808Button.Value
                app.count808 = app.count808 + 1;
            elseif app.nm830Button.Value
                app.count830 = app.count830 + 1;
            end
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                freq = 140;
                Refl = 0;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Realpart
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp74_log{end}>min(ybounds) & amp74_log{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                        case app.Imagpart
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase74{end}>min(ybounds) & phase74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    end
                    [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(Badpoints,...
                        sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                    [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                        sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                        corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                    taramp = amp74_log{end};
                    tarphase = phase74{end};
                    sddist = sddist74;
                    app.refmuaTextArea.Value = num2str(app.mua074);
                    app.refmuspTextArea.Value = num2str(app.mus074);
                    app.pairs74 = pairs74;
                    app.sddist74 = sddist74;
                    app.Measmnt74 = Measmnt74;
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Realpart
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp78_log{end}>min(ybounds) & amp78_log{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                        case app.Imagpart
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase78{end}>min(ybounds) & phase78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                    end
                    [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(Badpoints,...
                        sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                    [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                        sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                        corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                    taramp = amp78_log{end};
                    tarphase = phase78{end};
                    sddist = sddist78;
                    app.refmuaTextArea.Value = num2str(app.mua078);
                    app.refmuspTextArea.Value = num2str(app.mus078);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Realpart
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp80_log{end}>min(ybounds) & amp80_log{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                        case app.Imagpart
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase80{end}>min(ybounds) & phase80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                    end
                    [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(Badpoints,...
                        sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                    [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                        sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                        corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                    taramp = amp80_log{end};
                    tarphase = phase80{end};
                    sddist = sddist80;
                    app.refmuaTextArea.Value = num2str(app.mua080);
                    app.refmuspTextArea.Value = num2str(app.mus080);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                else
                    name = '830nm';
                    switch selected_axis
                        case app.Realpart
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp83_log{end}>min(ybounds) & amp83_log{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                        case app.Imagpart
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase83{end}>min(ybounds) & phase83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                    end
                    [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(Badpoints,...
                        sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                    [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                        sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                        corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                    taramp = amp83_log{end};
                    tarphase = phase83{end};
                    sddist = sddist83;
                    app.refmuaTextArea.Value = num2str(app.mua083);
                    app.refmuspTextArea.Value = num2str(app.mus083);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;
                
                
            else
                undoStruct = getCurrentDisplayInfo(app,app.targetFileStruct);
            
                if isempty(app.undoStructCell)           % not possible to get here
                    app.undoSeq = 0;                        % initial setting
                    app.undoStructCell{1} = undoStruct;     % initial value
                end 
                
                pert = undoStruct.pert;                         % current display information on the screen
                sddist = undoStruct.sddist;
                Measmnt = undoStruct.Measmnt;   
                
                switch selected_axis
                    case app.Realpart
                        BadPoints = find(real(pert)>min(ybounds) & real(pert)<max(ybounds) & sddist>min(xbounds) & sddist<max(xbounds));
                    case app.Imagpart
                        BadPoints = find(imag(pert)>min(ybounds) & imag(pert)<max(ybounds) & sddist>min(xbounds) & sddist<max(xbounds));
                    case app.pert
                        BadPoints = find(real(pert)>min(xbounds) & real(pert)<max(xbounds) & imag(pert)>min(ybounds) & imag(pert)<max(ybounds));
                end 
                
                if isempty(BadPoints)
                    return;
                else
                    pert(BadPoints) = [];                       % modify current display information on the screen
                    Measmnt(BadPoints,:) = [];
                    sddist(BadPoints) = [];  
                end
                
                undoStruct.pert = pert;
                undoStruct.sddist = sddist;
                undoStruct.Measmnt = Measmnt;
                
                app.undoStructCell{undoStruct.targetFileSelected}{end+1} = undoStruct;
                app.undoSeq = length(app.undoStructCell{undoStruct.targetFileSelected}) - 1;
                
                targetFileStruct = setCurrentDisplayInfo(app,app.undoStructCell{undoStruct.targetFileSelected}{end});
                app.targetFileStruct = targetFileStruct;
                
                draw_pert(app,targetFileStruct)
                app.numofundo.Value = num2str(app.undoSeq);
            end
            
            
            app.xbounds = [];
            app.ybounds = [];
        end

        % Button pushed function: UndoLastButton
        function UndoLastButtonPushed(app, event)
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                
                if app.nm740Button.Value
                    name = '740nm';
                    sddist74 = sddist74(1,1:end-1);
                    A_m74 = A_m74(1,1:end-1);
                    P_m74 = P_m74(1,1:end-1);
                    pairs74 = pairs74(1,1:end-1);
                    Measmnt74 = Measmnt74(1,1:end-1);
                    muabg74 = muabg74(1,1:end-1);
                    muspbg74 = muspbg74(1,1:end-1);
                    muabg74a = muabg74a(1,1:end-1);
                    muspbg74a = muspbg74a(1,1:end-1);
                    phase74 = phase74(1,1:end-1);
                    amp74_log = amp74_log(1,1:end-1);
                    corr_ki74 = corr_ki74(1,1:end-1);
                    corr_kr74 = corr_kr74(1,1:end-1);
                    
                    taramp = amp74_log{end};
                    tarphase = phase74{end};
                    sddist = sddist74;
                    app.refmuaTextArea.Value = num2str(app.mua074);
                    app.refmuspTextArea.Value = num2str(app.mus074);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    sddist78 = sddist78(1,1:end-1);
                    A_m78 = A_m78(1,1:end-1);
                    P_m78 = P_m78(1,1:end-1);
                    pairs78 = pairs78(1,1:end-1);
                    Measmnt78 = Measmnt78(1,1:end-1);
                    muabg78 = muabg78(1,1:end-1);
                    muspbg78 = muspbg78(1,1:end-1);
                    muabg78a = muabg78a(1,1:end-1);
                    muspbg78a = muspbg78a(1,1:end-1);
                    phase78 = phase78(1,1:end-1);
                    amp78_log = amp78_log(1,1:end-1);
                    corr_ki78 = corr_ki78(1,1:end-1);
                    corr_kr78 = corr_kr78(1,1:end-1);
                    
                    taramp = amp78_log{end};
                    tarphase = phase78{end};
                    sddist = sddist78;
                    app.refmuaTextArea.Value = num2str(app.mua078);
                    app.refmuspTextArea.Value = num2str(app.mus078);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    sddist80 = sddist80(1,1:end-1);
                    A_m80 = A_m80(1,1:end-1);
                    P_m80 = P_m80(1,1:end-1);
                    pairs80 = pairs80(1,1:end-1);
                    Measmnt80 = Measmnt80(1,1:end-1);
                    muabg80 = muabg80(1,1:end-1);
                    muspbg80 = muspbg80(1,1:end-1);
                    muabg80a = muabg80a(1,1:end-1);
                    muspbg80a = muspbg80a(1,1:end-1);
                    phase80 = phase80(1,1:end-1);
                    amp80_log = amp80_log(1,1:end-1);
                    corr_ki80 = corr_ki80(1,1:end-1);
                    corr_kr80 = corr_kr80(1,1:end-1);
                    
                    taramp = amp80_log{end};
                    tarphase = phase80{end};
                    sddist = sddist80;
                    app.refmuaTextArea.Value = num2str(app.mua080);
                    app.refmuspTextArea.Value = num2str(app.mus080);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    sddist83 = sddist83(1,1:end-1);
                    A_m83 = A_m83(1,1:end-1);
                    P_m83 = P_m83(1,1:end-1);
                    pairs83 = pairs83(1,1:end-1);
                    Measmnt83 = Measmnt83(1,1:end-1);
                    muabg83 = muabg83(1,1:end-1);
                    muspbg83 = muspbg83(1,1:end-1);
                    muabg83a = muabg83a(1,1:end-1);
                    muspbg83a = muspbg83a(1,1:end-1);
                    phase83 = phase83(1,1:end-1);
                    amp83_log = amp83_log(1,1:end-1);
                    corr_ki83 = corr_ki83(1,1:end-1);
                    corr_kr83 = corr_kr83(1,1:end-1);
                    
                    taramp = amp83_log{end};
                    tarphase = phase83{end};
                    sddist = sddist83;
                    app.refmuaTextArea.Value = num2str(app.mua083);
                    app.refmuspTextArea.Value = num2str(app.mus083);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;
            else
                if isempty(app.undoStructCell)
                    return
                end
                wave_index = 0;
                targetFileSelected = 1;
                undo_wave = [];
                undoStructCell = app.undoStructCell{targetFileSelected};
                undoStructCell_1 = undoStructCell{1};
                if length(undoStructCell) == 1                      % No changes have been made so there is nothing to be undone
                    app.undoSeq = 0;
                    app.numofundo.Value = num2str(app.undoSeq);
                    return;
                else
                    if undoStructCell{end}.wavelength == 740
                        app.nm740Button.Value = 1;
                        app.count740 = app.count740 - 1;
                        wave_index = 1;
                    elseif undoStructCell{end}.wavelength == 780
                        app.nm780Button.Value = 1;
                        app.count780 = app.count780 - 1;
                        wave_index = 2;
                    elseif undoStructCell{end}.wavelength == 808
                        app.nm808Button.Value = 1;
                        app.count808 = app.count808 - 1;
                        wave_index = 3;
                    elseif undoStructCell{end}.wavelength == 830
                        app.nm830Button.Value = 1;
                        app.count830 = app.count830 - 1;
                        wave_index = 4;
                    end
                    undo_index = [app.count740, app.count780, app.count808, app.count830];
                    for i = 1:length(undoStructCell)-1
                        undo_wave(i,:) = undoStructCell{i}.index;
                    end
                    choice = find(undo_wave(:,wave_index)==undo_index(wave_index),1);
                    if choice == 1
                        undoStruct = getCurrentDisplayInfo(app,app.initial_targetFileStruct);
                        undoStructCell{1} = undoStruct;  % initial value
                    end
                    undoStructCell = undoStructCell(1,1:end-1);
                    app.undoSeq = length(undoStructCell) - 1;
                    app.numofundo.Value = num2str(app.undoSeq);
                end
                
                targetFileStruct = setCurrentDisplayInfo(app,undoStructCell{choice});  % set current info to the targetFileStruct
                app.targetFileStruct = targetFileStruct;
                
                draw_pert(app,targetFileStruct)
                app.numofundo.Value = num2str(app.undoSeq);
                undoStructCell{1} = undoStructCell_1;
                app.undoStructCell{targetFileSelected} = undoStructCell;
            end
            
        end

        % Button pushed function: UndoAllButton
        function UndoAllButtonPushed(app, event)
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                
                if app.nm740Button.Value
                    name = '740nm';
                    sddist74 = sddist74(1);
                    A_m74 = A_m74(1);
                    P_m74 = P_m74(1);
                    pairs74 = pairs74(1);
                    Measmnt74 = Measmnt74(1);
                    muabg74 = muabg74(1);
                    muspbg74 = muspbg74(1);
                    muabg74a = muabg74a(1);
                    muspbg74a = muspbg74a(1);
                    phase74 = phase74(1);
                    amp74_log = amp74_log(1);
                    corr_ki74 = corr_ki74(1);
                    corr_kr74 = corr_kr74(1);
                    
                    taramp = amp74_log{end};
                    tarphase = phase74{end};
                    sddist = sddist74;
                    app.refmuaTextArea.Value = num2str(app.mua074);
                    app.refmuspTextArea.Value = num2str(app.mus074);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    sddist78 = sddist78(1);
                    A_m78 = A_m78(1);
                    P_m78 = P_m78(1);
                    pairs78 = pairs78(1);
                    Measmnt78 = Measmnt78(1);
                    muabg78 = muabg78(1);
                    muspbg78 = muspbg78(1);
                    muabg78a = muabg78a(1);
                    muspbg78a = muspbg78a(1);
                    phase78 = phase78(1);
                    amp78_log = amp78_log(1);
                    corr_ki78 = corr_ki78(1);
                    corr_kr78 = corr_kr78(1);
                    
                    taramp = amp78_log{end};
                    tarphase = phase78{end};
                    sddist = sddist78;
                    app.refmuaTextArea.Value = num2str(app.mua078);
                    app.refmuspTextArea.Value = num2str(app.mus078);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    sddist80 = sddist80(1);
                    A_m80 = A_m80(1);
                    P_m80 = P_m80(1);
                    pairs80 = pairs80(1);
                    Measmnt80 = Measmnt80(1);
                    muabg80 = muabg80(1);
                    muspbg80 = muspbg80(1);
                    muabg80a = muabg80a(1);
                    muspbg80a = muspbg80a(1);
                    phase80 = phase80(1);
                    amp80_log = amp80_log(1);
                    corr_ki80 = corr_ki80(1);
                    corr_kr80 = corr_kr80(1);
                    
                    taramp = amp80_log{end};
                    tarphase = phase80{end};
                    sddist = sddist80;
                    app.refmuaTextArea.Value = num2str(app.mua080);
                    app.refmuspTextArea.Value = num2str(app.mus080);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    sddist83 = sddist83(1);
                    A_m83 = A_m83(1);
                    P_m83 = P_m83(1);
                    pairs83 = pairs83(1);
                    Measmnt83 = Measmnt83(1);
                    muabg83 = muabg83(1);
                    muspbg83 = muspbg83(1);
                    muabg83a = muabg83a(1);
                    muspbg83a = muspbg83a(1);
                    phase83 = phase83(1);
                    amp83_log = amp83_log(1);
                    corr_ki83 = corr_ki83(1);
                    corr_kr83 = corr_kr83(1);
                    
                    taramp = amp83_log{end};
                    tarphase = phase83{end};
                    sddist = sddist83;
                    app.refmuaTextArea.Value = num2str(app.mua083);
                    app.refmuspTextArea.Value = num2str(app.mus083);
                    
                    plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;
            else
                errordlg('Changes on perturbation do not supported by Undo All','ERROR');
            end
        end

        % Button pushed function: CGDButton
        function CGDButtonPushed(app, event)
            imagemethod(app,0)
        end

        % Button pushed function: CGDREGButton
        function CGDREGButtonPushed(app, event)
            imagemethod(app,1)
        end

        % Button pushed function: AutoEncoderButton
        function AutoEncoderButtonPushed(app, event)
            imagemethod(app,2)
        end

        % Button pushed function: TartoPertButton
        function TartoPertButtonPushed(app, event)
            % Generate perturbation data only from current target data
            app.tarmode = 0;
            f = waitbar(0,'Please wait for Target to perturbation results ...');
            pause(0.5)     
            
            app.FittingUnwrapButton.Enable = false;
            app.FittingDeleteButton.Enable = false;
            app.ClusterUnwrapButton.Enable = false;
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            Nmeas = source*detect;
            sddist_matrix = app.sddist_matrix;
            if s_geom(1,2) == 4.012 
                sddist =  sddist_matrix([3 5 7 9 2 4 6 8 1],[5 1 8 12 6 2 14 9 7 10 3 13 4 11]);  
                sddist = reshape(sddist.',[1,Nmeas]);  
            else
                sddist = reshape(sddist_matrix.',[1,Nmeas]);  
            end
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            
            if ~iscell(pairs74)			% should have the same action for all four wavelength
                	pairs74 = {pairs74};
                	pairs78 = {pairs78};
                	pairs80 = {pairs80};
                	pairs83 = {pairs83};
            end    
            if ~iscell(Measmnt74)	% should have the same action for all four wavelength
            	Measmnt74 = {Measmnt74};
            	Measmnt78 = {Measmnt78};
            	Measmnt80 = {Measmnt80};
            	Measmnt83 = {Measmnt83};
            end
            
            [amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,3:4};
            [amp74,phase74] = process_tar2pert(app,s_geom,source,detect,sddist_matrix,pairs74,amp74_log{end},phase74{end},Measmnt74);
            [amp78,phase78] = process_tar2pert(app,s_geom,source,detect,sddist_matrix,pairs78,amp78_log{end},phase78{end},Measmnt78);
            [amp80,phase80] = process_tar2pert(app,s_geom,source,detect,sddist_matrix,pairs80,amp80_log{end},phase80{end},Measmnt80);
            [amp83,phase83] = process_tar2pert(app,s_geom,source,detect,sddist_matrix,pairs83,amp83_log{end},phase83{end},Measmnt83);
            
            x_test74 = [amp74,phase74];
            x_test78 = [amp78,phase78];
            x_test80 = [amp80,phase80];
            x_test83 = [amp83,phase83];
            app.output_pert74 = double(single(py.main_tar2pert.patient_test(py.numpy.array(x_test74))));
            app.output_pert78 = double(single(py.main_tar2pert.patient_test(py.numpy.array(x_test78))));
            app.output_pert80 = double(single(py.main_tar2pert.patient_test(py.numpy.array(x_test80))));
            app.output_pert83 = double(single(py.main_tar2pert.patient_test(py.numpy.array(x_test83))));
            waitbar(.60,f,'Please wait for Target to perturbation results ...');
%             draw_tar2pert(app)
            
            app.CGDButton.Enable = true;
            app.CGDREGButton.Enable = true;
            app.AutoEncoderButton.Enable = true;
            app.L1Button.Enable = true;
            app.UndoAllButton.Enable = false;
            
            len740 = size(Measmnt74,1);
            len780 = size(Measmnt78,1);
            len808 = size(Measmnt80,1);
            len830 = size(Measmnt83,1);
            targetFileStruct = getTargetFileStruct(len740,len780,len808,len830);
            
            numOfDataFiles = 1;                 % keep it consistence with multiple file load
            pert740_2d = {numOfDataFiles};      % build cell array for pert on each file and will be used by outliers removal
            pert780_2d = {numOfDataFiles}; 
            pert808_2d = {numOfDataFiles};
            pert830_2d = {numOfDataFiles};
            Measmnt740_2d = {numOfDataFiles};   % build cell array for Measmnt on each file and will be used by outliers removal
            Measmnt780_2d = {numOfDataFiles}; 
            Measmnt808_2d = {numOfDataFiles};
            Measmnt830_2d = {numOfDataFiles};
            sddist740_2d = {numOfDataFiles};
            sddist780_2d = {numOfDataFiles};
            sddist808_2d = {numOfDataFiles};
            sddist830_2d = {numOfDataFiles};
            
            pert74 = app.output_pert74(1:Nmeas) + 1i*app.output_pert74(Nmeas+1:end);
            pert78 = app.output_pert78(1:Nmeas) + 1i*app.output_pert78(Nmeas+1:end);
            pert80 = app.output_pert80(1:Nmeas) + 1i*app.output_pert80(Nmeas+1:end);
            pert83 = app.output_pert83(1:Nmeas) + 1i*app.output_pert83(Nmeas+1:end);
            pert740_2d{1} = pert74;  % build cell array for pert on each file and will be used by outliers removal
            pert780_2d{1} = pert78; 
            pert808_2d{1} = pert80;
            pert830_2d{1} = pert83;
            Measmnt740_2d{1} = app.CallingApp.Measmnt74{1};
            Measmnt780_2d{1} = app.CallingApp.Measmnt78{1};
            Measmnt808_2d{1} = app.CallingApp.Measmnt80{1};
            Measmnt830_2d{1} = app.CallingApp.Measmnt83{1};
            sddist740_2d{1} = sddist;
            sddist780_2d{1} = sddist;
            sddist808_2d{1} = sddist;
            sddist830_2d{1} = sddist;
            pairs740_2d{1} = app.CallingApp.pairs74{1};
            pairs780_2d{1} = app.CallingApp.pairs78{1};
            pairs808_2d{1} = app.CallingApp.pairs80{1};
            pairs830_2d{1} = app.CallingApp.pairs83{1};
            
            [pert740_cell,Measmnt740_cell,sddist740_cell,pairs740_cell] = applyOutlierKMean7(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_cell,Measmnt780_cell,sddist780_cell,pairs780_cell] = applyOutlierKMean7(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_cell,Measmnt808_cell,sddist808_cell,pairs808_cell] = applyOutlierKMean7(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_cell,Measmnt830_cell,sddist830_cell,pairs830_cell] = applyOutlierKMean7(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            
            tar_value = app.TarDropDown.Value;
            targetFileStruct(1).fileName = tar_value;
            targetFileStruct(1).fileSeq = 1; 
            targetFileStruct(1).pert740 = pert740_cell{1};      % the ones which have gone through -1.1 filter AND outliers removal
            targetFileStruct(1).pert780 = pert780_cell{1};
            targetFileStruct(1).pert808 = pert808_cell{1};
            targetFileStruct(1).pert830 = pert830_cell{1};
            targetFileStruct(1).sddist740 = sddist740_cell{1};
            targetFileStruct(1).sddist780 = sddist780_cell{1};
            targetFileStruct(1).sddist808 = sddist808_cell{1};
            targetFileStruct(1).sddist830 = sddist830_cell{1};
            targetFileStruct(1).Measmnt740 = Measmnt740_cell{1};
            targetFileStruct(1).Measmnt780 = Measmnt780_cell{1};
            targetFileStruct(1).Measmnt808 = Measmnt808_cell{1};
            targetFileStruct(1).Measmnt830 = Measmnt830_cell{1};
            targetFileStruct(1).pairs740 = pairs740_cell{1};
            targetFileStruct(1).pairs780 = pairs780_cell{1};
            targetFileStruct(1).pairs808 = pairs808_cell{1};
            targetFileStruct(1).pairs830 = pairs830_cell{1};
                
            targetFileStruct(1).pert740A = pert740_2d{1};       % the ones which DO NOT go through outliers removal, just -1.1 filter
            targetFileStruct(1).pert780A = pert780_2d{1};
            targetFileStruct(1).pert808A = pert808_2d{1};
            targetFileStruct(1).pert830A = pert830_2d{1};
            targetFileStruct(1).sddist740A = sddist740_2d{1};
            targetFileStruct(1).sddist780A = sddist780_2d{1};
            targetFileStruct(1).sddist808A = sddist808_2d{1};
            targetFileStruct(1).sddist830A = sddist830_2d{1};
            targetFileStruct(1).Measmnt740A = Measmnt740_2d{1};
            targetFileStruct(1).Measmnt780A = Measmnt780_2d{1};
            targetFileStruct(1).Measmnt808A = Measmnt808_2d{1};
            targetFileStruct(1).Measmnt830A = Measmnt830_2d{1};
            targetFileStruct(1).pairs740A = pairs740_2d{1};
            targetFileStruct(1).pairs780A = pairs780_2d{1};
            targetFileStruct(1).pairs808A = pairs808_2d{1};
            targetFileStruct(1).pairs830A = pairs830_2d{1};
            
            [pert740_lof,Measmnt740_lof,sddist740_lof,pairs740_lof] = LOFmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_lof,Measmnt780_lof,sddist780_lof,pairs780_lof] = LOFmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_lof,Measmnt808_lof,sddist808_lof,pairs808_lof] = LOFmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_lof,Measmnt830_lof,sddist830_lof,pairs830_lof] = LOFmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            targetFileStruct(1).pert740L = pert740_lof{1};       % the ones which go through LOF outliers removal
            targetFileStruct(1).pert780L = pert780_lof{1};
            targetFileStruct(1).pert808L = pert808_lof{1};
            targetFileStruct(1).pert830L = pert830_lof{1};
            targetFileStruct(1).sddist740L = sddist740_lof{1};
            targetFileStruct(1).sddist780L = sddist780_lof{1};
            targetFileStruct(1).sddist808L = sddist808_lof{1};
            targetFileStruct(1).sddist830L = sddist830_lof{1};
            targetFileStruct(1).Measmnt740L = Measmnt740_lof{1};
            targetFileStruct(1).Measmnt780L = Measmnt780_lof{1};
            targetFileStruct(1).Measmnt808L = Measmnt808_lof{1};
            targetFileStruct(1).Measmnt830L = Measmnt830_lof{1};
            targetFileStruct(1).pairs740L = pairs740_lof{1};
            targetFileStruct(1).pairs780L = pairs780_lof{1};
            targetFileStruct(1).pairs808L = pairs808_lof{1};
            targetFileStruct(1).pairs830L = pairs830_lof{1};
            range = app.ELPconfidenceintervalEditField.Value;
            [pert740_elp,Measmnt740_elp,sddist740_elp,pairs740_elp] = ELPmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect,range);
            [pert780_elp,Measmnt780_elp,sddist780_elp,pairs780_elp] = ELPmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect,range);
            [pert808_elp,Measmnt808_elp,sddist808_elp,pairs808_elp] = ELPmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect,range);
            [pert830_elp,Measmnt830_elp,sddist830_elp,pairs830_elp] = ELPmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect,range);
            targetFileStruct(1).pert740E = pert740_elp{1};       % the ones which go through elp outliers removal
            targetFileStruct(1).pert780E = pert780_elp{1};
            targetFileStruct(1).pert808E = pert808_elp{1};
            targetFileStruct(1).pert830E = pert830_elp{1};
            targetFileStruct(1).sddist740E = sddist740_elp{1};
            targetFileStruct(1).sddist780E = sddist780_elp{1};
            targetFileStruct(1).sddist808E = sddist808_elp{1};
            targetFileStruct(1).sddist830E = sddist830_elp{1};
            targetFileStruct(1).Measmnt740E = Measmnt740_elp{1};
            targetFileStruct(1).Measmnt780E = Measmnt780_elp{1};
            targetFileStruct(1).Measmnt808E = Measmnt808_elp{1};
            targetFileStruct(1).Measmnt830E = Measmnt830_elp{1};
            targetFileStruct(1).pairs740E = pairs740_elp{1};
            targetFileStruct(1).pairs780E = pairs780_elp{1};
            targetFileStruct(1).pairs808E = pairs808_elp{1};
            targetFileStruct(1).pairs830E = pairs830_elp{1};
            
            app.targetFileStruct = targetFileStruct;
            app.pairs74 = {targetFileStruct(1).pairs740};
            app.pairs78 = {targetFileStruct(1).pairs780};
            app.pairs80 = {targetFileStruct(1).pairs808};
            app.pairs83 = {targetFileStruct(1).pairs830};
            app.sddist_matrix = sddist_matrix;
            app.sddist74 = {targetFileStruct(1).sddist740}; % make it into cell array because of undo
            app.sddist78 = {targetFileStruct(1).sddist780};
            app.sddist80 = {targetFileStruct(1).sddist808};
            app.sddist83 = {targetFileStruct(1).sddist830};
            
            app.Measmnt74 = Measmnt740_cell{1};
            app.Measmnt78 = Measmnt780_cell{1};
            app.Measmnt80 = Measmnt808_cell{1};
            app.Measmnt83 = Measmnt830_cell{1};
            if ~iscell(app.Measmnt74)
                app.Measmnt74 = {app.Measmnt74};
                app.Measmnt78 = {app.Measmnt78};
                app.Measmnt80 = {app.Measmnt80};
                app.Measmnt83 = {app.Measmnt83};
            end
            
            app.pert74 = {targetFileStruct(1).pert740};
            app.pert78 = {targetFileStruct(1).pert780};
            app.pert80 = {targetFileStruct(1).pert808};
            app.pert83 = {targetFileStruct(1).pert830};
            
            app.data74 = {[real(targetFileStruct(1).pert740) imag(targetFileStruct(1).pert740)]};  %can't have any transportation because they are complex numbers
            app.data78 = {[real(targetFileStruct(1).pert780) imag(targetFileStruct(1).pert780)]};
            app.data80 = {[real(targetFileStruct(1).pert808) imag(targetFileStruct(1).pert808)]};
            app.data83 = {[real(targetFileStruct(1).pert830) imag(targetFileStruct(1).pert830)]};
            
%             app.CallingApp.bga74 = aba_r74;    % get from load reference file on the imger_4wvl
%             app.CallingApp.bgp74 = abp_r74;
%             app.CallingApp.bga78 = aba_r78;
%             app.CallingApp.bgp78 = abp_r78;
%             app.CallingApp.bga80 = aba_r80;
%             app.CallingApp.bgp80 = abp_r80;
%             app.CallingApp.bga83 = aba_r83;
%             app.CallingApp.bgp83 = abp_r83;
%             
%             app.CallingApp.aba74 = aba74;
%             app.CallingApp.aba78 = aba78;
%             app.CallingApp.aba80 = aba80;
%             app.CallingApp.aba83 = aba83;
%             app.CallingApp.abp74 = abp74;
%             app.CallingApp.abp78 = abp78;
%             app.CallingApp.abp80 = abp80;
%             app.CallingApp.abp83 = abp83;

            targetFileStruct = app.targetFileStruct;
            app.initial_targetFileStruct = targetFileStruct;
            draw_pert(app,targetFileStruct)
            
            undoStruct = getCurrentDisplayInfo(app,targetFileStruct);
            app.undoStructCell{1}{1} = undoStruct;  % initial value
            waitbar(.99,f,'Please wait for Target to perturbation results ...');
            app.undoSeq = 0;                        % initial setting for single target file
            app.numofundo.Value = num2str(app.undoSeq);
            waitbar(1,f,'Finished');
            close(f);
        end

        % Button pushed function: USCropButton
        function USCropButtonPushed(app, event)
            % Mannually crop US image as the input for US + DOT model
            app.MeasureApp = measure(app,[0,0]); % 1st para is (0 = us crop mode, 1 = L1 set layers mode), 2nd para is number of layers, only valid in L1 mode
            waitfor(app.MeasureApp)
            if isempty(app.us_features)
                errordlg('Cannot find width infomation, please try again','ERROR');
                return
            end 
            [batch, width, length] = size(app.us_features);
            app.us_features = reshape(app.us_features,1,batch, width, length);
            app.WithHistoclassificationButton.Enable = true;
            if ~isempty(app.us_features) && ~isempty(app.histo_features) 
                app.WithReconclassificationButton.Enable = true;
            end 
        end

        % Button pushed function: TargetprocessButton
        function TargetprocessButtonPushed(app, event)
            cla(app.Realpart);
            cla(app.Imagpart);
            cla(app.pert);
            app.CGDButton.Enable = false;
            app.CGDREGButton.Enable = false;
            app.AutoEncoderButton.Enable = false;
            app.L1Button.Enable = false;
            app.RefTextArea.Enable = false;
            app.RefTextArea.Value = 'Not applicable';
            app.UndoAllButton.Enable = true;
            app.FittingUnwrapButton.Enable = true;
            app.ClusterUnwrapButton.Enable = true;
            app.FittingDeleteButton.Enable = true;
            app.count740 = 0;
            app.count780 = 0;
            app.count808 = 0;
            app.count830 = 0;
            
            Refl = 0; % no use now
            freq = 140;
            pairs = app.pairs;
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            sddist_matrix = app.sddist_matrix;
            sddist = app.sddist;
            pairs74 = app.CallingApp.pairs74{end};
            pairs78 = app.CallingApp.pairs78{end};
            pairs80 = app.CallingApp.pairs80{end};
            pairs83 = app.CallingApp.pairs83{end};
            sddist74 = app.CallingApp.sddist74{end};
            sddist78 = app.CallingApp.sddist78{end};
            sddist80 = app.CallingApp.sddist80{end};
            sddist83 = app.CallingApp.sddist83{end};
            Measmnt74 = app.CallingApp.Measmnt74{end};
            Measmnt78 = app.CallingApp.Measmnt78{end};
            Measmnt80 = app.CallingApp.Measmnt80{end};
            Measmnt83 = app.CallingApp.Measmnt83{end};
            Nmeas = source*detect;
            
            [tar_size, ~] = size(app.CallingApp.tar_alldata{1});
            tar_total_data = cell(tar_size*3,4);
            for i = 1:tar_size
                tar_total_data(3*i-2,:) = app.CallingApp.tar_alldata{1}(i,:);
                tar_total_data(3*i-1,:) = app.CallingApp.tar_alldata{2}(i,:);
                tar_total_data(3*i,:) = app.CallingApp.tar_alldata{3}(i,:);
            end
            tar_value = app.TarDropDown.Value;
            tar_name = strfind(app.CallingApp.tar_name,tar_value);
            tar_file_idx = find(~cellfun(@isempty, tar_name));
            aba_r74 = reshape(tar_total_data{tar_file_idx,1}(1:Nmeas),source,detect);
            aba_r78 = reshape(tar_total_data{tar_file_idx,2}(1:Nmeas),source,detect);
            aba_r80 = reshape(tar_total_data{tar_file_idx,3}(1:Nmeas),source,detect);
            aba_r83 = reshape(tar_total_data{tar_file_idx,4}(1:Nmeas),source,detect);
            abp_r74 = reshape(tar_total_data{tar_file_idx,1}(Nmeas+1:end),source,detect);
            abp_r78 = reshape(tar_total_data{tar_file_idx,2}(Nmeas+1:end),source,detect);
            abp_r80 = reshape(tar_total_data{tar_file_idx,3}(Nmeas+1:end),source,detect);
            abp_r83 = reshape(tar_total_data{tar_file_idx,4}(Nmeas+1:end),source,detect);
            [A_m74_modified,A_m78_modified,A_m80_modified,A_m83_modified,P_m74_modified,P_m78_modified,P_m80_modified,P_m83_modified] = app.CallingApp.cali_data{5:8,1:2};
            [bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.CallingApp.cali_data{9:12,3:4};
            
            %Run normal calibration
            calibration4wvl;
            
            %Run the local fit programs for each source wavelength
            local_fit74;
            local_fit78;
            local_fit80;
            local_fit83;
            
            if app.nm740Button.Value
                name = '740nm';
                app.refmuaTextArea.Value = num2str(app.mua074);
                app.refmuspTextArea.Value = num2str(app.mus074);
                sddist = sddist74;
                taramp = amp74_log;
                tarphase = phase74;
            elseif app.nm780Button.Value
                name = '780nm';
                app.refmuaTextArea.Value = num2str(app.mua078);
                app.refmuspTextArea.Value = num2str(app.mus078);
                sddist = sddist78;
                taramp = amp78_log;
                tarphase = phase78;
            elseif app.nm808Button.Value
                name = '808nm';
                app.refmuaTextArea.Value = num2str(app.mua080);
                app.refmuspTextArea.Value = num2str(app.mus080);
                sddist = sddist80;
                taramp = amp80_log;
                tarphase = phase80;
            else
                name = '830nm';
                app.refmuaTextArea.Value = num2str(app.mua083);
                app.refmuspTextArea.Value = num2str(app.mus083);
                sddist = sddist83;
                taramp = amp83_log;
                tarphase = phase83;
            end
            plot_result(app,app.Realpart,sddist,taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Imagpart,sddist,tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
           
            app.cali_tardata = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             {corr_ki74},{corr_kr74},{corr_ki78},{corr_kr78};
                             {corr_ki80},{corr_kr80},{corr_ki83},{corr_kr83}};
            app.pairs74 = {pairs74};
            app.pairs78 = {pairs78};
            app.pairs80 = {pairs80};
            app.pairs83 = {pairs83};
            app.sddist74 = {sddist74};
            app.sddist78 = {sddist78};
            app.sddist80 = {sddist80};
            app.sddist83 = {sddist83};
            app.Measmnt74 = {Measmnt74};
            app.Measmnt78 = {Measmnt78};
            app.Measmnt80 = {Measmnt80};
            app.Measmnt83 = {Measmnt83};
            
            app.CallingApp.tar2pertmode = 1;
            app.tarmode = 1;
            app.TartoPertButton.Enable = true;
        end

        % Button pushed function: WithReconclassificationButton
        function WithReconclassificationButtonPushed(app, event)
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            sddist_matrix = app.sddist_matrix;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            Nmeas = source*detect;
            
            [flip_state,large_mapping,two_layer,layerDepth,mua1,mua2,musp1,musp2,...
            xy_x_radius,xy_y_radius,z_radius,depth,n_depth,xy_grid,xcenter_position,ycenter_position,...
        	outliers_removal,LOFRemoval,ELPRemoval,itmax,epsPercent,show_absorb] = getOnscreenParameters(app);
            if n_depth <= 0.5
                n_depth = depth;
                if z_radius < 0.5
                    while n_depth >= 0.5
                        n_depth = n_depth - 0.5;
                    end
                elseif z_radius > 0.5 && z_radius < 0.75
                    n_depth = n_depth - 0.25;
                    while n_depth > 0.5
                        n_depth = n_depth - 0.5;
                    end
                elseif z_radius >= 0.75 && z_radius < 1.25
                    while n_depth > 0.5
                        n_depth = n_depth - 0.5;
                    end   
                elseif z_radius >= 1.25
                    n_depth = n_depth - 0.25;
                    while n_depth > 0.5
                        n_depth = n_depth - 0.5;
                    end   
                else
                    while n_depth > 0.5
                        n_depth = n_depth - 0.5;
                    end   
                end
            end
            for d_layer = 1:7
                if n_depth > depth - z_radius
                    start_layer = d_layer;
                    break
                else
                    n_depth = n_depth + 0.5;
                end
            end
            if isempty(app.volume74)
                errordlg('Cannot find reconstruction image, please try again.','ERROR');
                return
            end
            [v_h, v_w, v_z] = size(app.volume74);
            if v_h == 37
                volume74 = app.volume74(3:end-3,3:end-3,:);
                volume78 = app.volume78(3:end-3,3:end-3,:);
                volume80 = app.volume80(3:end-3,3:end-3,:);
                volume83 = app.volume83(3:end-3,3:end-3,:);
                hemo = app.hemo(3:end-3,3:end-3,:)*0.15/65;
            end
            
            img74 = permute(volume74(:,:,start_layer:start_layer+2),[3 1 2]);
            img78 = permute(volume78(:,:,start_layer:start_layer+2),[3 1 2]);
            img80 = permute(volume80(:,:,start_layer:start_layer+2),[3 1 2]);
            img83 = permute(volume83(:,:,start_layer:start_layer+2),[3 1 2]);
            hemo_map = permute(hemo(:,:,start_layer:start_layer+2),[3 1 2]);
            [aa,bb,cc] = size(img74);
            img74 = reshape(img74,1,aa,bb,cc);
            img78 = reshape(img78,1,aa,bb,cc);
            img80 = reshape(img80,1,aa,bb,cc);
            img83 = reshape(img83,1,aa,bb,cc);
            hemo_map = reshape(hemo_map,1,aa,bb,cc); % should be 1*3*32*32
            recon_results = py.secondstage_feature_extraction.recon_feature_extraction(py.numpy.array(single(hemo_map)));
            app.recon_pred = single(recon_results{2});
            app.recon_features = squeeze(double(single(recon_results{1})));
            us_features = app.us_features;
            histo_features = app.histo_features;
            recon_features = app.recon_features;
            [aa,bb,cc] = size(recon_features);
            histo_features = reshape(histo_features,1,aa,bb,cc);
            recon_features = reshape(recon_features,1,aa,bb,cc);
            final_pred = single(py.secondstage_feature_extraction.fusion_prediction(py.numpy.array(single(recon_features)),py.numpy.array(single(us_features)),py.numpy.array(single(histo_features))));
            pred74 = py.dot_us_model.main_us_dot(py.torch.from_numpy(py.numpy.array(us_features)), py.torch.from_numpy(py.numpy.array(img74)));
            pred78 = py.dot_us_model.main_us_dot(py.torch.from_numpy(py.numpy.array(us_features)), py.torch.from_numpy(py.numpy.array(img78)));
            pred80 = py.dot_us_model.main_us_dot(py.torch.from_numpy(py.numpy.array(us_features)), py.torch.from_numpy(py.numpy.array(img80)));
            pred83 = py.dot_us_model.main_us_dot(py.torch.from_numpy(py.numpy.array(us_features)), py.torch.from_numpy(py.numpy.array(img83)));
            mean_pred = (pred74+pred78+pred80+pred83)/4;
            ave_nn_pred = (app.recon_pred + 2*app.us_predict + 4*app.histo_predict)/7
            f = msgbox(['Probability of malignancy with Recon: ', num2str(double(final_pred)), ' ; Ave probability: ', num2str(double(ave_nn_pred)), ' ; DOT probability: ', num2str(double(app.recon_pred)), ' ; histo probability: ', num2str(double(app.histo_predict)), ' ; us probability: ', num2str(double(app.us_predict))]);
        end

        % Button pushed function: FittingUnwrapButton
        function FittingUnwrapButtonPushed(app, event)
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                freq = 140;
                Refl = 0;
                
                if app.nm740Button.Value
                    name = '740nm';
                    taramp = amp74_log;
                    tarphase = phase74;
                    sddist = app.sddist74;
                elseif app.nm780Button.Value
                    name = '780nm';
                    taramp = amp78_log;
                    tarphase = phase78;
                    sddist = app.sddist78;
                elseif app.nm808Button.Value
                    name = '808nm';
                    taramp = amp80_log;
                    tarphase = phase80;
                    sddist = app.sddist80;
                else
                    name = '830nm';
                    taramp = amp83_log;
                    tarphase = phase83;
                    sddist = app.sddist83;
                end
                selected_axis = app.Imagpart;
                phase_copy = tarphase{end};
                phase = tarphase{end};
               
                SD_idx = find(sddist{end} > 4.5);
                phase_copy(SD_idx) = 0;
                phase_copy = phase_copy(phase_copy~=0);
                sddist_copy = sddist{end}(sddist{end} <= 4.5);
                c = polyfit(sddist_copy,phase_copy,1);
                y_est = polyval(c,sddist{end});
                difference = phase - y_est;
                botoutlier = find(difference < -3.2);
                topoutlier = find(difference > 3.2);
                
                if ~isempty(botoutlier)
                    p1 = 1; p2 = 0;
                    if app.nm740Button.Value
                        name = '740nm';
                        phase_axis = 'fit_axes_phase';
                        A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                        Measmnt74(end+1) = Measmnt74(end);
                        sddist74(end+1) = sddist74(end);
                        
                        [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                            muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,botoutlier,...
                            p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                            Refl);
                        
                        taramp = amp74_log{end};
                        tarphase = phase74{end};
                        sddist = sddist74;
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm780Button.Value
                        name = '780nm';
                        phase_axis = 'fit_axes_phase';
                        A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                        Measmnt78(end+1) = Measmnt78(end);
                        sddist78(end+1) = sddist78(end);
                        
                        [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                            muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,botoutlier,...
                            p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                            Refl);
                        
                        taramp = amp78_log{end};
                        tarphase = phase78{end};
                        sddist = sddist78;
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm808Button.Value
                        name = '808nm';
                        phase_axis = 'fit_axes_phase';
                        A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                        Measmnt80(end+1) = Measmnt80(end);
                        sddist80(end+1) = sddist80(end);
                        
                        [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                            muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,botoutlier,...
                            p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                            Refl);
                        
                        taramp = amp80_log{end};
                        tarphase = phase80{end};
                        sddist = sddist80;
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm830Button.Value
                        name = '830nm';
                        phase_axis = 'fit_axes_phase';
                        A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                        Measmnt83(end+1) = Measmnt83(end);
                        sddist83(end+1) = sddist83(end);
                        
                        [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                            muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,botoutlier,...
                            p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                            Refl);
                        
                        taramp = amp83_log{end};
                        tarphase = phase83{end};
                        sddist = sddist83;
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    end
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;  
                
                if ~isempty(topoutlier)
                    p1 = 0; p2 = 1;
                    if app.nm740Button.Value
                        name = '740nm';
                        phase_axis = 'fit_axes_phase';
                        A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                        Measmnt74(end+1) = Measmnt74(end);
                        sddist74(end+1) = sddist74(end);
                        
                        [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                            muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,topoutlier,...
                            p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                            Refl);
                        
                        taramp = amp74_log{end};
                        tarphase = phase74{end};
                        sddist = sddist74;
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm780Button.Value
                        name = '780nm';
                        phase_axis = 'fit_axes_phase';
                        A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                        Measmnt78(end+1) = Measmnt78(end);
                        sddist78(end+1) = sddist78(end);
                        
                        [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                            muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,topoutlier,...
                            p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                            Refl);
                        
                        taramp = amp78_log{end};
                        tarphase = phase78{end};
                        sddist = sddist78;
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm808Button.Value
                        name = '808nm';
                        phase_axis = 'fit_axes_phase';
                        A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                        Measmnt80(end+1) = Measmnt80(end);
                        sddist80(end+1) = sddist80(end);
                        
                        [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                            muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,topoutlier,...
                            p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                            Refl);
                        
                        taramp = amp80_log{end};
                        tarphase = phase80{end};
                        sddist = sddist80;
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm830Button.Value
                        name = '830nm';
                        phase_axis = 'fit_axes_phase';
                        A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                        Measmnt83(end+1) = Measmnt83(end);
                        sddist83(end+1) = sddist83(end);
                        
                        [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                            muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,topoutlier,...
                            p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                            Refl);
                        
                        taramp = amp83_log{end};
                        tarphase = phase83{end};
                        sddist = sddist83;
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    end
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;  
            else
                return
            end

        end

        % Button pushed function: FittingDeleteButton
        function FittingDeleteButtonPushed(app, event)
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                freq = 140;
                Refl = 0;
                
                if app.nm740Button.Value
                    name = '740nm';
                    taramp = amp74_log;
                    tarphase = phase74;
                    sddist = app.sddist74;
                elseif app.nm780Button.Value
                    name = '780nm';
                    taramp = amp78_log;
                    tarphase = phase78;
                    sddist = app.sddist78;
                elseif app.nm808Button.Value
                    name = '808nm';
                    taramp = amp80_log;
                    tarphase = phase80;
                    sddist = app.sddist80;
                else
                    name = '830nm';
                    taramp = amp83_log;
                    tarphase = phase83;
                    sddist = app.sddist83;
                end
                selected_axis = app.Imagpart;
                phase_copy = tarphase{end};
                phase = tarphase{end};
                slope_diff = 0.1959;
                intersect_diff = 0.2877;
                SD_idx = find(sddist{end} > 4.5);
                phase_copy(SD_idx) = 0;
                phase_copy = phase_copy(phase_copy~=0);
                sddist_copy = sddist{end}(sddist{end} <= 4.5);
                c = polyfit(sddist_copy,phase_copy,1);
                y_est = polyval(c,sddist{end});
                difference = phase - y_est;
                upperbound = sddist{end}*slope_diff - intersect_diff;
                lowerbound = sddist{end}*(-slope_diff) + intersect_diff;
                botoutlier = find(difference < lowerbound);
                topoutlier = find(difference > upperbound);
                figure;plot(sddist{end},difference,'bo');hold on
                plot(sddist{end},upperbound,'ro')
                plot(sddist{end},lowerbound,'ro');hold off
                sddist_index = (1:Nmeas);
                sddist_index(botoutlier) = [];
                topoutlier = find(ismember(sddist_index,topoutlier));
                
                if ~isempty(botoutlier)
                    if app.nm740Button.Value
                        name = '740nm';
                        axisChoice = 'fit_axes_phase';

                        [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(botoutlier,...
                            sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                        [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                            sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                            corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                        
                        taramp = amp74_log{end};
                        tarphase = phase74{end};
                        sddist = sddist74;
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        app.pairs74 = pairs74;
                        app.sddist74 = sddist74;
                        app.Measmnt74 = Measmnt74;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm780Button.Value
                        name = '780nm';
                        axisChoice = 'fit_axes_phase';
                        
                        [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(botoutlier,...
                            sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                        [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                            sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                            corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                            
                        taramp = amp78_log{end};
                        tarphase = phase78{end};
                        sddist = sddist78;
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        app.pairs78 = pairs78;
                        app.sddist78 = sddist78;
                        app.Measmnt78 = Measmnt78;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm808Button.Value
                        name = '808nm';
                        axisChoice = 'fit_axes_phase';
                        [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(botoutlier,...
                            sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                        [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                            sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                            corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                        
                        taramp = amp80_log{end};
                        tarphase = phase80{end};
                        sddist = sddist80;
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        app.pairs80 = pairs80;
                        app.sddist80 = sddist80;
                        app.Measmnt80 = Measmnt80;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm830Button.Value
                        name = '830nm';
                        axisChoice = 'fit_axes_phase';
                        [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(botoutlier,...
                            sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                        [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                            sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                            corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                        
                        taramp = amp83_log{end};
                        tarphase = phase83{end};
                        sddist = sddist83;
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        app.pairs83 = pairs83;
                        app.sddist83 = sddist83;
                        app.Measmnt83 = Measmnt83;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    end
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;  
                
                if ~isempty(topoutlier)
                    if app.nm740Button.Value
                        name = '740nm';
                        axisChoice = 'fit_axes_phase';

                        [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(topoutlier,...
                            sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                        [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                            sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                            corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                        
                        taramp = amp74_log{end};
                        tarphase = phase74{end};
                        sddist = sddist74;
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        app.pairs74 = pairs74;
                        app.sddist74 = sddist74;
                        app.Measmnt74 = Measmnt74;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm780Button.Value
                        name = '780nm';
                        axisChoice = 'fit_axes_phase';
                        
                        [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(topoutlier,...
                            sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                        [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                            sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                            corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                            
                        taramp = amp78_log{end};
                        tarphase = phase78{end};
                        sddist = sddist78;
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        app.pairs78 = pairs78;
                        app.sddist78 = sddist78;
                        app.Measmnt78 = Measmnt78;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm808Button.Value
                        name = '808nm';
                        axisChoice = 'fit_axes_phase';
                        [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(topoutlier,...
                            sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                        [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                            sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                            corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                        
                        taramp = amp80_log{end};
                        tarphase = phase80{end};
                        sddist = sddist80;
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        app.pairs80 = pairs80;
                        app.sddist80 = sddist80;
                        app.Measmnt80 = Measmnt80;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm830Button.Value
                        name = '830nm';
                        axisChoice = 'fit_axes_phase';
                        [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(topoutlier,...
                            sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                        [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                            sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                            corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                        
                        taramp = amp83_log{end};
                        tarphase = phase83{end};
                        sddist = sddist83;
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        app.pairs83 = pairs83;
                        app.sddist83 = sddist83;
                        app.Measmnt83 = Measmnt83;
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    end
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;  
            else
                return
            end
        end

        % Button pushed function: L1Button
        function L1ButtonPushed(app, event)
            imagemethod(app,3) % 'width_info' in regShaped.m is hard coded now, but it should be derived from US image, measure it or use NN
        end

        % Button pushed function: ClusterUnwrapButton
        function ClusterUnwrapButtonPushed(app, event)
            if app.tarmode
                
                [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_tardata{1:4,1:4};
                [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_tardata{5:8,1:4};         
                [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_tardata{9:12,1:4};
                [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_tardata{13:14,1:4};
                
                source = app.source;
                detect = app.detect;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Nmeas = source*detect;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74;
                pairs78 = app.pairs78;
                pairs80 = app.pairs80;
                pairs83 = app.pairs83;
                sddist74 = app.sddist74;
                sddist78 = app.sddist78;
                sddist80 = app.sddist80;
                sddist83 = app.sddist83;
                Measmnt74 = app.Measmnt74;
                Measmnt78 = app.Measmnt78;
                Measmnt80 = app.Measmnt80;
                Measmnt83 = app.Measmnt83;
                freq = 140;
                Refl = 0;
                
                if app.nm740Button.Value
                    name = '740nm';
                    taramp = amp74_log;
                    tarphase = phase74;
                    sddist = app.sddist74;
                elseif app.nm780Button.Value
                    name = '780nm';
                    taramp = amp78_log;
                    tarphase = phase78;
                    sddist = app.sddist78;
                elseif app.nm808Button.Value
                    name = '808nm';
                    taramp = amp80_log;
                    tarphase = phase80;
                    sddist = app.sddist80;
                else
                    name = '830nm';
                    taramp = amp83_log;
                    tarphase = phase83;
                    sddist = app.sddist83;
                end
                selected_axis = app.Imagpart;
                phase_copy = tarphase{end};
%                 phase = tarphase{end};
               
                small_idx = find(sddist{end} == min(sddist{end}),1);
                big_idx = find(sddist{end} ==  max(sddist{end}),1);
                difference_phase = phase_copy(big_idx) - phase_copy(small_idx);
                if difference_phase > 2 * Const.pi || difference_phase < - Const.pi
                    threshold = abs(difference_phase) / 2 + min(phase_copy(big_idx),phase_copy(small_idx));
                    SD_idx = find(phase_copy > threshold);
                else
                    errordlg('Cannot detect two clasters of data points, please mannually adjust the phase.','ERROR!!');
                    return
                end
                
                
                if ~isempty(SD_idx)
                    p1 = 0; p2 = 1;
                    if app.nm740Button.Value
                        name = '740nm';
                        phase_axis = 'fit_axes_phase';
                        A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                        Measmnt74(end+1) = Measmnt74(end);
                        sddist74(end+1) = sddist74(end);
                        
                        [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                            muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,SD_idx,...
                            p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                            Refl);
                        
                        taramp = amp74_log{end};
                        tarphase = phase74{end};
                        sddist = sddist74;
                        app.refmuaTextArea.Value = num2str(app.mua074);
                        app.refmuspTextArea.Value = num2str(app.mus074);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm780Button.Value
                        name = '780nm';
                        phase_axis = 'fit_axes_phase';
                        A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                        Measmnt78(end+1) = Measmnt78(end);
                        sddist78(end+1) = sddist78(end);
                        
                        [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                            muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,SD_idx,...
                            p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                            Refl);
                        
                        taramp = amp78_log{end};
                        tarphase = phase78{end};
                        sddist = sddist78;
                        app.refmuaTextArea.Value = num2str(app.mua078);
                        app.refmuspTextArea.Value = num2str(app.mus078);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm808Button.Value
                        name = '808nm';
                        phase_axis = 'fit_axes_phase';
                        A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                        Measmnt80(end+1) = Measmnt80(end);
                        sddist80(end+1) = sddist80(end);
                        
                        [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                            muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,SD_idx,...
                            p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                            Refl);
                        
                        taramp = amp80_log{end};
                        tarphase = phase80{end};
                        sddist = sddist80;
                        app.refmuaTextArea.Value = num2str(app.mua080);
                        app.refmuspTextArea.Value = num2str(app.mus080);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    elseif app.nm830Button.Value
                        name = '830nm';
                        phase_axis = 'fit_axes_phase';
                        A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                        Measmnt83(end+1) = Measmnt83(end);
                        sddist83(end+1) = sddist83(end);
                        
                        [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                            muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,SD_idx,...
                            p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                            Refl);
                        
                        taramp = amp83_log{end};
                        tarphase = phase83{end};
                        sddist = sddist83;
                        app.refmuaTextArea.Value = num2str(app.mua083);
                        app.refmuspTextArea.Value = num2str(app.mus083);
                        
                        plot_result(app,app.Realpart,sddist{end},taramp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Imagpart,sddist{end},tarphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    end
                end
                app.cali_tardata = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
                app.pairs74 = pairs74;
                app.pairs78 = pairs78;
                app.pairs80 = pairs80;
                app.pairs83 = pairs83;
                app.sddist74 = sddist74;
                app.sddist78 = sddist78;
                app.sddist80 = sddist80;
                app.sddist83 = sddist83;
                app.Measmnt74 = Measmnt74;
                app.Measmnt78 = Measmnt78;
                app.Measmnt80 = Measmnt80;
                app.Measmnt83 = Measmnt83;  
            else
                return
            end
        end

        % Button pushed function: WithHistoclassificationButton
        function WithHistoclassificationButtonPushed(app, event)
            Xedges = [-Inf -1:2/30:1 Inf];
            Yedges = [-Inf -1:2/30:1 Inf];
            realpert74_all = [];
            realpert78_all = [];
            realpert80_all = [];
            realpert83_all = [];
            imagpert74_all = [];
            imagpert78_all = [];
            imagpert80_all = [];
            imagpert83_all = [];
            source = app.source;
            detect = app.detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            sddist_matrix = app.sddist_matrix;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            Nmeas = source*detect;
            
            num_file = length(app.TarDropDown.Items);
            tar_total_data = cell(num_file,4);
            for i = 1:num_file/3
                tar_total_data(3*i-2,:) = app.CallingApp.tar_alldata{1}(i,:);
                tar_total_data(3*i-1,:) = app.CallingApp.tar_alldata{2}(i,:);
                tar_total_data(3*i,:) = app.CallingApp.tar_alldata{3}(i,:);
            end
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83] = app.CallingApp.cali_data{9:12,1:2};
            
            for tar_file_idx = 1:num_file
                aba74 = reshape(tar_total_data{tar_file_idx,1}(1:Nmeas),source,detect);
                aba78 = reshape(tar_total_data{tar_file_idx,2}(1:Nmeas),source,detect);
                aba80 = reshape(tar_total_data{tar_file_idx,3}(1:Nmeas),source,detect);
                aba83 = reshape(tar_total_data{tar_file_idx,4}(1:Nmeas),source,detect);
                abp74 = reshape(tar_total_data{tar_file_idx,1}(Nmeas+1:end),source,detect);
                abp78 = reshape(tar_total_data{tar_file_idx,2}(Nmeas+1:end),source,detect);
                abp80 = reshape(tar_total_data{tar_file_idx,3}(Nmeas+1:end),source,detect);
                abp83 = reshape(tar_total_data{tar_file_idx,4}(Nmeas+1:end),source,detect);

                [pert74,data74,Nmeas74new,Measmnt74new,sddist74new,pairs74New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs74,aba74,abp74,aba_r74,abp_r74,Measmnt74{end});  % both pert and data are cells
                [pert78,data78,Nmeas78new,Measmnt78new,sddist78new,pairs78New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs78,aba78,abp78,aba_r78,abp_r78,Measmnt78{end});
                [pert80,data80,Nmeas80new,Measmnt80new,sddist80new,pairs80New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs80,aba80,abp80,aba_r80,abp_r80,Measmnt80{end});
                [pert83,data83,Nmeas83new,Measmnt83new,sddist83new,pairs83New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs83,aba83,abp83,aba_r83,abp_r83,Measmnt83{end});

                realpert74_all = [realpert74_all,real(pert74{end})];
                realpert78_all = [realpert78_all,real(pert78{end})];
                realpert80_all = [realpert80_all,real(pert80{end})];
                realpert83_all = [realpert83_all,real(pert83{end})];
                imagpert74_all = [imagpert74_all,imag(pert74{end})];
                imagpert78_all = [imagpert78_all,imag(pert78{end})];
                imagpert80_all = [imagpert80_all,imag(pert80{end})];
                imagpert83_all = [imagpert83_all,imag(pert83{end})];
            end
            
%             h74 = histogram2(realpert74_all,imagpert74_all,Xedges,Yedges);
%             h78 = histogram2(realpert78_all,imagpert78_all,Xedges,Yedges);
%             h80 = histogram2(realpert80_all,imagpert80_all,Xedges,Yedges);
%             h83 = histogram2(realpert83_all,imagpert83_all,Xedges,Yedges);
            f1 = figure;
            h_all = histogram2([realpert74_all,realpert78_all,realpert80_all,realpert83_all],[imagpert74_all,imagpert78_all,imagpert80_all,imagpert83_all],Xedges,Yedges);
            h_all_input = h_all.Values;
            h_all_input = h_all_input/(detect*num_file*4);
            [aa,bb] = size(h_all_input);
            h_all_input = reshape(h_all_input,1,1,aa,bb);
            close(f1)
            histo_results = py.firststage_feature_extraction.histo_feature_extraction(py.numpy.array(single(h_all_input)));
            app.histo_predict = single(histo_results{2});
            app.histo_features = squeeze(double(single(histo_results{1})));
            app.first_pred = (app.histo_predict + app.us_predict)/2;
%             pred = py.histo.main_classification_histogramUS(py.torch.from_numpy(py.numpy.array(single(h_all_input))), py.torch.from_numpy(py.numpy.array(single(app.us_features))));
%             pred = pred.detach().numpy();
            f = msgbox(['Probability of malignancy with Histo: ', num2str(double(app.first_pred))]);
            if ~isempty(app.us_features) && ~isempty(app.histo_features) 
                app.WithReconclassificationButton.Enable = true;
            end 
        end

        % Button pushed function: ReconEvaluationButton
        function ReconEvaluationButtonPushed(app, event)
            if isempty(app.volume74)
                errordlg('Cannot find reconstruction image, please try again.','ERROR');
                return
            end
            [v_h, v_w, v_z] = size(app.volume74);
            if v_h == 37
                volume74 = app.volume74(3:end-2,3:end-2,:);
                volume78 = app.volume78(3:end-2,3:end-2,:);
                volume80 = app.volume80(3:end-2,3:end-2,:);
                volume83 = app.volume83(3:end-2,3:end-2,:);
            end
            app.prob74 = double(single(py.DOT_assessment.dot_imageval(py.numpy.array(volume74))));
            app.prob78 = double(single(py.DOT_assessment.dot_imageval(py.numpy.array(volume78))));
            app.prob80 = double(single(py.DOT_assessment.dot_imageval(py.numpy.array(volume80))));
            app.prob83 = double(single(py.DOT_assessment.dot_imageval(py.numpy.array(volume83))));
        end

        % Callback function
        function ArtiremovButtonPushed(app, event)
 
        end

        % Value changed function: LOFRemovalCheckBox
        function LOFRemovalCheckBoxValueChanged(app, event)
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1 
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            else
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
            end   
        end

        % Button pushed function: MeasureUSButton
        function MeasureUSButtonPushed(app, event)
            % Measure target informations, z_radius and target to top depth
            app.MeasureApp = measure(app,[2,0]); % 1st para is (0 = us crop mode, 1 = L1 set layers mode, 2 = Measure mode), 2nd para is number of layers, only valid in L1 mode
            waitfor(app.MeasureApp)
        end

        % Button pushed function: SemisegmentButton
        function SemisegmentButtonPushed(app, event)
            [path, ~] = fileparts(app.CallingApp.ref_name{1});
            if ~isempty(app.img_path)
                US_image = app.img_path;
            else
                US_image = uigetfile({'*.png';'*.jpg';'*.bmp'},'Select an target US image',path);
            end
            us_path = fullfile(path, US_image);
            if ~isempty(dir(us_path))
                us_paras = py.auto_us_seg.auto_seg(us_path);
                center_depth = round(us_paras{5},2);
                radius = round(us_paras{6},1);
                cropped_img = us_paras{7};
                left = double(us_paras{8});
                right = double(us_paras{9});
                up = double(us_paras{10});
                down = double(us_paras{11});
                image = uint8(us_paras{1});
            else
                errordlg('Please select corresponding US image','ERROR');
                return
            end
            if 2*radius < 1
                z_radius = 0.49;
            elseif (1 <= 2*radius) && 2*radius < 1.6
                z_radius = 0.7;
            elseif (1.6 <= 2*radius) && 2*radius < 2.3
                z_radius = 0.99;
            elseif (2.3 <= 2*radius) && 2*radius < 2.7
                z_radius = 1.24;
            elseif (2.7 <= 2*radius) && 2*radius < 3.5
                z_radius = 1.49;
            else
                errordlg('z radius is too high, auto parameter method may be wrong, please check the image','Warning');
            end
            f = waitbar(0,'Please wait...');
            pause(0.5)      
            waitbar(.15,f,'Please wait for US feature extraction ...');
            app.img_crop = image(up:down,left:right,:);
            app.z_radius = z_radius;
            app.target_depth = center_depth;
            app.ztargetradiusEditField.Value = z_radius;
            app.centerdepthEditField.Value = center_depth;
            waitbar(.5,f,'Please wait for US feature extraction ...');
            us_results = py.firststage_feature_extraction.us_feature_extraction(py.numpy.array(app.img_crop));
            waitbar(.9,f,'Please wait for US feature extraction ...');
            app.us_predict = single(us_results{2});
            app.us_features = squeeze(double(single(us_results{1})));
%             app.us_features = squeeze(double(single(py.us_extract.us_feature_extract(py.numpy.array(app.img_crop)))));
            [batch, width, length] = size(app.us_features);
            app.us_features = reshape(app.us_features,1,batch, width, length);
            app.WithHistoclassificationButton.Enable = true;
            waitbar(1,f,'Finished');
            close(f);
            if ~isempty(app.us_features) && ~isempty(app.histo_features) 
                app.WithReconclassificationButton.Enable = true;
            end
        end

        % Value changed function: ELPRemovalCheckBox
        function ELPRemovalCheckBoxValueChanged(app, event)
            outsum = app.KmeansOutliersRemovalCheckBox.Value + app.LOFRemovalCheckBox.Value + app.ELPRemovalCheckBox.Value;
            if outsum > 1
                app.LOFRemovalCheckBox.Value = 0;
                app.KmeansOutliersRemovalCheckBox.Value = 0;
                app.ELPRemovalCheckBox.Value = 0;
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
                errordlg('Only one outlier removal method can be applied, please select or cancel one','ERROR');
                return
            else
                targetFileStruct = app.targetFileStruct;
                draw_pert(app,targetFileStruct)
            end   
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            [path, ~] = fileparts(app.CallingApp.ref_name{1});
            tar_value = app.TarDropDown.Value;
            ref_value = app.RefTextArea.Value;
            save_name = strcat(tar_value,'_',ref_value);
            save_path = fullfile(path,'saved_data');
            if ~exist(save_path, 'dir')
                mkdir(save_path)
            end
            save_path = fullfile(path,'saved_data',save_name{1});
            aba_r74 = app.CallingApp.bga74;    % get from load reference file on the imger_4wvl
            abp_r74 = app.CallingApp.bgp74;
            aba_r78 = app.CallingApp.bga78;
            abp_r78 = app.CallingApp.bgp78;
            aba_r80 = app.CallingApp.bga80;
            abp_r80 = app.CallingApp.bgp80;
            aba_r83 = app.CallingApp.bga83;
            abp_r83 = app.CallingApp.bgp83;
            
            aba74 = app.CallingApp.aba74;
            aba78 = app.CallingApp.aba78;
            aba80 = app.CallingApp.aba80;
            aba83 = app.CallingApp.aba83;
            abp74 = app.CallingApp.abp74;
            abp78 = app.CallingApp.abp78;
            abp80 = app.CallingApp.abp80;
            abp83 = app.CallingApp.abp83;
            
            mua0 = [app.mua074, app.mua078, app.mua080, app.mua083];
            musp0 = [app.mus074, app.mus078, app.mus080, app.mus083];
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            
            [origdata74,origpert74,origsddist74,origpairs74,origMeasmnt74,origdata78,origpert78,origsddist78,origpairs78,origMeasmnt78,origdata80,origpert80,origsddist80,origpairs80,origMeasmnt80,origdata83,origpert83,origsddist83,origpairs83,origMeasmnt83] = app.origdata{1:5,1:4};
            
            current_kmeans = app.KmeansOutliersRemovalCheckBox.Value; 
            current_lof = app.LOFRemovalCheckBox.Value;
            current_elp = app.ELPRemovalCheckBox.Value;
            
%             get current raw data (without any outlier removal)
            app.KmeansOutliersRemovalCheckBox.Value = 0;
            app.LOFRemovalCheckBox.Value = 0;
            app.ELPRemovalCheckBox.Value = 0;
            [raw_data74,raw_data78,raw_data80,raw_data83,raw_Measmnt74,raw_Measmnt78,raw_Measmnt80,raw_Measmnt83,raw_Nmeas74,raw_Nmeas78,raw_Nmeas80,raw_Nmeas83,raw_pairs74,raw_pairs78,raw_pairs80,raw_pairs83,raw_pert74,raw_pert78,raw_pert80,raw_pert83,raw_sddist74,raw_sddist78,raw_sddist80,raw_sddist83] = GetDataFromTargetFileStructure(app);
            if (length(raw_data74) == length(origdata74{1})) && (length(raw_data78) == length(origdata78{1})) && (length(raw_data80) == length(origdata80{1})) && (length(raw_data83) == length(origdata83{1}))
                data74 = {raw_data74};data78 = {raw_data78};data80 = {raw_data80};data83 = {raw_data83};
                Measmnt74 = {raw_Measmnt74};Measmnt78 = {raw_Measmnt78};Measmnt80 = {raw_Measmnt80};Measmnt83 = {raw_Measmnt83};
                pairs74 = {raw_pairs74};pairs78 = {raw_pairs78};pairs80 = {raw_pairs80};pairs83 = {raw_pairs83};
                pert74 = {raw_pert74};pert78 = {raw_pert78};pert80 = {raw_pert80};pert83 = {raw_pert83};
                sddist74 = {raw_sddist74};sddist78 = {raw_sddist78};sddist80 = {raw_sddist80};sddist83 = {raw_sddist83};
            else
                data74 = {origdata74{1},raw_data74};data78 = {origdata78{1},raw_data78};data80 = {origdata80{1},raw_data80};data83 = {origdata83{1},raw_data83};
                Measmnt74 = {origMeasmnt74{1},raw_Measmnt74};Measmnt78 = {origMeasmnt74{1},raw_Measmnt78};Measmnt80 = {origMeasmnt74{1},raw_Measmnt80};Measmnt83 = {origMeasmnt74{1},raw_Measmnt83};
                pairs74 = {origpairs74{1},raw_pairs74};pairs78 = {origpairs78{1},raw_pairs78};pairs80 = {origpairs80{1},raw_pairs80};pairs83 = {origpairs83{1},raw_pairs83};
                pert74 = {origpert74{1},raw_pert74};pert78 = {origpert78{1},raw_pert78};pert80 = {origpert80{1},raw_pert80};pert83 = {origpert83{1},raw_pert83};
                sddist74 = {origsddist74{1},raw_sddist74};sddist78 = {origsddist78{1},raw_sddist78};sddist80 = {origsddist80{1},raw_sddist80};sddist83 = {origsddist83{1},raw_sddist83};
            end
            
%             get current removaled data (if exists)
            app.KmeansOutliersRemovalCheckBox.Value = current_kmeans;
            app.LOFRemovalCheckBox.Value = current_lof;
            app.ELPRemovalCheckBox.Value = current_elp;
            [rem_data74,rem_data78,rem_data80,rem_data83,rem_Measmnt74,rem_Measmnt78,rem_Measmnt80,rem_Measmnt83,rem_Nmeas74,rem_Nmeas78,rem_Nmeas80,rem_Nmeas83,rem_pairs74,rem_pairs78,rem_pairs80,rem_pairs83,rem_pert74,rem_pert78,rem_pert80,rem_pert83,rem_sddist74,rem_sddist78,rem_sddist80,rem_sddist83] = GetDataFromTargetFileStructure(app);
            if (length(raw_data74) == length(rem_data74)) && (length(raw_data78) == length(rem_data78)) && (length(raw_data80) == length(rem_data80)) && (length(raw_data83) == length(rem_data83))
                
            else
                data74{end+1} = rem_data74;data78{end+1} = rem_data78;data80{end+1} = rem_data80;data83{end+1} = rem_data83;
                Measmnt74{end+1} = rem_Measmnt74;Measmnt78{end+1} = rem_Measmnt78;Measmnt80{end+1} = rem_Measmnt80;Measmnt83{end+1} = rem_Measmnt83;
                pairs74{end+1} = rem_pairs74;pairs78{end+1} = rem_pairs78;pairs80{end+1} = rem_pairs80;pairs83{end+1} = rem_pairs83;
                pert74{end+1} = rem_pert74;pert78{end+1} = rem_pert78;pert80{end+1} = rem_pert80;pert83{end+1} = rem_pert80;
                sddist74{end+1} = rem_sddist74;sddist78{end+1} = rem_sddist78;sddist80{end+1} = rem_sddist80;sddist83{end+1} = rem_sddist83;
            end
            save(save_path,'mua0','musp0','data74','data78',"sddist83","sddist80","sddist78","sddist74","pert83","pert80","pert78","pert74","pairs83","pairs80","pairs78","pairs74","Measmnt83","Measmnt80","Measmnt78","Measmnt74","data83","data80", ...
                's_geom',"d_geom",'aba_r74',"abp_r74","aba_r78","abp_r78","aba_r80","abp_r80","aba_r83","abp_r83","aba74","aba78","aba80","aba83","abp74","abp78","abp80","abp83")
        end

        % Value changed function: ELPconfidenceintervalEditField
        function ELPconfidenceintervalEditFieldValueChanged(app, event)
            value = app.ELPconfidenceintervalEditField.Value;
            current_kmeans = app.KmeansOutliersRemovalCheckBox.Value; 
            current_lof = app.LOFRemovalCheckBox.Value;
            current_elp = app.ELPRemovalCheckBox.Value;
            
            app.CallingApp.tar2pertmode = 0;
            app.TartoPertButton.Enable = false;
            app.RefTextArea.Enable = true;
            app.RefTextArea.Value = app.CallingApp.reference;
            source = app.CallingApp.Ns;
            detect = app.CallingApp.Nd;
            s_geom = app.CallingApp.s_geom;
            d_geom = app.CallingApp.d_geom;
            sddist_matrix = app.CallingApp.sddist_matrix;
            pairs74 = app.CallingApp.pairs74;
            pairs78 = app.CallingApp.pairs78;
            pairs80 = app.CallingApp.pairs80;
            pairs83 = app.CallingApp.pairs83;
            Measmnt74 = app.CallingApp.Measmnt74;
            Measmnt78 = app.CallingApp.Measmnt78;
            Measmnt80 = app.CallingApp.Measmnt80;
            Measmnt83 = app.CallingApp.Measmnt83;
            Nmeas = source*detect;
            
            [tar_size, ~] = size(app.CallingApp.tar_alldata{1});
            tar_total_data = cell(tar_size*3,4);
            for i = 1:tar_size
                tar_total_data(3*i-2,:) = app.CallingApp.tar_alldata{1}(i,:);
                tar_total_data(3*i-1,:) = app.CallingApp.tar_alldata{2}(i,:);
                tar_total_data(3*i,:) = app.CallingApp.tar_alldata{3}(i,:);
            end
            tar_value = app.TarDropDown.Value;
            tar_name = strfind(app.CallingApp.tar_name,tar_value);
            tar_file_idx = find(~cellfun(@isempty, tar_name));
            aba74 = reshape(tar_total_data{tar_file_idx,1}(1:Nmeas),source,detect);
            aba78 = reshape(tar_total_data{tar_file_idx,2}(1:Nmeas),source,detect);
            aba80 = reshape(tar_total_data{tar_file_idx,3}(1:Nmeas),source,detect);
            aba83 = reshape(tar_total_data{tar_file_idx,4}(1:Nmeas),source,detect);
            abp74 = reshape(tar_total_data{tar_file_idx,1}(Nmeas+1:end),source,detect);
            abp78 = reshape(tar_total_data{tar_file_idx,2}(Nmeas+1:end),source,detect);
            abp80 = reshape(tar_total_data{tar_file_idx,3}(Nmeas+1:end),source,detect);
            abp83 = reshape(tar_total_data{tar_file_idx,4}(Nmeas+1:end),source,detect);
            
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83] = app.CallingApp.cali_data{9:12,1:2};

            % empty structure which is defined in targetFileStruct.m
            len740 = size(Measmnt74,1);
            len780 = size(Measmnt78,1);
            len808 = size(Measmnt80,1);
            len830 = size(Measmnt83,1);
            targetFileStruct = getTargetFileStruct(len740,len780,len808,len830);
            
            if ~iscell(pairs74)			% should have the same action for all four wavelength
            	pairs74 = {pairs74};
            	pairs78 = {pairs78};
            	pairs80 = {pairs80};
            	pairs83 = {pairs83};
            end    
            if ~iscell(Measmnt74)	% should have the same action for all four wavelength
            	Measmnt74 = {Measmnt74};
            	Measmnt78 = {Measmnt78};
            	Measmnt80 = {Measmnt80};
            	Measmnt83 = {Measmnt83};
            end
            
            numOfDataFiles = 1;                 % keep it consistence with multiple file load
            pert740_2d = {numOfDataFiles};      % build cell array for pert on each file and will be used by outliers removal
            pert780_2d = {numOfDataFiles}; 
            pert808_2d = {numOfDataFiles};
            pert830_2d = {numOfDataFiles};
            Measmnt740_2d = {numOfDataFiles};   % build cell array for Measmnt on each file and will be used by outliers removal
            Measmnt780_2d = {numOfDataFiles}; 
            Measmnt808_2d = {numOfDataFiles};
            Measmnt830_2d = {numOfDataFiles};
            sddist740_2d = {numOfDataFiles};
            sddist780_2d = {numOfDataFiles};
            sddist808_2d = {numOfDataFiles};
            sddist830_2d = {numOfDataFiles};

            % will eliminate the data which has real(pert) < -1.1
            [pert74,data74,Nmeas74new,Measmnt74new,sddist74new,pairs74New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs74,aba74,abp74,aba_r74,abp_r74,Measmnt74{end});  % both pert and data are cells
            [pert78,data78,Nmeas78new,Measmnt78new,sddist78new,pairs78New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs78,aba78,abp78,aba_r78,abp_r78,Measmnt78{end});
            [pert80,data80,Nmeas80new,Measmnt80new,sddist80new,pairs80New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs80,aba80,abp80,aba_r80,abp_r80,Measmnt80{end});
            [pert83,data83,Nmeas83new,Measmnt83new,sddist83new,pairs83New] = bgfieldandpert_func(source,detect,sddist_matrix,pairs83,aba83,abp83,aba_r83,abp_r83,Measmnt83{end});
            
            app.origdata = {data74,data78,data80,data83;
                            pert74,pert78,pert80,pert83;
                            {app.sddist74{end}},{app.sddist78{end}},{app.sddist80{end}},{app.sddist83{end}};
                            {app.pairs74{end}},{app.pairs78{end}},{app.pairs80{end}},{app.pairs83{end}};
                            {app.Measmnt74{end}},{app.Measmnt78{end}},{app.Measmnt80{end}},{app.Measmnt83{end}};};
            
            pert740_2d{1} = pert74{end};  % build cell array for pert on each file and will be used by outliers removal
            pert780_2d{1} = pert78{end}; 
            pert808_2d{1} = pert80{end};
            pert830_2d{1} = pert83{end};
            Measmnt740_2d{1} = Measmnt74new{end};
            Measmnt780_2d{1} = Measmnt78new{end};
            Measmnt808_2d{1} = Measmnt80new{end};
            Measmnt830_2d{1} = Measmnt83new{end};
            sddist740_2d{1} = sddist74new{end};
            sddist780_2d{1} = sddist78new{end};
            sddist808_2d{1} = sddist80new{end};
            sddist830_2d{1} = sddist83new{end};
            pairs740_2d{1} = pairs74New{end};
            pairs780_2d{1} = pairs78New{end};
            pairs808_2d{1} = pairs80New{end};
            pairs830_2d{1} = pairs83New{end};
            
            [pert740_cell,Measmnt740_cell,sddist740_cell,pairs740_cell] = applyOutlierKMean7(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_cell,Measmnt780_cell,sddist780_cell,pairs780_cell] = applyOutlierKMean7(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_cell,Measmnt808_cell,sddist808_cell,pairs808_cell] = applyOutlierKMean7(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_cell,Measmnt830_cell,sddist830_cell,pairs830_cell] = applyOutlierKMean7(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            
            targetFileStruct(1).fileName = tar_value;
            targetFileStruct(1).fileSeq = 1; 
            targetFileStruct(1).pert740 = pert740_cell{1};      % the ones which have gone through -1.1 filter AND outliers removal
            targetFileStruct(1).pert780 = pert780_cell{1};
            targetFileStruct(1).pert808 = pert808_cell{1};
            targetFileStruct(1).pert830 = pert830_cell{1};
            targetFileStruct(1).sddist740 = sddist740_cell{1};
            targetFileStruct(1).sddist780 = sddist780_cell{1};
            targetFileStruct(1).sddist808 = sddist808_cell{1};
            targetFileStruct(1).sddist830 = sddist830_cell{1};
            targetFileStruct(1).Measmnt740 = Measmnt740_cell{1};
            targetFileStruct(1).Measmnt780 = Measmnt780_cell{1};
            targetFileStruct(1).Measmnt808 = Measmnt808_cell{1};
            targetFileStruct(1).Measmnt830 = Measmnt830_cell{1};
            targetFileStruct(1).pairs740 = pairs740_cell{1};
            targetFileStruct(1).pairs780 = pairs780_cell{1};
            targetFileStruct(1).pairs808 = pairs808_cell{1};
            targetFileStruct(1).pairs830 = pairs830_cell{1};
                
            targetFileStruct(1).pert740A = pert740_2d{1};       % the ones which DO NOT go through outliers removal, just -1.1 filter
            targetFileStruct(1).pert780A = pert780_2d{1};
            targetFileStruct(1).pert808A = pert808_2d{1};
            targetFileStruct(1).pert830A = pert830_2d{1};
            targetFileStruct(1).sddist740A = sddist740_2d{1};
            targetFileStruct(1).sddist780A = sddist780_2d{1};
            targetFileStruct(1).sddist808A = sddist808_2d{1};
            targetFileStruct(1).sddist830A = sddist830_2d{1};
            targetFileStruct(1).Measmnt740A = Measmnt740_2d{1};
            targetFileStruct(1).Measmnt780A = Measmnt780_2d{1};
            targetFileStruct(1).Measmnt808A = Measmnt808_2d{1};
            targetFileStruct(1).Measmnt830A = Measmnt830_2d{1};
            targetFileStruct(1).pairs740A = pairs740_2d{1};
            targetFileStruct(1).pairs780A = pairs780_2d{1};
            targetFileStruct(1).pairs808A = pairs808_2d{1};
            targetFileStruct(1).pairs830A = pairs830_2d{1};
            
            [pert740_lof,Measmnt740_lof,sddist740_lof,pairs740_lof] = LOFmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect);
            [pert780_lof,Measmnt780_lof,sddist780_lof,pairs780_lof] = LOFmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect);
            [pert808_lof,Measmnt808_lof,sddist808_lof,pairs808_lof] = LOFmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect);
            [pert830_lof,Measmnt830_lof,sddist830_lof,pairs830_lof] = LOFmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect);
            targetFileStruct(1).pert740L = pert740_lof{1};       % the ones which go through LOF outliers removal
            targetFileStruct(1).pert780L = pert780_lof{1};
            targetFileStruct(1).pert808L = pert808_lof{1};
            targetFileStruct(1).pert830L = pert830_lof{1};
            targetFileStruct(1).sddist740L = sddist740_lof{1};
            targetFileStruct(1).sddist780L = sddist780_lof{1};
            targetFileStruct(1).sddist808L = sddist808_lof{1};
            targetFileStruct(1).sddist830L = sddist830_lof{1};
            targetFileStruct(1).Measmnt740L = Measmnt740_lof{1};
            targetFileStruct(1).Measmnt780L = Measmnt780_lof{1};
            targetFileStruct(1).Measmnt808L = Measmnt808_lof{1};
            targetFileStruct(1).Measmnt830L = Measmnt830_lof{1};
            targetFileStruct(1).pairs740L = pairs740_lof{1};
            targetFileStruct(1).pairs780L = pairs780_lof{1};
            targetFileStruct(1).pairs808L = pairs808_lof{1};
            targetFileStruct(1).pairs830L = pairs830_lof{1};
            range = app.ELPconfidenceintervalEditField.Value;
            [pert740_elp,Measmnt740_elp,sddist740_elp,pairs740_elp] = ELPmethod(pert740_2d,Measmnt740_2d,sddist740_2d,pairs740_2d,source,detect,range);
            [pert780_elp,Measmnt780_elp,sddist780_elp,pairs780_elp] = ELPmethod(pert780_2d,Measmnt780_2d,sddist780_2d,pairs780_2d,source,detect,range);
            [pert808_elp,Measmnt808_elp,sddist808_elp,pairs808_elp] = ELPmethod(pert808_2d,Measmnt808_2d,sddist808_2d,pairs808_2d,source,detect,range);
            [pert830_elp,Measmnt830_elp,sddist830_elp,pairs830_elp] = ELPmethod(pert830_2d,Measmnt830_2d,sddist830_2d,pairs830_2d,source,detect,range);
            targetFileStruct(1).pert740E = pert740_elp{1};       % the ones which go through elp outliers removal
            targetFileStruct(1).pert780E = pert780_elp{1};
            targetFileStruct(1).pert808E = pert808_elp{1};
            targetFileStruct(1).pert830E = pert830_elp{1};
            targetFileStruct(1).sddist740E = sddist740_elp{1};
            targetFileStruct(1).sddist780E = sddist780_elp{1};
            targetFileStruct(1).sddist808E = sddist808_elp{1};
            targetFileStruct(1).sddist830E = sddist830_elp{1};
            targetFileStruct(1).Measmnt740E = Measmnt740_elp{1};
            targetFileStruct(1).Measmnt780E = Measmnt780_elp{1};
            targetFileStruct(1).Measmnt808E = Measmnt808_elp{1};
            targetFileStruct(1).Measmnt830E = Measmnt830_elp{1};
            targetFileStruct(1).pairs740E = pairs740_elp{1};
            targetFileStruct(1).pairs780E = pairs780_elp{1};
            targetFileStruct(1).pairs808E = pairs808_elp{1};
            targetFileStruct(1).pairs830E = pairs830_elp{1};
                
            app.targetFileStruct = targetFileStruct;
            app.pairs74 = {targetFileStruct(1).pairs740};
            app.pairs78 = {targetFileStruct(1).pairs780};
            app.pairs80 = {targetFileStruct(1).pairs808};
            app.pairs83 = {targetFileStruct(1).pairs830};
            app.sddist_matrix = sddist_matrix;
            app.sddist74 = {targetFileStruct(1).sddist740}; % make it into cell array because of undo
            app.sddist78 = {targetFileStruct(1).sddist780};
            app.sddist80 = {targetFileStruct(1).sddist808};
            app.sddist83 = {targetFileStruct(1).sddist830};
            
            app.Measmnt74 = Measmnt740_cell{1};
            app.Measmnt78 = Measmnt780_cell{1};
            app.Measmnt80 = Measmnt808_cell{1};
            app.Measmnt83 = Measmnt830_cell{1};
            if ~iscell(app.Measmnt74)
                app.Measmnt74 = {app.Measmnt74};
                app.Measmnt78 = {app.Measmnt78};
                app.Measmnt80 = {app.Measmnt80};
                app.Measmnt83 = {app.Measmnt83};
            end
            
            app.pert74 = {targetFileStruct(1).pert740};
            app.pert78 = {targetFileStruct(1).pert780};
            app.pert80 = {targetFileStruct(1).pert808};
            app.pert83 = {targetFileStruct(1).pert830};
            
            app.data74 = {[real(targetFileStruct(1).pert740) imag(targetFileStruct(1).pert740)]};  %can't have any transportation because they are complex numbers
            app.data78 = {[real(targetFileStruct(1).pert780) imag(targetFileStruct(1).pert780)]};
            app.data80 = {[real(targetFileStruct(1).pert808) imag(targetFileStruct(1).pert808)]};
            app.data83 = {[real(targetFileStruct(1).pert830) imag(targetFileStruct(1).pert830)]};
            
            app.CallingApp.bga74 = aba_r74;    % get from load reference file on the imger_4wvl
            app.CallingApp.bgp74 = abp_r74;
            app.CallingApp.bga78 = aba_r78;
            app.CallingApp.bgp78 = abp_r78;
            app.CallingApp.bga80 = aba_r80;
            app.CallingApp.bgp80 = abp_r80;
            app.CallingApp.bga83 = aba_r83;
            app.CallingApp.bgp83 = abp_r83;
            
            app.CallingApp.aba74 = aba74;
            app.CallingApp.aba78 = aba78;
            app.CallingApp.aba80 = aba80;
            app.CallingApp.aba83 = aba83;
            app.CallingApp.abp74 = abp74;
            app.CallingApp.abp78 = abp78;
            app.CallingApp.abp80 = abp80;
            app.CallingApp.abp83 = abp83;
            
            targetFileStruct = app.targetFileStruct;
            app.initial_targetFileStruct = targetFileStruct;
            draw_pert(app,targetFileStruct)
            
            undoStruct = getCurrentDisplayInfo(app,targetFileStruct);
            app.undoStructCell{1}{1} = undoStruct;  % initial value
            
            app.undoSeq = 0;                        % initial setting for single target file
            app.numofundo.Value = num2str(app.undoSeq);
            

            app.KmeansOutliersRemovalCheckBox.Value = current_kmeans;
            app.LOFRemovalCheckBox.Value = current_lof;
            app.ELPRemovalCheckBox.Value = current_elp;
        end

        % Button pushed function: AutoimagingButton
        function AutoimagingButtonPushed(app, event)
            img_dir = app.CallingApp.img_dir;
            app.ELPRemovalCheckBox.Value = 1;
            ELPconfidenceintervalEditFieldValueChanged(app, event)
            tar_value = app.TarDropDown.Value;
            tar_index = regexp(tar_value,'\d*','Match');
            img_index = regexp(img_dir,'\d*','Match');
            for i = 1:length(img_index)
                img_id = img_index{i};
                result = strcmp(img_id, tar_index);
                if all(result) == 1
                    img_ind = i;
                    break
                end
            end
            app.img_path = img_dir{img_ind};
            SemisegmentButtonPushed(app, event)
            app.prob74=0;app.prob78=0;app.prob80=0;app.prob83=0;
            count = 1;
            while mean([app.prob74,app.prob78,app.prob80,app.prob83])<0.35
                switch count
                    case 1
                        CGDREGButtonPushed(app, event)
                    case 2
                        AutoEncoderButtonPushed(app,event)
                    case 3
                        CGDButtonPushed(app, event)
                end
                ReconEvaluationButtonPushed(app, event)
                count = count + 1;
            end
            WithReconclassificationButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TarDropDownLabel = uilabel(app.UIFigure);
            app.TarDropDownLabel.Text = 'Tar';
            app.TarDropDown = uidropdown(app.UIFigure);
            app.TarDropDown.ValueChangedFcn = createCallbackFcn(app, @TarDropDownValueChanged, true);
            app.SourceWavelengthsButtonGroup = uibuttongroup(app.UIFigure);
            app.SourceWavelengthsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceWavelengthsButtonGroupSelectionChanged, true);
            app.SourceWavelengthsButtonGroup.Title = 'Source Wavelengths';
            app.nm740Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm740Button.Text = '740nm';
            app.nm740Button.Value = true;
            app.nm780Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm780Button.Text = '780nm';
            app.nm808Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm808Button.Text = '808nm';
            app.nm830Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm830Button.Text = '830nm';
            app.RefTextAreaLabel = uilabel(app.UIFigure);
            app.RefTextAreaLabel.Text = 'Ref';
            app.RefTextArea = uitextarea(app.UIFigure);
            app.RefTextArea.Editable = 'off';
            app.AutoimagingButton = uibutton(app.UIFigure, 'push');
            app.AutoimagingButton.ButtonPushedFcn = createCallbackFcn(app, @AutoimagingButtonPushed, true);
            app.AutoimagingButton.Text = 'Auto imaging';
            app.PerturbationdisplayPanel = uipanel(app.UIFigure);
            app.PerturbationdisplayPanel.Title = 'Perturbation display';
            app.pert = uiaxes(app.PerturbationdisplayPanel);
            title(app.pert, 'Im(Pert) vs. Re(Pert)')
            xlabel(app.pert, 'X')
            ylabel(app.pert, 'Y')
            zlabel(app.pert, 'Z')
            app.Imagpart = uiaxes(app.PerturbationdisplayPanel);
            title(app.Imagpart, 'Im(Pert) vs. Source-Detect Dist')
            xlabel(app.Imagpart, 'SD-dist')
            ylabel(app.Imagpart, 'Imag')
            zlabel(app.Imagpart, 'Z')
            app.Realpart = uiaxes(app.PerturbationdisplayPanel);
            title(app.Realpart, 'Re(Pert) vs. Source-Detect Dist')
            xlabel(app.Realpart, 'SD-dist')
            ylabel(app.Realpart, 'Real')
            zlabel(app.Realpart, 'Z')
            app.SavedataButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.ClusterUnwrapButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.ClusterUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterUnwrapButtonPushed, true);
            app.ClusterUnwrapButton.Text = 'Cluster Unwrap';
            app.FittingDeleteButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.FittingDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @FittingDeleteButtonPushed, true);
            app.FittingDeleteButton.Text = 'Fitting Delete';
            app.FittingUnwrapButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.FittingUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @FittingUnwrapButtonPushed, true);
            app.FittingUnwrapButton.Text = 'Fitting Unwrap';
            app.numofundo = uitextarea(app.PerturbationdisplayPanel);
            app.numofundo.Editable = 'off';
            app.UndoAllButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.UndoAllButton.ButtonPushedFcn = createCallbackFcn(app, @UndoAllButtonPushed, true);
            app.UndoAllButton.Text = 'Undo All';
            app.UndoLastButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.UndoLastButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastButtonPushed, true);
            app.UndoLastButton.Text = 'Undo Last';
            app.DeletePointButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.SelectPointButton = uibutton(app.PerturbationdisplayPanel, 'push');
            app.SelectPointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointButtonPushed, true);
            app.SelectPointButton.Text = 'Select Point';
            app.LesionDimensionsPanel = uipanel(app.UIFigure);
            app.LesionDimensionsPanel.Title = 'Lesion Dimensions';
            app.MeshLabel = uilabel(app.LesionDimensionsPanel);
            app.MeshLabel.Text = 'Mesh';
            app.TargetLabel = uilabel(app.LesionDimensionsPanel);
            app.TargetLabel.Text = 'Target';
            app.xradiusEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.xradiusEditFieldLabel.Text = 'x radius';
            app.xradiusEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.xradiusEditField.Value = 4.5;
            app.yradiusEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.yradiusEditFieldLabel.Text = 'y radius';
            app.yradiusEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.yradiusEditField.Value = 4.5;
            app.ztargetradiusLabel = uilabel(app.LesionDimensionsPanel);
            app.ztargetradiusLabel.Text = 'z (target) radius';
            app.ztargetradiusEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.ztargetradiusEditField.Value = 0.49;
            app.xcenterposEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.xcenterposEditFieldLabel.Text = 'x center pos';
            app.xcenterposEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.ycenterposEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.ycenterposEditFieldLabel.Text = 'y center pos';
            app.ycenterposEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.centerdepthEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.centerdepthEditFieldLabel.Text = 'center depth';
            app.centerdepthEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.centerdepthEditField.Value = 1.5;
            app.xygridsizeEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.xygridsizeEditFieldLabel.Text = 'xy grid size';
            app.xygridsizeEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.xygridsizeEditField.Value = 0.25;
            app.ndepthEditFieldLabel = uilabel(app.LesionDimensionsPanel);
            app.ndepthEditFieldLabel.Text = 'n depth';
            app.ndepthEditField = uieditfield(app.LesionDimensionsPanel, 'numeric');
            app.ndepthEditField.Limits = [0 0.75];
            app.ndepthEditField.Value = 0.25;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AbsorbscatterTab = uitab(app.TabGroup);
            app.AbsorbscatterTab.Title = 'Absorb & scatter';
            app.HemoglobinLabel = uilabel(app.AbsorbscatterTab);
            app.HemoglobinLabel.Text = 'Hemoglobin';
            app.MeanLabel_3 = uilabel(app.AbsorbscatterTab);
            app.MeanLabel_3.Text = 'Mean';
            app.MaxLabel_3 = uilabel(app.AbsorbscatterTab);
            app.MaxLabel_3.Text = 'Max';
            app.maxhb = uitextarea(app.AbsorbscatterTab);
            app.meanhb = uitextarea(app.AbsorbscatterTab);
            app.TarabsorptionPanel = uipanel(app.AbsorbscatterTab);
            app.TarabsorptionPanel.Title = 'Tar absorption';
            app.MeanLabel_2 = uilabel(app.TarabsorptionPanel);
            app.MeanLabel_2.Text = 'Mean';
            app.MaxLabel_2 = uilabel(app.TarabsorptionPanel);
            app.MaxLabel_2.Text = 'Max';
            app.nmLabel = uilabel(app.TarabsorptionPanel);
            app.nmLabel.Text = '740nm';
            app.nmLabel_2 = uilabel(app.TarabsorptionPanel);
            app.nmLabel_2.Text = '780nm';
            app.nmLabel_3 = uilabel(app.TarabsorptionPanel);
            app.nmLabel_3.Text = '808nm';
            app.nmLabel_4 = uilabel(app.TarabsorptionPanel);
            app.nmLabel_4.Text = '830nm';
            app.maxab740 = uitextarea(app.TarabsorptionPanel);
            app.maxab780 = uitextarea(app.TarabsorptionPanel);
            app.maxab808 = uitextarea(app.TarabsorptionPanel);
            app.maxab830 = uitextarea(app.TarabsorptionPanel);
            app.meanab740 = uitextarea(app.TarabsorptionPanel);
            app.meanab780 = uitextarea(app.TarabsorptionPanel);
            app.meanab808 = uitextarea(app.TarabsorptionPanel);
            app.meanab830 = uitextarea(app.TarabsorptionPanel);
            app.RefcoefficientPanel = uipanel(app.AbsorbscatterTab);
            app.RefcoefficientPanel.Title = 'Ref coefficient';
            app.MuspLabel = uilabel(app.RefcoefficientPanel);
            app.MuspLabel.Text = 'Musp';
            app.MuaLabel = uilabel(app.RefcoefficientPanel);
            app.MuaLabel.Text = 'Mua';
            app.mua740 = uitextarea(app.RefcoefficientPanel);
            app.mua780 = uitextarea(app.RefcoefficientPanel);
            app.mua808 = uitextarea(app.RefcoefficientPanel);
            app.mua830 = uitextarea(app.RefcoefficientPanel);
            app.musp740 = uitextarea(app.RefcoefficientPanel);
            app.musp780 = uitextarea(app.RefcoefficientPanel);
            app.musp808 = uitextarea(app.RefcoefficientPanel);
            app.musp830 = uitextarea(app.RefcoefficientPanel);
            app.AbsorptionScatteringCoefficientsPanel = uipanel(app.AbsorbscatterTab);
            app.AbsorptionScatteringCoefficientsPanel.Title = 'Absorption & Scattering Coefficients';
            app.KmeansOutliersRemovalCheckBox = uicheckbox(app.AbsorptionScatteringCoefficientsPanel);
            app.KmeansOutliersRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @KmeansOutliersRemovalCheckBoxValueChanged, true);
            app.KmeansOutliersRemovalCheckBox.Text = 'Kmeans Outliers Removal';
            app.LOFRemovalCheckBox = uicheckbox(app.AbsorptionScatteringCoefficientsPanel);
            app.LOFRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @LOFRemovalCheckBoxValueChanged, true);
            app.LOFRemovalCheckBox.Text = 'LOF Removal';
            app.ELPRemovalCheckBox = uicheckbox(app.AbsorptionScatteringCoefficientsPanel);
            app.ELPRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ELPRemovalCheckBoxValueChanged, true);
            app.ELPRemovalCheckBox.Text = 'Elliptic Outlier Removal';
            app.muaTextAreaLabel = uilabel(app.AbsorptionScatteringCoefficientsPanel);
            app.muaTextAreaLabel.Text = 'mua:';
            app.refmuaTextArea = uitextarea(app.AbsorptionScatteringCoefficientsPanel);
            app.muspLabel = uilabel(app.AbsorptionScatteringCoefficientsPanel);
            app.muspLabel.Text = 'musp:';
            app.refmuspTextArea = uitextarea(app.AbsorptionScatteringCoefficientsPanel);
            app.ELPconfidenceintervalEditFieldLabel = uilabel(app.AbsorptionScatteringCoefficientsPanel);
            app.ELPconfidenceintervalEditFieldLabel.Text = 'ELP confidence interval ';
            app.ELPconfidenceintervalEditField = uieditfield(app.AbsorptionScatteringCoefficientsPanel, 'numeric');
            app.ELPconfidenceintervalEditField.ValueChangedFcn = createCallbackFcn(app, @ELPconfidenceintervalEditFieldValueChanged, true);
            app.ELPconfidenceintervalEditField.Value = 0.975;
            app.TotalTab = uitab(app.TabGroup);
            app.TotalTab.Title = 'Total';
            app.TotalhemoglobinPanel = uipanel(app.TotalTab);
            app.TotalhemoglobinPanel.Title = 'Total hemoglobin';
            app.OxyTab = uitab(app.TabGroup);
            app.OxyTab.Title = 'Oxy';
            app.OxyhemoglobinPanel = uipanel(app.OxyTab);
            app.OxyhemoglobinPanel.Title = 'Oxy hemoglobin';
            app.DeoxyTab = uitab(app.TabGroup);
            app.DeoxyTab.Title = 'Deoxy';
            app.DeoxyhemoglobinPanel = uipanel(app.DeoxyTab);
            app.DeoxyhemoglobinPanel.Title = 'Deoxy hemoglobin';
            app.ImagingmethodsPanel = uipanel(app.UIFigure);
            app.ImagingmethodsPanel.Title = 'Imaging methods';
            app.SegmentationsinglemearsurementclassificationPanel = uipanel(app.ImagingmethodsPanel);
            app.SegmentationsinglemearsurementclassificationPanel.Title = 'Segmentation, single mearsurement & classification';
            app.TartoPertButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.TartoPertButton.ButtonPushedFcn = createCallbackFcn(app, @TartoPertButtonPushed, true);
            app.TartoPertButton.Text = 'Tar to Pert';
            app.USCropButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.USCropButton.ButtonPushedFcn = createCallbackFcn(app, @USCropButtonPushed, true);
            app.USCropButton.Text = 'US Crop';
            app.WithReconclassificationButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.WithReconclassificationButton.ButtonPushedFcn = createCallbackFcn(app, @WithReconclassificationButtonPushed, true);
            app.WithReconclassificationButton.Text = 'With Recon classification';
            app.TargetprocessButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.TargetprocessButton.ButtonPushedFcn = createCallbackFcn(app, @TargetprocessButtonPushed, true);
            app.TargetprocessButton.Text = 'Target process';
            app.WithHistoclassificationButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.WithHistoclassificationButton.ButtonPushedFcn = createCallbackFcn(app, @WithHistoclassificationButtonPushed, true);
            app.WithHistoclassificationButton.Text = 'With Histo classification';
            app.SemisegmentButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.SemisegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SemisegmentButtonPushed, true);
            app.SemisegmentButton.Text = 'Semi segment';
            app.MeasureUSButton = uibutton(app.SegmentationsinglemearsurementclassificationPanel, 'push');
            app.MeasureUSButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureUSButtonPushed, true);
            app.MeasureUSButton.Text = 'Measure US';
            app.DOTreconstructionPanel = uipanel(app.ImagingmethodsPanel);
            app.DOTreconstructionPanel.Title = 'DOT reconstruction';
            app.FlipMatricesCheckBox = uicheckbox(app.DOTreconstructionPanel);
            app.FlipMatricesCheckBox.Text = 'Flip Matrices';
            app.LargeCheckBox = uicheckbox(app.DOTreconstructionPanel);
            app.LargeCheckBox.Text = 'Large';
            app.ShowabsorptionCheckBox = uicheckbox(app.DOTreconstructionPanel);
            app.ShowabsorptionCheckBox.Text = 'Show absorption';
            app.CGDButton = uibutton(app.DOTreconstructionPanel, 'push');
            app.CGDButton.ButtonPushedFcn = createCallbackFcn(app, @CGDButtonPushed, true);
            app.CGDButton.Text = 'CGD';
            app.CGDREGButton = uibutton(app.DOTreconstructionPanel, 'push');
            app.CGDREGButton.ButtonPushedFcn = createCallbackFcn(app, @CGDREGButtonPushed, true);
            app.CGDREGButton.Text = 'CGD REG';
            app.AutoEncoderButton = uibutton(app.DOTreconstructionPanel, 'push');
            app.AutoEncoderButton.ButtonPushedFcn = createCallbackFcn(app, @AutoEncoderButtonPushed, true);
            app.AutoEncoderButton.Text = 'Auto Encoder';
            app.L1Button = uibutton(app.DOTreconstructionPanel, 'push');
            app.L1Button.ButtonPushedFcn = createCallbackFcn(app, @L1ButtonPushed, true);
            app.L1Button.Text = 'L1';
            app.ReconEvaluationButton = uibutton(app.DOTreconstructionPanel, 'push');
            app.ReconEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @ReconEvaluationButtonPushed, true);
            app.ReconEvaluationButton.Text = 'Recon Evaluation';
            app.MaxIterationEditFieldLabel = uilabel(app.DOTreconstructionPanel);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.DOTreconstructionPanel, 'numeric');
            app.MaxIterationEditField.Value = 3;
            app.EPSEditFieldLabel = uilabel(app.DOTreconstructionPanel);
            app.EPSEditFieldLabel.Text = '%EPS';
            app.EPSEditField = uieditfield(app.DOTreconstructionPanel, 'numeric');
            app.EPSEditField.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imaging(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end