classdef measure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DepthTextArea         matlab.ui.control.TextArea
        DepthTextAreaLabel    matlab.ui.control.Label
        zradiusTextArea       matlab.ui.control.TextArea
        zradiusTextAreaLabel  matlab.ui.control.Label
        HeightButton          matlab.ui.control.Button
        RulerMeasureButton    matlab.ui.control.Button
        SaveExitButton        matlab.ui.control.Button
        WidthMeasureButton    matlab.ui.control.Button
        RulerTextArea         matlab.ui.control.TextArea
        RulerTextAreaLabel    matlab.ui.control.Label
        SetRulerButton        matlab.ui.control.Button
        CropimageButton       matlab.ui.control.Button
        LoadimageButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
        CallingApp
        us_features
        us_predict
        img
        selected_axis
        xbounds
        ybounds
        nlayers
        para
        width1
        width2
        width3
        width4
        width5
        z_radius
        target_depth
    end
    
    methods (Access = private)
        
        function newPos_CallbackRuler(app,evt)
            % Display the current point position in a text label
%             title(mat2str(evt.CurrentPosition,3));
            dist = sqrt((evt.CurrentPosition(1,1)-evt.CurrentPosition(2,1))^2+(evt.CurrentPosition(1,2)-evt.CurrentPosition(2,2))^2);
%             h_textbox = findobj(gcf,'Tag','rulerValue');
            text = app.RulerTextArea.Value;
            
            if ~isempty(text)
                app.RulerTextArea.Value = num2str(dist,5);
%                 set(h_textbox,'string', num2str(dist,'%2.3f'));
%                 set(h_textbox,'Value', dist);
            end
        end
        
        function measure_ruler(app,y,x)
            y1 = round(y/2);
            x1 = round(x/2);
           
            app.RulerTextArea.Value = num2str(100);

            h = drawline(app.UIAxes,'Position',[x1 y1; x1 y1+100]);    % The new position, pos, has the form, [X1 Y1; X2 Y2].
            set(h,'Tag','ruler_vline');
            propListener = addlistener(h,"MovingROI",@(src,evt) newPos_CallbackRuler(app,evt));

        end
        
        function newPos_width(app,evt,idx)
            % Display the current point position in a text label
            dist = sqrt((evt.CurrentPosition(1,1)-evt.CurrentPosition(2,1))^2+(evt.CurrentPosition(1,2)-evt.CurrentPosition(2,2))^2);
%             h_textbox = findobj(gcf,'Tag','rulerValue');
            if idx == 1
                app.width1 = dist / str2double(app.RulerTextArea.Value);
            elseif idx == 2
                app.width2 = dist / str2double(app.RulerTextArea.Value);
            elseif idx == 3
                app.width3 = dist / str2double(app.RulerTextArea.Value);
            elseif idx == 4
                app.width4 = dist / str2double(app.RulerTextArea.Value);
            elseif idx == 5
                app.width5 = dist / str2double(app.RulerTextArea.Value);
            end
        end
        
        function measure_width(app,y,x,idx)
           
            width = drawline(app.UIAxes,'Position',[x y; x+100 y]);    % The new position, pos, has the form, [X1 Y1; X2 Y2].
            set(width,'Tag',['width',num2str(idx)]);
            propListener = addlistener(width,"MovingROI",@(src,evt) newPos_width(app,evt,idx));

        end
        
        function newPosdepth_CallbackRuler(app,evt)
            % Display the current point position in a text label
%             title(mat2str(evt.CurrentPosition,3));
            dist = sqrt((evt.CurrentPosition(1,1)-evt.CurrentPosition(2,1))^2+(evt.CurrentPosition(1,2)-evt.CurrentPosition(2,2))^2);
%             h_textbox = findobj(gcf,'Tag','rulerValue');
            text = app.DepthTextArea.Value;
            depth = dist/ str2double(app.RulerTextArea.Value);
            if ~isempty(text)
                app.DepthTextArea.Value = num2str(depth,5);
                app.target_depth = depth;
%                 set(h_textbox,'string', num2str(dist,'%2.3f'));
%                 set(h_textbox,'Value', dist);
            end
        end
        
        function measure_depth(app,y,x)
            y1 = round(y/2);
            x1 = round(x/2);
           
            app.DepthTextArea.Value = num2str(150/ str2double(app.RulerTextArea.Value));

            depth_line = drawline(app.UIAxes,'Position',[x1 y1; x1 y1+150]);    % The new position, pos, has the form, [X1 Y1; X2 Y2].
            set(depth_line,'Tag','height_vline');
            depth_propListener = addlistener(depth_line,"MovingROI",@(src,evt) newPosdepth_CallbackRuler(app,evt));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imagingapp, para)
            app.CallingApp = imagingapp;
            disableDefaultInteractivity(app.UIAxes)
            app.CropimageButton.Enable = false;
            app.SetRulerButton.Enable = false;
            app.WidthMeasureButton.Enable = false;
            app.SaveExitButton.Enable = false;
            app.RulerMeasureButton.Enable = false;
            app.HeightButton.Enable = false;
            app.para = para;
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [path, ~] = fileparts(app.CallingApp.CallingApp.ref_name{1});
            
            US_image = uigetfile({'*.png';'*.jpg';'*.bmp'},'Select an US image',path);
            us_path = fullfile(path, US_image);
            if ~isempty(dir(us_path))
                app.img = imread(us_path);
                imshow(app.img, 'Parent', app.UIAxes);
            else
                errordlg('Please select right US image','ERROR');
                return
            end
            if app.para(1) == 1
                app.SetRulerButton.Enable = true;
                app.nlayers = app.para(2);
            elseif app.para(1) == 0
                app.CropimageButton.Enable = true;
            elseif app.para(1) == 2
                app.SetRulerButton.Enable = true;
               
            end
            
            app.UIFigure.HandleVisibility = 'on';
        end

        % Button pushed function: CropimageButton
        function CropimageButtonPushed(app, event)
            %Delete box outlining selected points if there is already one there
            lines = findobj(gcbf,'Tag','L');
            delete(lines);
            
            [I, rect] = imcrop(app.img);
            f = waitbar(0,'Please wait US feature extraction ...');
            pause(0.5) 
            r1 = rectangle(app.UIAxes,'Position',rect,'EdgeColor','b','LineWidth',1.5);
            set(r1,'Tag','L');
            waitbar(0.33,f,'Please wait US feature extraction ...');
%             app.us_features = py.us_extract.us_feature_extract(py.numpy.array(I));
            us_results = py.firststage_feature_extraction.us_feature_extraction(py.numpy.array(I));
            app.us_predict = single(us_results{2});
            app.us_features = squeeze(double(single(us_results{1})));
%             app.us_features = squeeze(double(single(py.us_extract.us_feature_extract(py.numpy.array(I)))));
            waitbar(1,f,'Finished');
            close(f)
            app.SaveExitButton.Enable = true;
        end

        % Button pushed function: SetRulerButton
        function SetRulerButtonPushed(app, event)
            [y,x,z] = size(app.img);
            
            h_ruler_vline = findobj(gcf, 'Tag', 'ruler_vline'); 	% if there is a ruler vertical line, delete it
            if ~isempty(h_ruler_vline)
                delete(h_ruler_vline);
            end
            
            h_height_vline = findobj(gcf, 'Tag', 'height_vline'); 	% if there is a height vertical line, delete it
            if ~isempty(h_height_vline)
                delete(h_height_vline);
            end
            
            lines = findobj(gca,'Tag','L');                         % rectanglar 
            if ~isempty(lines)
                delete(lines);
            end
            if app.para(1) == 1
                app.WidthMeasureButton.Enable = true;
            elseif app.para(1) == 2
                app.RulerMeasureButton.Enable = true;
                app.HeightButton.Enable = true;
            end
            measure_ruler(app,y,x)
        end

        % Button pushed function: WidthMeasureButton
        function WidthMeasureButtonPushed(app, event)
            n_layers = app.nlayers;
            [y,x,z] = size(app.img);
            y1 = round(y/2);
            x1 = round(x/2);
            if n_layers == 1
                width_line1 = findobj(gcf, 'Tag', ['width',num2str(n_layers)]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line1)
                    delete(width_line1);
                end
            elseif n_layers == 2
                width_line1 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-1]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line1)
                    delete(width_line1);
                end
                width_line2 = findobj(gcf, 'Tag', ['width',num2str(n_layers)]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line2)
                    delete(width_line2);
                end
            elseif n_layers == 3
                width_line1 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-2]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line1)
                    delete(width_line1);
                end
                width_line2 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-1]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line2)
                    delete(width_line2);
                end
                width_line3 = findobj(gcf, 'Tag', ['width',num2str(n_layers)]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line3)
                    delete(width_line3);
                end
            elseif n_layers == 4
                width_line1 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-3]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line1)
                    delete(width_line1);
                end
                width_line2 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-2]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line2)
                    delete(width_line2);
                end
                width_line3 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-1]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line3)
                    delete(width_line3);
                end
                width_line4 = findobj(gcf, 'Tag', ['width',num2str(n_layers)]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line4)
                    delete(width_line4);
                end
            elseif n_layers == 5
                width_line1 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-4]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line1)
                    delete(width_line1);
                end
                width_line2 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-3]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line2)
                    delete(width_line2);
                end
                width_line3 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-2]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line3)
                    delete(width_line3);
                end
                width_line4 = findobj(gcf, 'Tag', ['width',num2str(n_layers)-1]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line4)
                    delete(width_line4);
                end
                width_line5 = findobj(gcf, 'Tag', ['width',num2str(n_layers)]); 	% if there is a ruler vertical line, delete it
                if ~isempty(width_line5)
                    delete(width_line5);
                end
            end
            
            for idx = 1:n_layers
                measure_width(app,y1+50*(idx-1),x1,idx)
            end
            app.SaveExitButton.Enable = true;
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            % Call main app's public function
            update_USinfo(app.CallingApp,app.us_features,app.width1,app.width2,app.width3,app.width4,app.width5,app.z_radius,app.target_depth,app.us_predict);
        
            % Delete the dialog box  
            delete(app)   
        end

        % Button pushed function: RulerMeasureButton
        function RulerMeasureButtonPushed(app, event)
            %Delete box outlining selected points if there is already one there
            lines = findobj(gcbf,'Tag','L');
            delete(lines);
            
            [I, rect] = imcrop(app.img);
            r1 = rectangle(app.UIAxes,'Position',rect,'EdgeColor','b','LineWidth',1.5);
            set(r1,'Tag','L');
            z_radius = rect(4)/ (2*str2double(app.RulerTextArea.Value));
            app.zradiusTextArea.Value = num2str(z_radius);
            app.z_radius = z_radius;
            app.SaveExitButton.Enable = true;
        end

        % Button pushed function: HeightButton
        function HeightButtonPushed(app, event)
            [y,x,z] = size(app.img);
            
%             h_ruler_vline = findobj(gcf, 'Tag', 'ruler_vline'); 	% if there is a ruler vertical line, delete it
%             if ~isempty(h_ruler_vline)
%                 delete(h_ruler_vline);
%             end
            
            h_height_vline = findobj(gcf, 'Tag', 'height_vline'); 	% if there is a height vertical line, delete it
            if ~isempty(h_height_vline)
                delete(h_height_vline);
            end
            
            measure_depth(app,y,x)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.CropimageButton = uibutton(app.UIFigure, 'push');
            app.CropimageButton.ButtonPushedFcn = createCallbackFcn(app, @CropimageButtonPushed, true);
            app.CropimageButton.Text = 'Crop image';
            app.SetRulerButton = uibutton(app.UIFigure, 'push');
            app.SetRulerButton.ButtonPushedFcn = createCallbackFcn(app, @SetRulerButtonPushed, true);
            app.SetRulerButton.Text = 'Set Ruler';
            app.RulerTextAreaLabel = uilabel(app.UIFigure);
            app.RulerTextAreaLabel.Text = 'Ruler';
            app.RulerTextArea = uitextarea(app.UIFigure);
            app.WidthMeasureButton = uibutton(app.UIFigure, 'push');
            app.WidthMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @WidthMeasureButtonPushed, true);
            app.WidthMeasureButton.Text = 'Width Measure';
            app.SaveExitButton = uibutton(app.UIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save/Exit';
            app.RulerMeasureButton = uibutton(app.UIFigure, 'push');
            app.RulerMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @RulerMeasureButtonPushed, true);
            app.RulerMeasureButton.Text = 'Ruler-Measure';
            app.HeightButton = uibutton(app.UIFigure, 'push');
            app.HeightButton.ButtonPushedFcn = createCallbackFcn(app, @HeightButtonPushed, true);
            app.HeightButton.Text = 'Height';
            app.zradiusTextAreaLabel = uilabel(app.UIFigure);
            app.zradiusTextAreaLabel.Text = 'z radius';
            app.zradiusTextArea = uitextarea(app.UIFigure);
            app.DepthTextAreaLabel = uilabel(app.UIFigure);
            app.DepthTextAreaLabel.Text = 'Depth';
            app.DepthTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = measure(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end