classdef preprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MeasurementsdisplayPanel      matlab.ui.container.Panel
        IntralipidTextArea            matlab.ui.control.TextArea
        IntralipidTextAreaLabel       matlab.ui.control.Label
        ReferenceTextArea             matlab.ui.control.TextArea
        ReferenceTextAreaLabel        matlab.ui.control.Label
        RemainingpointsTextArea       matlab.ui.control.TextArea
        RemainingpointsTextAreaLabel  matlab.ui.control.Label
        Intraamp                      matlab.ui.control.UIAxes
        Refamp                        matlab.ui.control.UIAxes
        Intraphase                    matlab.ui.control.UIAxes
        Refphase                      matlab.ui.control.UIAxes
        Motionplotamp                 matlab.ui.control.UIAxes
        Motionplotpha                 matlab.ui.control.UIAxes
        CreateimagesButton            matlab.ui.control.Button
        LogTextArea                   matlab.ui.control.TextArea
        LogTextAreaLabel              matlab.ui.control.Label
        OutlierremovalPanel           matlab.ui.container.Panel
        AutoUnwrapButton              matlab.ui.control.Button
        ClusterUnwrapButton           matlab.ui.control.Button
        Delete                        matlab.ui.control.Button
        Unwrap                        matlab.ui.control.Button
        UndoLastButton                matlab.ui.control.Button
        UndoAllButton                 matlab.ui.control.Button
        PhaseadjustButtonGroup        matlab.ui.container.ButtonGroup
        minus2pi                      matlab.ui.control.RadioButton
        plus2pi                       matlab.ui.control.RadioButton
        PhaseshiftButton              matlab.ui.control.Button
        RemovepointsButton            matlab.ui.control.Button
        SelectpointsButton            matlab.ui.control.Button
        Smooth                        matlab.ui.control.Button
        refmuspTextArea               matlab.ui.control.TextArea
        muspTextArea_2Label           matlab.ui.control.Label
        intramuspTextArea             matlab.ui.control.TextArea
        muspTextAreaLabel             matlab.ui.control.Label
        refmuaTextArea                matlab.ui.control.TextArea
        muaTextArea_2Label            matlab.ui.control.Label
        intramuaTextArea              matlab.ui.control.TextArea
        muaTextAreaLabel              matlab.ui.control.Label
        RefTissueLabel                matlab.ui.control.Label
        IntralipidLabel               matlab.ui.control.Label
        AbsorptionScatteringCoefficientsLabel  matlab.ui.control.Label
        SourceWavelengthsButtonGroup  matlab.ui.container.ButtonGroup
        nm830Button                   matlab.ui.control.RadioButton
        nm808Button                   matlab.ui.control.RadioButton
        nm780Button                   matlab.ui.control.RadioButton
        nm740Button                   matlab.ui.control.RadioButton
        IntralipidDropDown            matlab.ui.control.DropDown
        IntralipidDropDownLabel       matlab.ui.control.Label
        Calibration                   matlab.ui.control.Button
        MotionMismatchcheckPanel      matlab.ui.container.Panel
        WavelengthDropDown            matlab.ui.control.DropDown
        WavelengthDropDownLabel       matlab.ui.control.Label
        PlotindexDropDown             matlab.ui.control.DropDown
        PlotindexDropDownLabel        matlab.ui.control.Label
        FilenameDropDown              matlab.ui.control.DropDown
        FilenameDropDownLabel         matlab.ui.control.Label
        CreatemotionfolderCheckBox    matlab.ui.control.CheckBox
        Mismatchcheck                 matlab.ui.control.Button
        Motioncheck                   matlab.ui.control.Button
        PlotmotionCheckBox            matlab.ui.control.CheckBox
        AutopreprocessingButton       matlab.ui.control.Button
        CurrentgeometryTextArea       matlab.ui.control.TextArea
        CurrentgeometryTextAreaLabel  matlab.ui.control.Label
        CurrentfolderTextArea         matlab.ui.control.TextArea
        CurrentfolderTextAreaLabel    matlab.ui.control.Label
        GeoDropDown                   matlab.ui.control.DropDown
        GeometryLabel                 matlab.ui.control.Label
        LoadFolder                    matlab.ui.control.Button
    end

    
    properties (Access = private)
       
        imagingApp
    end
    
    properties (Access = public)
        mode
        tar_name
        ref_name
        intra_name
        tar_nLesion
        ref_nLesion
        intra_nLesion
        meanTol
        ptDiffTol
        badptsTol
        Ns
        Nd
        s_geom
        d_geom
        Measmnt
        Measmnt74
        Measmnt78
        Measmnt80
        Measmnt83
        pairs
        pairs74
        pairs78
        pairs80
        pairs83
        sddist_matrix
        ref_isMotion
        tar_isMotion
        tar_alldata
        ref_alldata
        intra_alldata
        cali_data
        selected_axis
        xbounds
        ybounds
        sddist74
        sddist78
        sddist80
        sddist83
        sddist
        pert74
        pert78
        pert80
        pert83
        data74
        data78
        data80
        data83
        bga74
        bgp74
        bga78
        bgp78
        bga80
        bgp80
        bga83
        bgp83
        aba74
        aba78
        aba80
        aba83
        abp74
        abp78
        abp80
        abp83
        targetFileStruct
        mua074
        mus074
        mua078
        mus078
        mua080
        mus080
        mua083
        mus083
        reference
        tar2pertmode = 0
        aveY
        folder_path
        answer
        Num_sort
        img_dir
    end
    
    methods (Access = private)
        
        function motionplot(app) %plot amplitude and phase in motion checking
            cla(app.Motionplotamp);
            cla(app.Motionplotpha);
            value = app.FilenameDropDown.Value;
            file_name = [app.tar_name , app.ref_name];
            name = strfind(file_name,value(1:end-2));
            file_idx = find(~cellfun(@isempty, name));
            plot_name = file_name(file_idx);
            number=file_idx(end)/3;
            number_of_pair = app.Ns * app.Nd;
            total_Data1 = [app.tar_alldata{1};app.ref_alldata{1}];
            total_Data2 = [app.tar_alldata{2};app.ref_alldata{2}];
            total_Data3 = [app.tar_alldata{3};app.ref_alldata{3}];
            % load the raw data in total_Data
            if app.PlotindexDropDown.Value == '1 vs. 2'
                amp741 = reshape(total_Data1{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp781 = reshape(total_Data1{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp801 = reshape(total_Data1{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp831 = reshape(total_Data1{number,4}(1:number_of_pair),app.Ns,app.Nd);
                amp742 = reshape(total_Data2{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp782 = reshape(total_Data2{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp802 = reshape(total_Data2{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp832 = reshape(total_Data2{number,4}(1:number_of_pair),app.Ns,app.Nd);
                pha741 = reshape(total_Data1{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha781 = reshape(total_Data1{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha801 = reshape(total_Data1{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha831 = reshape(total_Data1{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
                pha742 = reshape(total_Data2{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha782 = reshape(total_Data2{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha802 = reshape(total_Data2{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha832 = reshape(total_Data2{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
            else
                amp741 = reshape(total_Data2{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp781 = reshape(total_Data2{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp801 = reshape(total_Data2{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp831 = reshape(total_Data2{number,4}(1:number_of_pair),app.Ns,app.Nd);
                amp742 = reshape(total_Data3{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp782 = reshape(total_Data3{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp802 = reshape(total_Data3{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp832 = reshape(total_Data3{number,4}(1:number_of_pair),app.Ns,app.Nd);
                pha741 = reshape(total_Data2{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha781 = reshape(total_Data2{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha801 = reshape(total_Data2{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha831 = reshape(total_Data2{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
                pha742 = reshape(total_Data3{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha782 = reshape(total_Data3{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha802 = reshape(total_Data3{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha832 = reshape(total_Data3{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
            end
            
            cali_amp741=log(app.sddist_matrix.^2.*amp741);
            cali_amp781=log(app.sddist_matrix.^2.*amp781);
            cali_amp801=log(app.sddist_matrix.^2.*amp801);
            cali_amp831=log(app.sddist_matrix.^2.*amp831);

            cali_amp742=log(app.sddist_matrix.^2.*amp742);
            cali_amp782=log(app.sddist_matrix.^2.*amp782);
            cali_amp802=log(app.sddist_matrix.^2.*amp802);
            cali_amp832=log(app.sddist_matrix.^2.*amp832);
            
            rawSD_idx = find(app.sddist_matrix<2.8);
            sddist_matrix = app.sddist_matrix;
            if ~isempty(rawSD_idx)
                sddist_matrix(rawSD_idx) = [];
            end
            switch app.WavelengthDropDown.Value
                case '740nm'
                    ampwavelength1 = cali_amp741;
                    ampwavelength2 = cali_amp742;
                    phawavelength1 = pha741;
                    phawavelength2 = pha742;
                case '780nm'
                    ampwavelength1 = cali_amp781;
                    ampwavelength2 = cali_amp782;
                    phawavelength1 = pha781;
                    phawavelength2 = pha782;
                case '808nm'
                    ampwavelength1 = cali_amp801;
                    ampwavelength2 = cali_amp802;
                    phawavelength1 = pha801;
                    phawavelength2 = pha802;
                case '830nm'
                    ampwavelength1 = cali_amp831;
                    ampwavelength2 = cali_amp832;
                    phawavelength1 = pha831;
                    phawavelength2 = pha832;
            end
            sddist_matrix = reshape(sddist_matrix,1,[]);

            ampInnerPos = app.Motionplotamp.InnerPosition;   
            ampwavelength1 = reshape(ampwavelength1,1,[]);
            ampwavelength2 = reshape(ampwavelength2,1,[]);
            w1 = plot(app.Motionplotamp,sddist_matrix,ampwavelength1,'r.');
            hold(app.Motionplotamp,'on')
%             xlim(app.Motionplotamp,[floor(min(sddist_matrix)) ceil(max(sddist_matrix))])
            w2 = plot(app.Motionplotamp,sddist_matrix,ampwavelength2,'bo');
            legend(app.Motionplotamp,[w1,w2],{'1st amp','2nd amp'});
            title(app.Motionplotamp,[app.PlotindexDropDown.Value, ', ', app.WavelengthDropDown.Value])
            hold(app.Motionplotamp,'off')
            ampinnerPosDiff = ampInnerPos - app.Motionplotamp.InnerPosition; 
            app.Motionplotamp.Position = app.Motionplotamp.Position + ampinnerPosDiff;
            
            phaInnerPos = app.Motionplotpha.InnerPosition;
            phawavelength1 = reshape(phawavelength1,1,[]);
            phawavelength2 = reshape(phawavelength2,1,[]);
            w1 = plot(app.Motionplotpha,sddist_matrix,phawavelength1,'r.');
            hold(app.Motionplotpha,'on')
%             xlim(app.Motionplotpha,[floor(min(sddist_matrix)) ceil(max(sddist_matrix))])
            w2 = plot(app.Motionplotpha,sddist_matrix,phawavelength2,'bo');
            legend(app.Motionplotpha,[w1,w2],{'1st amp','2nd amp'});
            title(app.Motionplotpha,[app.PlotindexDropDown.Value, ', ', app.WavelengthDropDown.Value])
            hold(app.Motionplotpha,'off')
            phainnerPosDiff = phaInnerPos - app.Motionplotpha.InnerPosition;
            app.Motionplotpha.Position = app.Motionplotpha.Position + phainnerPosDiff;
        end
        
        function plot_result(app,axes,x,y,axes_title,color) % plot calibrated reference and intralipid
            pos = get(axes,'InnerPosition');
            plot(axes,x,y,color);
            grid(axes,'on')
            xlim(axes,[floor(min(x)) ceil(max(x))])
            title(axes,axes_title)
            set(axes,'InnerPosition',pos);
            app.RemainingpointsTextArea.Value = num2str(length(x));
        end
        
        function [intra_outliers, ref_outliers, intra_not_wrapped, ref_not_wrapped, fitting_error] = find_outliers(app,event) % find posible outliers in ref and intra outlier removal
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            fitting_error = 0;
            if app.nm740Button.Value
                name = '740nm';
                intraamp = A_m74{end};
                intraphase = P_m74{end};
                refamp = amp74_log{end};
                refphase = phase74{end};
                sddist = app.sddist74{end};
            elseif app.nm780Button.Value
                name = '780nm';
                intraamp = A_m78{end};
                intraphase = P_m78{end};
                refamp = amp78_log{end};
                refphase = phase78{end};
                sddist = app.sddist78{end};
            elseif app.nm808Button.Value
                name = '808nm';
                intraamp = A_m80{end};
                intraphase = P_m80{end};
                refamp = amp80_log{end};
                refphase = phase80{end};
                sddist = app.sddist80{end};
            else
                name = '830nm';
                intraamp = A_m83{end};
                intraphase = P_m83{end};
                refamp = amp83_log{end};
                refphase = phase83{end};
                sddist = app.sddist83{end};
            end
            intraphase_copy = intraphase;
            refphase_copy = refphase;
            SD_idx = find(sddist > 4.5);
            refphase_copy(SD_idx) = 0;
            intraphase_copy(SD_idx) = 0;
            intraphase_copy = intraphase_copy(intraphase_copy~=0);
            refphase_copy = refphase_copy(refphase_copy~=0);
            sddist_copy = sddist(sddist <= 4.5);
            intra_short_c = polyfit(sddist_copy,intraphase_copy,1);
            ref_short_c = polyfit(sddist_copy,refphase_copy,1);
            intra_short = polyval(intra_short_c,sddist);
            ref_short = polyval(ref_short_c,sddist);
            
            intra_c = polyfit(sddist,intraphase,1);
            ref_c = polyfit(sddist,refphase,1);
            intra_y = polyval(intra_c,sddist);
            ref_y = polyval(ref_c,sddist);
            intra_fit_diff = abs(intra_short - intra_y);
            ref_fit_diff = abs(ref_short - ref_y);
            if max(intra_fit_diff) > 4 || max(ref_fit_diff) > 4
                fitting_error = 1;
            end
            intra_diffs = intraphase - intra_y;
            ref_diffs = refphase - ref_y;
            abs_intra_diffs = abs(intra_diffs);
            mean_intra_diff = mean(abs_intra_diffs);
            std_intra_diff = std(abs_intra_diffs);
            abs_ref_diffs = abs(ref_diffs);
            mean_ref_diff = mean(abs_ref_diffs);
            std_ref_diff = std(abs_ref_diffs);
            intra_outliers = find((abs_intra_diffs > mean_intra_diff + 4*std_intra_diff) & (abs_intra_diffs < 1.8 * Const.pi));
            ref_outliers = find((abs_ref_diffs > mean_ref_diff + 4*std_ref_diff) & (abs_ref_diffs < 1.8 * Const.pi));
            intra_not_wrapped = find(abs_intra_diffs > 1.8 * Const.pi);
            ref_not_wrapped = find(abs_ref_diffs > 1.8 * Const.pi);
        end
        
        function outlier_removal(app,outliers,selected_axis) % not used in current GUI
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;
%             refamp = reshape(amp74_log{end},app.Ns,app.Nd);
%             refphase = reshape(phase74{end},app.Ns,app.Nd);
%             refamp_input = zeros(1,app.Ns,app.Nd);
%             refamp_input(1,:,:) = refamp;
%             refphase_input = zeros(1,app.Ns,app.Nd);
%             refphase_input(1,:,:) = refphase;
%             [aba_r74,abp_r74] = outlierRemoval(refamp_input,refphase_input,app.Ns,app.Nd);
%             sddist = sddist74{end};
%             aba_r74 = reshape(aba_r74,1,[]);
%             abp_r74 = reshape(abp_r74,1,[]);
            
            if ~isempty(outliers)
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(outliers,...
                        sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                    [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                        sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                        corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    app.pairs74 = pairs74;
                    app.sddist74 = sddist74;
                    app.Measmnt74 = Measmnt74;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(outliers,...
                        sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                    [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                        sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                        corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    app.pairs78 = pairs78;
                    app.sddist78 = sddist78;
                    app.Measmnt78 = Measmnt78;

                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(outliers,...
                        sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                    [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                        sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                        corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    app.pairs80 = pairs80;
                    app.sddist80 = sddist80;
                    app.Measmnt80 = Measmnt80;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                else
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(outliers,...
                        sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                    [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                        sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                        corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    app.pairs83 = pairs83;
                    app.sddist83 = sddist83;
                    app.Measmnt83 = Measmnt83;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
        end
        
        function [nLesion, name, error] = data_check(app, name) % remove incomplete dataset
            [~, nLesion] = size(name);
            error = 0;
            nLesion = nLesion/3;
            if nLesion < 1
                errordlg('Files are not enough (less than 3).','ERROR!!');
                error = 1;
                return
            end
            [~, dataname] = fileparts(name);
            numbers_str = regexp(dataname,'\d*','Match');
            numbers = [];
            for i = 1:length(numbers_str)
                numbers = [numbers,str2double(numbers_str{1,i}{1})];
            end
            [B,BG] = groupcounts(numbers');
            data_ind = BG(find(B ~= 3));
            total_patient_index = [];
            for i = 1:length(data_ind)
                patient_index = find(numbers == data_ind(i));
                total_patient_index = [total_patient_index, patient_index];
            end
            name(total_patient_index) = []; 
            [~, nLesion] = size(name);
            nLesion = nLesion/3;
            if nLesion < 1
                errordlg('Files are not enough (less than 3).','ERROR!!');
                error = 1;
                return
            end
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Starting the program.'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            value = app.GeoDropDown.Value; % select geometry file
            switch value
                case 'WashU'
                    filename = 's9d14_new4_3_sysWU2.m';
                    fprintf('WashU')
                case 'UConn'
                    filename = 's9d14_new4_3_sysUCONN.m';
                    fprintf('Uconn')
                case 'FPGA'
                    filename = 's9d14_new4_system1_flipped.m';
                    fprintf('FPGA')
                case 'Sys1'
                    filename = 's9d14_new4_3_ZHU_MOD_sys1.m';
                    fprintf('Sys1')
                case 'Sys3'
                    filename = 's9d14_new4_system3.m';
                    fprintf('Sys3')
                case 'Sys2'
                    filename = 'System2_ver2_CrossPairsOnly.m';
                    fprintf('Sys2')
            end
            run(filename)
            app.Ns = size(s_geom,1);
            app.Nd = size(d_geom,1);
            [~, str] = fileparts(filename);
            if strcmp(str,'s9d14_new4_3_sysWU2')||strcmp(str,'s9d14_new4_3_sysWU3')
                app.mode = 1; 
            elseif strcmp(str,'s9d14_new4_3_sysUCONN')
                app.mode = 2;
            elseif strcmp(str,'s9d14_new4_system1_flipped')
                app.mode = 3;
            elseif strcmp(str,'s9d14_new4_3_ZHU_MOD_sys1')
                app.mode = 4;
            elseif strcmp(str,'s9d14_new4_system3')
                app.mode = 5;
            elseif strcmp(str,'System2_ver2_CrossPairsOnly')
                app.mode = 6;
            else
                app.mode = 0;
            end
            app.CurrentgeometryTextArea.Value = value;
            app.sddist_matrix = sddist_matrix;
            app.pairs = pairs;
            app.s_geom = s_geom;
            app.d_geom = d_geom;
            app.Measmnt = Measmnt;
            app.sddist = sddist;
            app.pairs74 = {pairs74};
            app.pairs78 = {pairs78};
            app.pairs80 = {pairs80};
            app.pairs83 = {pairs83};
            app.sddist74 = {sddist74};
            app.sddist78 = {sddist78};
            app.sddist80 = {sddist80};
            app.sddist83 = {sddist83};
            app.Measmnt74 = {Measmnt74};
            app.Measmnt78 = {Measmnt78};
            app.Measmnt80 = {Measmnt80};
            app.Measmnt83 = {Measmnt83};
            app.FilenameDropDown.Enable = false;
            app.WavelengthDropDown.Enable = false;
            app.PlotindexDropDown.Enable = false;
            app.IntralipidDropDown.Enable = false;
            app.Motioncheck.Enable = false;
            app.Mismatchcheck.Enable = false;
            app.Calibration.Enable = false;
            app.Smooth.Enable = false;
            app.AutoUnwrapButton.Enable = false;
            app.SelectpointsButton.Enable = false;
            app.RemovepointsButton.Enable = false;
            app.PhaseshiftButton.Enable = false;
            app.UndoLastButton.Enable = false;
            app.UndoAllButton.Enable = false;
            app.Unwrap.Enable = false;
            app.Delete.Enable = false;
            app.ClusterUnwrapButton.Enable = false;
            app.CreateimagesButton.Enable = false;
            app.AutopreprocessingButton.Enable = false;
            disableDefaultInteractivity(app.Intraamp)
            disableDefaultInteractivity(app.Intraphase)
            disableDefaultInteractivity(app.Refamp)
            disableDefaultInteractivity(app.Refphase)
            pe = pyenv;
            if isempty(pe.Version)
                errordlg('Python not installed.','ERROR!!');
                return
            end
        end

        % Button pushed function: LoadFolder
        function LoadFolderPushed(app, event)
            % load intralipid, reference and target txt file (maybe binary
            % later) by selecting folder
            app.tar2pertmode = 0;
            app.LogTextArea.Value = '';
            app.FilenameDropDown.Enable = false;
            app.WavelengthDropDown.Enable = false;
            app.PlotindexDropDown.Enable = false;
            app.IntralipidDropDown.Enable = false;
            app.Motioncheck.Enable = false;
            app.Mismatchcheck.Enable = false;
            app.Calibration.Enable = false;
            app.Smooth.Enable = false;
            app.AutoUnwrapButton.Enable = false;
            app.SelectpointsButton.Enable = false;
            app.RemovepointsButton.Enable = false;
            app.PhaseshiftButton.Enable = false;
            app.UndoLastButton.Enable = false;
            app.UndoAllButton.Enable = false;
            app.IntralipidDropDown.Enable = false;
            app.Unwrap.Enable = false;
            app.Delete.Enable = false;
            app.ClusterUnwrapButton.Enable = false;
            app.CreateimagesButton.Enable = false;
            app.AutopreprocessingButton.Enable = false;
            cla(app.Motionplotamp);
            cla(app.Motionplotpha);
            cla(app.Intraamp)
            cla(app.Intraphase)
            cla(app.Refamp)
            cla(app.Refphase)
            app.aveY = [];
            app.LogTextArea.Value = '';
            app.intramuaTextArea.Value = '';
            app.refmuaTextArea.Value = '';
            app.intramuspTextArea.Value = '';
            app.refmuspTextArea.Value = '';
            app.CurrentfolderTextArea.Value = '';
            app.ReferenceTextArea.Value = '';
            app.IntralipidTextArea.Value = '';
            if ~isempty(app.ref_isMotion) || ~isempty(app.tar_isMotion)
                app.ref_isMotion = [];
                app.tar_isMotion = [];
            end
            folder_path = uigetdir();
            app.folder_path = folder_path;
            if folder_path == 0
                errordlg('Please select points for phase shifting from Phase vs. Source-Detector axes only.','ERROR!!');
                return
            end
            [current_folder, ~] = fileparts(folder_path);
            app.CurrentfolderTextArea.Value = current_folder;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Loading the data, please wait..'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            txt_dir = dir(fullfile(folder_path,'*.txt'));
            txt_name = {txt_dir(:).name};
            % load image file names within same folder
            img_dir_p = dir(fullfile(folder_path,'*.png'));
            img_dir_j = dir(fullfile(folder_path,'*.jpg'));
            img_dir_b = dir(fullfile(folder_path,'*.bmp'));
            img_dir_t = dir(fullfile(folder_path,'*.tif'));
            [~,img_imdex] = max([length(img_dir_p),length(img_dir_j),length(img_dir_b),length(img_dir_t)]);
            if img_imdex == 1
                img_dir = img_dir_p;
            elseif img_imdex == 2
                img_dir = img_dir_j;
            elseif img_imdex == 3
                img_dir = img_dir_b;
            elseif img_imdex == 4
                img_dir = img_dir_t;
            end
            img_name = {img_dir(:).name};
            img_name = img_name(contains(img_name,'Target'));
            [app.img_dir,index] = natsort(img_name);
%             app.intra_name = txt_name(strncmp(txt_name,'Intralipid', 10));
%             app.ref_name = txt_name(strncmp(txt_name,'Reference', 9));
%             app.tar_name = txt_name(strncmp(txt_name,'Target', 6));
            % load intralipid, ref, and target file names
            app.intra_name = txt_name(contains(txt_name,'Intralipid'));
            app.ref_name = txt_name(contains(txt_name,'Reference'));
            app.tar_name = txt_name(contains(txt_name,'Target'));
            [app.intra_name,index] = natsort(app.intra_name);
            [app.ref_name,index] = natsort(app.ref_name);
            [app.tar_name,index] = natsort(app.tar_name);
            
            app.ref_name(:) = fullfile(folder_path, app.ref_name(:));
            app.tar_name(:) = fullfile(folder_path, app.tar_name(:));
            app.intra_name(:) = fullfile(folder_path, app.intra_name(:));
            if isempty(app.tar_name) || isempty(app.ref_name) || isempty(app.intra_name)
                errordlg('Cannot find the .txt data, please try again.','ERROR!!');
                return
            end
            % check data integrity 
            [app.tar_nLesion, app.tar_name, tar_error] = data_check(app, app.tar_name);
            [app.ref_nLesion, app.ref_name, ref_error] = data_check(app, app.ref_name);
            [app.intra_nLesion, app.intra_name, intra_error] = data_check(app, app.intra_name);
            if tar_error == 1 || ref_error == 1 || intra_error == 1
                return
            end
            [~, intra_file] = fileparts(app.intra_name);
            app.IntralipidDropDown.Items = intra_file;

            % load all data files
            [app.tar_alldata, app.ref_alldata, app.intra_alldata] = data_load(app.mode, app.tar_name, app.ref_name, app.intra_name, app.tar_nLesion, app.ref_nLesion, app.intra_nLesion, app.Ns, app.Nd, app.sddist_matrix);
            app.Motioncheck.Enable = true;
            app.AutopreprocessingButton.Enable = true;
%             app.Mismatchcheck.Enable = true;
%             app.Calibration.Enable = true;
%             app.CreateimagesButton.Enable = true;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Successfully loaded.'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            f = msgbox("Successfully loaded");
        end

        % Value changed function: GeoDropDown
        function GeoDropDownValueChanged(app, event)
            % switch geometry file
            value = app.GeoDropDown.Value;
            switch value
                case 'WashU'
                    filename = 's9d14_new4_3_sysWU2.m';
                    fprintf('WashU')
                case 'UConn'
                    filename = 's9d14_new4_3_sysUCONN.m';
                    fprintf('Uconn')
                case 'FPGA'
                    filename = 's9d14_new4_system1_flipped.m';
                    fprintf('FPGA')
                case 'Sys1'
                    filename = 's9d14_new4_3_ZHU_MOD_sys1.m';
                    fprintf('Sys1')
                case 'Sys3'
                    filename = 's9d14_new4_system3.m';
                    fprintf('Sys3')
                case 'Sys2'
                    filename = 'System2_ver2_CrossPairsOnly.m';
                    fprintf('Sys2')
            end
            run(filename)
            app.Ns = size(s_geom,1);
            app.Nd = size(d_geom,1);
            [~, str] = fileparts(filename);
            if strcmp(str,'s9d14_new4_3_sysWU2')||strcmp(str,'s9d14_new4_3_sysWU3')
                app.mode = 1; 
            elseif strcmp(str,'s9d14_new4_3_sysUCONN')
                app.mode = 2;
            elseif strcmp(str,'s9d14_new4_system1_flipped')
                app.mode = 3;
            elseif strcmp(str,'s9d14_new4_3_ZHU_MOD_sys1')
                app.mode = 4;
            elseif strcmp(str,'s9d14_new4_system3')
                app.mode = 5;
            elseif strcmp(str,'System2_ver2_CrossPairsOnly')
                app.mode = 6;
            else
                app.mode = 0;
            end
            app.CurrentgeometryTextArea.Value = value;
            app.sddist_matrix = sddist_matrix;
            app.pairs = pairs;
            app.s_geom = s_geom;
            app.d_geom = d_geom;
            app.Measmnt = Measmnt;
            app.sddist = sddist;
            app.pairs74 = {pairs74};
            app.pairs78 = {pairs78};
            app.pairs80 = {pairs80};
            app.pairs83 = {pairs83};
            app.sddist74 = {sddist74};
            app.sddist78 = {sddist78};
            app.sddist80 = {sddist80};
            app.sddist83 = {sddist83};
            app.Measmnt74 = {Measmnt74};
            app.Measmnt78 = {Measmnt78};
            app.Measmnt80 = {Measmnt80};
            app.Measmnt83 = {Measmnt83};
            app.FilenameDropDown.Enable = false;
            app.WavelengthDropDown.Enable = false;
            app.PlotindexDropDown.Enable = false;
            app.IntralipidDropDown.Enable = false;
            app.Motioncheck.Enable = false;
            app.Mismatchcheck.Enable = false;
            app.Calibration.Enable = false;
            app.Smooth.Enable = false;
            app.AutoUnwrapButton.Enable = false;
            app.SelectpointsButton.Enable = false;
            app.RemovepointsButton.Enable = false;
            app.PhaseshiftButton.Enable = false;
            app.UndoLastButton.Enable = false;
            app.UndoAllButton.Enable = false;
            app.Unwrap.Enable = false;
            app.Delete.Enable = false;
            app.ClusterUnwrapButton.Enable = false;
            app.CreateimagesButton.Enable = false;
            disableDefaultInteractivity(app.Intraamp)
            disableDefaultInteractivity(app.Intraphase)
            disableDefaultInteractivity(app.Refamp)
            disableDefaultInteractivity(app.Refphase)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Geometry changed, please reload the data if data was loaded.'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
        end

        % Button pushed function: Motioncheck
        function MotioncheckButtonPushed(app, event)
            m = findall(0,'type','figure','tag','Msgbox_ ');
            delete(m);
            app.LogTextArea.Value = [app.LogTextArea.Value;'Motion checking, please wait.. (motion_detect_noplot.m)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            app.meanTol = 0.15; % abs relative difference toleratance
            app.ptDiffTol = 0.025; % abs value difference (not relative)
            app.badptsTol = 7; % How many bad pts can have in one source-all detectors data
            if app.PlotmotionCheckBox.Value == 1
                [app.tar_isMotion, app.ref_isMotion] = motion_detect(app.tar_name, app.ref_name, app.tar_nLesion, app.ref_nLesion, app.meanTol, app.ptDiffTol, app.badptsTol, app.Ns, app.Nd, app.sddist_matrix, app.tar_alldata, app.ref_alldata);
            else
                [app.tar_isMotion, app.ref_isMotion] = motion_detect_noplot(app.tar_name, app.ref_name, app.tar_nLesion, app.ref_nLesion, app.meanTol, app.ptDiffTol, app.badptsTol, app.Ns, app.Nd, app.sddist_matrix, app.tar_alldata, app.ref_alldata);
            end
            total_motion = sum(app.tar_isMotion) + app.ref_isMotion;
            % move motion files to another folder
            if app.CreatemotionfolderCheckBox.Value == 1
                motion_folder = fullfile(app.folder_path,'motionMX');
                create_folder = 0;
                if ~exist(motion_folder, 'dir')
                    if mkdir(motion_folder)
                        create_folder = 1;
                    end
                end
                if create_folder == 1
                    tar_idx = [];
                    ref_idx = [];
                    for i = 1:length(app.tar_isMotion)/2
                        if app.tar_isMotion(2*i -1)==1 || app.tar_isMotion(2*i) ==1
                            move_file1 = app.tar_name{3*i-2};
                            move_file2 = app.tar_name{3*i-1};
                            move_file3 = app.tar_name{3*i};
                            movefile(move_file1,motion_folder)
                            movefile(move_file2,motion_folder)
                            movefile(move_file3,motion_folder)
                            app.tar_nLesion = app.tar_nLesion - 1;
                            tar_idx = [tar_idx i];
                        end
                    end
                    for i = 1:length(app.ref_isMotion)/2
                        if app.ref_isMotion(2*i -1)==1 || app.ref_isMotion(2*i) ==1
                            move_file1 = app.ref_name{3*i-2};
                            move_file2 = app.ref_name{3*i-1};
                            move_file3 = app.ref_name{3*i};
                            movefile(move_file1,motion_folder)
                            movefile(move_file2,motion_folder)
                            movefile(move_file3,motion_folder)
                            app.ref_nLesion = app.ref_nLesion - 1;
                            ref_idx = [ref_idx i];
                        end
                    end
                    if ~isempty(tar_idx)
                        true_tar_idx = [3*tar_idx,3*tar_idx-1,3*tar_idx-2];
                        app.tar_name(true_tar_idx) = [];
                        true_tar_idx = [2*tar_idx,2*tar_idx-1];
                        app.tar_isMotion(true_tar_idx) = [];
                        app.tar_alldata{1}(tar_idx,:)=[];
                        app.tar_alldata{2}(tar_idx,:)=[];
                        app.tar_alldata{3}(tar_idx,:)=[];
                    end
                    if ~isempty(ref_idx)
                        true_ref_idx = [3*ref_idx,3*ref_idx-1,3*ref_idx-2];
                        app.ref_name(true_ref_idx) = [];
                        true_ref_idx = [2*ref_idx,2*ref_idx-1];
                        app.ref_isMotion(true_ref_idx) = [];
                        app.ref_alldata{1}(ref_idx,:)=[];
                        app.ref_alldata{2}(ref_idx,:)=[];
                        app.ref_alldata{3}(ref_idx,:)=[];
                    end
                end
            end
            
            file_number = app.tar_nLesion + app.ref_nLesion;
            file_idx=[2:3:file_number*3];
            [~, tar_file] = fileparts(app.tar_name);
            [~, ref_file] = fileparts(app.ref_name);
            files=[tar_file,ref_file];
            app.FilenameDropDown.Items = files(file_idx);
            
            app.FilenameDropDown.Enable = true;
            app.WavelengthDropDown.Enable = true;
            app.PlotindexDropDown.Enable = true;
            app.Mismatchcheck.Enable = true;
%             app.Calibration.Enable = true;
%             app.CreateimagesButton.Enable = true;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Motion checking finished.'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            motionplot(app)
        end

        % Drop down opening function: FilenameDropDown
        function FilenameDropDownOpening(app, event)
           
        end

        % Value changed function: FilenameDropDown
        function FilenameDropDownValueChanged(app, event)
            motionplot(app)
            if ~isempty(app.aveY)
                CalibrationButtonPushed(app, event)
            end
        end

        % Value changed function: WavelengthDropDown
        function WavelengthDropDownValueChanged(app, event)
            motionplot(app)
        end

        % Value changed function: PlotindexDropDown
        function PlotindexDropDownValueChanged(app, event)
            motionplot(app)
        end

        % Button pushed function: Mismatchcheck
        function MismatchcheckButtonPushed(app, event)
%             last_tar = app.tar_name{end};
%             [~, intra_file] = fileparts(last_tar);
%             numbers = regexp(intra_file,'\d*','Match');
%             last_tar_idx = str2double(numbers{1});
            m = findall(0,'type','figure','tag','Msgbox_ ');
            delete(m);
            app.LogTextArea.Value = [app.LogTextArea.Value;'Mismatch checking, please wait..(mismatch_process.m)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            [~, refname] = fileparts(app.ref_name);
            ref_numbers_str = regexp(refname,'\d*','Match');
            ref_numbers = [];
            for i = 1:length(ref_numbers_str)
                ref_numbers = [ref_numbers,str2double(ref_numbers_str{1,i}{1})];
            end
            ref_numbers = unique(ref_numbers);
            if isempty(app.ref_isMotion) || isempty(app.tar_isMotion)
                app.tar_isMotion = zeros(app.tar_nLesion*2,1);
                app.ref_isMotion = zeros(app.ref_nLesion*2,1);
            end
            [pertub_Features,app.aveY]  = mismatch_process(app.ref_isMotion, app.tar_isMotion, app.tar_nLesion, app.ref_nLesion, app.tar_alldata, app.ref_alldata, app.s_geom, app.Ns, app.Nd, ref_numbers);
            [Y_sorted,I] = sort(app.aveY);
            app.Num_sort = ref_numbers(I);
%             file_number = app.tar_nLesion + app.ref_nLesion;
%             file_idx=[2:3:file_number*3];
%             [~, tar_file] = fileparts(app.tar_name);
%             [~, ref_file] = fileparts(app.ref_name);
%             files=[tar_file,ref_file];
            file_number = app.ref_nLesion;
            file_idx=[2:3:file_number*3];
            [~, ref_file] = fileparts(app.ref_name);
            files=[ref_file];
            app.FilenameDropDown.Items = files(file_idx);
            % switch to no ref method if minimum value > 0.4
            if min(app.aveY) > 0.4
                app.CreateimagesButton.Enable = true;
                app.tar2pertmode = 1;
            else
                app.FilenameDropDown.Enable = true;
                app.WavelengthDropDown.Enable = true;
                app.PlotindexDropDown.Enable = true;
                app.Calibration.Enable = true;
                app.IntralipidDropDown.Enable = true;
    %             app.CreateimagesButton.Enable = true;
                app.LogTextArea.Value = [app.LogTextArea.Value;'Mismatch finished.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.FilenameDropDown.Value = app.FilenameDropDown.Items(I(1));
                FilenameDropDownValueChanged(app, event)
    %             CalibrationButtonPushed(app, event)
                app.AutoUnwrapButton.Enable = true;
            end   
        end

        % Button pushed function: Calibration
        function CalibrationButtonPushed(app, event)
            
            % clear pairs, sddist and measurements at the begining
            app.pairs74 = app.pairs74(1);
            app.pairs78 = app.pairs78(1);
            app.pairs80 = app.pairs80(1);
            app.pairs83 = app.pairs83(1);
            app.sddist74 = app.sddist74(1);
            app.sddist78 = app.sddist78(1);
            app.sddist80 = app.sddist80(1);
            app.sddist83 = app.sddist83(1);
            app.Measmnt74 = app.Measmnt74(1);
            app.Measmnt78 = app.Measmnt78(1);
            app.Measmnt80 = app.Measmnt80(1);
            app.Measmnt83 = app.Measmnt83(1);
            
            % activate bottons
%             app.Smooth.Enable = true;
%             app.Unwrap.Enable = true;
%             app.Delete.Enable = true;
%             app.AutoUnwrapButton.Enable = true;
%             app.ClusterUnwrapButton.Enable = true;
%             app.SelectpointsButton.Enable = true;
%             app.RemovepointsButton.Enable = true;
%             app.PhaseshiftButton.Enable = true;
%             app.UndoLastButton.Enable = true;
%             app.UndoAllButton.Enable = true;
            app.FilenameDropDown.Enable = true;
            app.WavelengthDropDown.Enable = true;
            app.PlotindexDropDown.Enable = true;
            
            % load parameters
            [~, tar_file] = fileparts(app.tar_name);
            [~, ref_file] = fileparts(app.ref_name);
%             files=[tar_file, ref_file];
            files=[ref_file];
            ref_file_idx=[2:3:app.ref_nLesion*3];
            app.FilenameDropDown.Items = files(ref_file_idx);
            Refl = 0; % no use now
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            sddist = app.sddist;
            pairs74 = app.pairs74{end};
            pairs78 = app.pairs78{end};
            pairs80 = app.pairs80{end};
            pairs83 = app.pairs83{end};
            sddist74 = app.sddist74{end};
            sddist78 = app.sddist78{end};
            sddist80 = app.sddist80{end};
            sddist83 = app.sddist83{end};
            Measmnt74 = app.Measmnt74{end};
            Measmnt78 = app.Measmnt78{end};
            Measmnt80 = app.Measmnt80{end};
            Measmnt83 = app.Measmnt83{end};
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            
            % get reference and intralipid
            [tar_size, ~] = size(app.tar_alldata{1});
            [ref_size, ~] = size(app.ref_alldata{1});
            total_data = cell(ref_size*3,4);
            for i = 1:ref_size
                total_data(3*i-2,:) = app.ref_alldata{1}(i,:);
                total_data(3*i-1,:) = app.ref_alldata{2}(i,:);
                total_data(3*i,:) = app.ref_alldata{3}(i,:);
            end
%             total_data = cell(tar_size*3+ref_size*3,4);
%             for i = 1:tar_size
%                 total_data(3*i-2,:) = app.tar_alldata{1}(i,:);
%                 total_data(3*i-1,:) = app.tar_alldata{2}(i,:);
%                 total_data(3*i,:) = app.tar_alldata{3}(i,:);
%             end
%             for i = 1:ref_size
%                 total_data(3*tar_size+3*i-2,:) = app.ref_alldata{1}(i,:);
%                 total_data(3*tar_size+3*i-1,:) = app.ref_alldata{2}(i,:);
%                 total_data(3*tar_size+3*i,:) = app.ref_alldata{3}(i,:);
%             end
%             file_name = [app.tar_name , app.ref_name];
            file_name = [app.ref_name];
            ref_or_tar_value = app.FilenameDropDown.Value;
            name = strfind(file_name,ref_or_tar_value);
            file_idx = find(~cellfun(@isempty, name));
            app.ReferenceTextArea.Value = ref_or_tar_value;
            aba_r74 = reshape(total_data{file_idx,1}(1:Nmeas),source,detect);
            aba_r78 = reshape(total_data{file_idx,2}(1:Nmeas),source,detect);
            aba_r80 = reshape(total_data{file_idx,3}(1:Nmeas),source,detect);
            aba_r83 = reshape(total_data{file_idx,4}(1:Nmeas),source,detect);
            abp_r74 = reshape(total_data{file_idx,1}(Nmeas+1:end),source,detect);
            abp_r78 = reshape(total_data{file_idx,2}(Nmeas+1:end),source,detect);
            abp_r80 = reshape(total_data{file_idx,3}(Nmeas+1:end),source,detect);
            abp_r83 = reshape(total_data{file_idx,4}(Nmeas+1:end),source,detect);

            intra_file_idx=[2:3:app.intra_nLesion*3];
            random_select_intra = randsample(intra_file_idx,1);
            app.IntralipidDropDown.Value = app.IntralipidDropDown.Items(random_select_intra);
            intra_value = app.IntralipidDropDown.Value;
            intra_name = strfind(app.intra_name,intra_value);
            intra_idx = find(~cellfun(@isempty, intra_name));
            [intra_size, ~] = size(app.intra_alldata{1});
            intra_total_data = cell(intra_size*3,4);
            for i = 1:intra_size
                intra_total_data(3*i-2,:) = app.intra_alldata{1}(i,:);
                intra_total_data(3*i-1,:) = app.intra_alldata{2}(i,:);
                intra_total_data(3*i,:) = app.intra_alldata{3}(i,:);
            end
            app.IntralipidTextArea.Value = intra_value;
            bga74 = reshape(intra_total_data{intra_idx,1}(1:Nmeas),source,detect);
            bga78 = reshape(intra_total_data{intra_idx,2}(1:Nmeas),source,detect);
            bga80 = reshape(intra_total_data{intra_idx,3}(1:Nmeas),source,detect);
            bga83 = reshape(intra_total_data{intra_idx,4}(1:Nmeas),source,detect);
            bgp74 = reshape(intra_total_data{intra_idx,1}(Nmeas+1:end),source,detect);
            bgp78 = reshape(intra_total_data{intra_idx,2}(Nmeas+1:end),source,detect);
            bgp80 = reshape(intra_total_data{intra_idx,3}(Nmeas+1:end),source,detect);
            bgp83 = reshape(intra_total_data{intra_idx,4}(Nmeas+1:end),source,detect);
            
            %Run normal calibration
            calibration4wvl;
            
            %Run the local fit programs for each source wavelength
            local_fit74;
            local_fit78;
            local_fit80;
            local_fit83;
            
            %Plot results
            if app.nm740Button.Value
                name = '740nm';
                app.intramuaTextArea.Value = num2str(muabg74);
                app.refmuaTextArea.Value = num2str(muabg74a);
                app.intramuspTextArea.Value = num2str(muspbg74);
                app.refmuspTextArea.Value = num2str(muspbg74a);
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                app.intramuaTextArea.Value = num2str(muabg78);
                app.refmuaTextArea.Value = num2str(muabg78a);
                app.intramuspTextArea.Value = num2str(muspbg78);
                app.refmuspTextArea.Value = num2str(muspbg78a);
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                app.intramuaTextArea.Value = num2str(muabg80);
                app.refmuaTextArea.Value = num2str(muabg80a);
                app.intramuspTextArea.Value = num2str(muspbg80);
                app.refmuspTextArea.Value = num2str(muspbg80a);
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                app.intramuaTextArea.Value = num2str(muabg83);
                app.refmuaTextArea.Value = num2str(muabg83a);
                app.intramuspTextArea.Value = num2str(muspbg83);
                app.refmuspTextArea.Value = num2str(muspbg83a);
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
            end
            app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             {corr_ki74},{corr_kr74},{corr_ki78},{corr_kr78};
                             {corr_ki80},{corr_kr80},{corr_ki83},{corr_kr83}};

            plot_result(app,app.Intraamp,sddist{1},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Intraphase,sddist{1},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Refamp,sddist{1},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
            plot_result(app,app.Refphase,sddist{1},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            app.reference = ref_or_tar_value;
            
            app.CreateimagesButton.Enable = true;
        end

        % Selection changed function: SourceWavelengthsButtonGroup
        function SourceWavelengthsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceWavelengthsButtonGroup.SelectedObject;
            sddist_matrix = app.sddist_matrix;
            sddist = app.sddist;
            if isempty(app.cali_data)
                errordlg('Please load the data and do the calibration first.','ERROR!!');
                return
            end
            if app.nm740Button.Value
                name = '740nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{1,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{1,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{1,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{1,4}{end});
                intraamp = app.cali_data{5,1}{end};
                intraphase = app.cali_data{5,2}{end};
                refamp = app.cali_data{5,3}{end};
                refphase = app.cali_data{5,4}{end};
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{2,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{2,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{2,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{2,4}{end});
                intraamp = app.cali_data{6,1}{end};
                intraphase = app.cali_data{6,2}{end};
                refamp = app.cali_data{6,3}{end};
                refphase = app.cali_data{6,4}{end};
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{3,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{3,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{3,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{3,4}{end});
                intraamp = app.cali_data{7,1}{end};
                intraphase = app.cali_data{7,2}{end};
                refamp = app.cali_data{7,3}{end};
                refphase = app.cali_data{7,4}{end};
                sddist = app.sddist80;
            else
                name = '830nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{4,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{4,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{4,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{4,4}{end});
                intraamp = app.cali_data{8,1}{end};
                intraphase = app.cali_data{8,2}{end};
                refamp = app.cali_data{8,3}{end};
                refphase = app.cali_data{8,4}{end};
                sddist = app.sddist83;
            end
            
            plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
            plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            app.RemainingpointsTextArea.Value = num2str(length(sddist{end}));
        end

        % Button pushed function: Smooth
        function SmoothButtonPushed(app, event)
            sddist_matrix = app.sddist_matrix;
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;
            
             if app.nm740Button.Value
                name = '740nm';
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
             end
            if isempty(app.answer)
                answer = questdlg('Which phase you want to unwrap?', ...
                	'Selection', ...
                	'Intralipid','Reference','Intralipid');
            else
                answer = app.answer;
            end
            % Handle response
            if ~isempty(answer)
                switch answer
                    case 'Intralipid'
                        selected_axis = app.Intraphase;
                        phase_copy = intraphase{end};
                        phase = intraphase{end};
                    case 'Reference'
                        selected_axis = app.Refphase;
                        phase_copy = refphase{end};
                        phase = refphase{end};
                end
            else
                errordlg('Please select the phase.','ERROR!!');
                return
            end
            
            [smo_phase,topoutlier,botoutlier] = smooth_array(phase_copy,sddist{end});
            % remove outliers, lower outliers first, higher second
            if ~isempty(botoutlier)
                p1 = 1; p2 = 0;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                    Measmnt78(end+1) = Measmnt78(end);
                    sddist78(end+1) = sddist78(end);
                    
                    [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                        muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                        Refl);
                    
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                    Measmnt80(end+1) = Measmnt80(end);
                    sddist80(end+1) = sddist80(end);
                    
                    [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                        muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                        Refl);
                    
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                    Measmnt83(end+1) = Measmnt83(end);
                    sddist83(end+1) = sddist83(end);
                    
                    [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                        muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                        Refl);
                    
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            
            if ~isempty(topoutlier)
                p1 = 0; p2 = 1;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                    Measmnt78(end+1) = Measmnt78(end);
                    sddist78(end+1) = sddist78(end);
                    
                    [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                        muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                        Refl);
                    
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                    Measmnt80(end+1) = Measmnt80(end);
                    sddist80(end+1) = sddist80(end);
                    
                    [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                        muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                        Refl);
                    
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                    Measmnt83(end+1) = Measmnt83(end);
                    sddist83(end+1) = sddist83(end);
                    
                    [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                        muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                        Refl);
                    
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            app.answer = [];
        end

        % Button pushed function: SelectpointsButton
        function SelectpointsButtonPushed(app, event)
            %Delete box outlining selected points if there is already one there
            lines = findobj(gcbf,'Tag','L');
            delete(lines);
            
            app.UIFigure.HandleVisibility = 'on';
            
            %User selects region from which points should be removed
            k=waitforbuttonpress;
            bound = rbbox;
            
            selected_axis = gca; %Determine which data set is being edited (phase or amplitude for intralipid or ref)
            
            %All objects need to be measured in the same units
            set(selected_axis,'Units','pixels');
            
            P = get(selected_axis, 'InnerPosition'); %Of axes, within the figure window
            
            %Get limits of plot
            xlimit = get(selected_axis, 'Xlim');
            ylimit = get(selected_axis, 'Ylim');
            
            % Get the coordinates of the lower left corner of the box,
            % its height and width, and the X-Y coordinates of the 4 corners
            deltax = xlimit(2)-xlimit(1);
            deltay = ylimit(2)-ylimit(1);
            left_dist = bound(1)-P(1); %Distance of rbbox from left boundary of axes
            up_dist = bound(2)-P(2);%Distance from lower boundary of axes
            
            %Convert dimensions of rbbox to x and y coordinates
            xlow = xlimit(1)+deltax*left_dist/P(3);
            xhigh = xlimit(1)+deltax*(left_dist+bound(3))/P(3);
            ylow = ylimit(1)+deltay*up_dist/P(4);
            yhigh = ylimit(1)+deltay*(up_dist+bound(4))/P(4);
            
            %X-Y information about the corners of the RBBOX
            xbounds = [xlow, xhigh];
            ybounds = [ylow, yhigh];
            
            %Create lines outlining the selected area
            r1 = rectangle('Position',[xlow,ylow,xhigh-xlow,yhigh-ylow]);
            set(r1,'Tag','L');
            
%             app.UIFigure.HandleVisibility = 'off';
            app.selected_axis = selected_axis;
            app.xbounds = xbounds;
            app.ybounds = ybounds;
        end

        % Button pushed function: RemovepointsButton
        function RemovepointsButtonPushed(app, event)
            selected_axis = app.selected_axis;
            xbounds = app.xbounds;
            ybounds = app.ybounds;
            
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;
            % remove outliers for selected wavelength
            if app.nm740Button.Value
                name = '740nm';
                switch selected_axis
                    case app.Intraamp
                        axisChoice = 'cali_axes_amp';
                        Badpoints = find(A_m74{end}>min(ybounds) & A_m74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    case app.Intraphase
                        axisChoice = 'cali_axes_phase';
                        Badpoints = find(P_m74{end}>min(ybounds) & P_m74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    case app.Refamp
                        axisChoice = 'fit_axes_amp';
                        Badpoints = find(amp74_log{end}>min(ybounds) & amp74_log{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    case app.Refphase
                        axisChoice = 'fit_axes_phase';
                        Badpoints = find(phase74{end}>min(ybounds) & phase74{end}<max(ybounds) & sddist74{end}>min(xbounds) &sddist74{end}>min(xbounds));
                end
                [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(Badpoints,...
                    sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                
                [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                    sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                    corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                intraamp = A_m74{end};
                intraphase = P_m74{end};
                refamp = amp74_log{end};
                refphase = phase74{end};
                sddist = sddist74;
                app.intramuaTextArea.Value = num2str(muabg74{end});
                app.refmuaTextArea.Value = num2str(muabg74a{end});
                app.intramuspTextArea.Value = num2str(muspbg74{end});
                app.refmuspTextArea.Value = num2str(muspbg74a{end});
                app.pairs74 = pairs74;
                app.sddist74 = sddist74;
                app.Measmnt74 = Measmnt74;
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                lines = findobj(gcbf,'Tag','L');
                delete(lines);
            elseif app.nm780Button.Value
                name = '780nm';
                switch selected_axis
                    case app.Intraamp
                        axisChoice = 'cali_axes_amp';
                        Badpoints = find(A_m78{end}>min(ybounds) & A_m78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                    case app.Intraphase
                        axisChoice = 'cali_axes_phase';
                        Badpoints = find(P_m78{end}>min(ybounds) & P_m78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                    case app.Refamp
                        axisChoice = 'fit_axes_amp';
                        Badpoints = find(amp78_log{end}>min(ybounds) & amp78_log{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                    case app.Refphase
                        axisChoice = 'fit_axes_phase';
                        Badpoints = find(phase78{end}>min(ybounds) & phase78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                end
                [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(Badpoints,...
                    sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                
                [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                    sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                    corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                intraamp = A_m78{end};
                intraphase = P_m78{end};
                refamp = amp78_log{end};
                refphase = phase78{end};
                sddist = sddist78;
                app.intramuaTextArea.Value = num2str(muabg78{end});
                app.refmuaTextArea.Value = num2str(muabg78a{end});
                app.intramuspTextArea.Value = num2str(muspbg78{end});
                app.refmuspTextArea.Value = num2str(muspbg78a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                lines = findobj(gcbf,'Tag','L');
                delete(lines);
            elseif app.nm808Button.Value
                name = '808nm';
                switch selected_axis
                    case app.Intraamp
                        axisChoice = 'cali_axes_amp';
                        Badpoints = find(A_m80{end}>min(ybounds) & A_m80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                    case app.Intraphase
                        axisChoice = 'cali_axes_phase';
                        Badpoints = find(P_m80{end}>min(ybounds) & P_m80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                    case app.Refamp
                        axisChoice = 'fit_axes_amp';
                        Badpoints = find(amp80_log{end}>min(ybounds) & amp80_log{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                    case app.Refphase
                        axisChoice = 'fit_axes_phase';
                        Badpoints = find(phase80{end}>min(ybounds) & phase80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                end
                [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(Badpoints,...
                    sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                
                [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                    sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                    corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                intraamp = A_m80{end};
                intraphase = P_m80{end};
                refamp = amp80_log{end};
                refphase = phase80{end};
                sddist = sddist80;
                app.intramuaTextArea.Value = num2str(muabg80{end});
                app.refmuaTextArea.Value = num2str(muabg80a{end});
                app.intramuspTextArea.Value = num2str(muspbg80{end});
                app.refmuspTextArea.Value = num2str(muspbg80a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                lines = findobj(gcbf,'Tag','L');
                delete(lines);
            else
                name = '830nm';
                switch selected_axis
                    case app.Intraamp
                        axisChoice = 'cali_axes_amp';
                        Badpoints = find(A_m83{end}>min(ybounds) & A_m83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                    case app.Intraphase
                        axisChoice = 'cali_axes_phase';
                        Badpoints = find(P_m83{end}>min(ybounds) & P_m83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                    case app.Refamp
                        axisChoice = 'fit_axes_amp';
                        Badpoints = find(amp83_log{end}>min(ybounds) & amp83_log{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                    case app.Refphase
                        axisChoice = 'fit_axes_phase';
                        Badpoints = find(phase83{end}>min(ybounds) & phase83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                end
                [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(Badpoints,...
                    sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                
                [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                    sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                    corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                intraamp = A_m83{end};
                intraphase = P_m83{end};
                refamp = amp83_log{end};
                refphase = phase83{end};
                sddist = sddist83;
                app.intramuaTextArea.Value = num2str(muabg83{end});
                app.refmuaTextArea.Value = num2str(muabg83a{end});
                app.intramuspTextArea.Value = num2str(muspbg83{end});
                app.refmuspTextArea.Value = num2str(muspbg83a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                lines = findobj(gcbf,'Tag','L');
                delete(lines);
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
%             app.UIFigure.HandleVisibility = 'off';
            app.RemainingpointsTextArea.Value = num2str(length(sddist{end}));
        end

        % Button pushed function: PhaseshiftButton
        function PhaseshiftButtonPushed(app, event)
            selected_axis = app.selected_axis;
            xbounds = app.xbounds;
            ybounds = app.ybounds;
            
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;
            p1 = app.plus2pi.Value;
            p2 = app.minus2pi.Value;
            % adjust phase for selected wavelength
            if (selected_axis == app.Intraamp | selected_axis == app.Refamp) %If user has not selected points from a phase axis
                errordlg('Please select points for phase shifting from Phase vs. Source-Detector axes only.','ERROR!!');
            else
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                            ShiftPoints = find(P_m74{end}>min(ybounds) & P_m74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                            ShiftPoints = find(phase74{end}>min(ybounds) & phase74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,ShiftPoints,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['RefPhase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                elseif app.nm780Button.Value
                        name = '780nm';
                        switch selected_axis
                            case app.Intraphase
                                phase_axis = 'cali_axes_phase';
                                ShiftPoints = find(P_m78{end}>min(ybounds) & P_m78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                            case app.Refphase
                                phase_axis = 'fit_axes_phase';
                                ShiftPoints = find(phase78{end}>min(ybounds) & phase78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                        end
                        A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                        Measmnt78(end+1) = Measmnt78(end);
                        sddist78(end+1) = sddist78(end);
                        
                        [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                            muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,ShiftPoints,...
                            p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                            Refl);
                        
                        intraamp = A_m78{end};
                        intraphase = P_m78{end};
                        refamp = amp78_log{end};
                        refphase = phase78{end};
                        sddist = sddist78;
                        app.intramuaTextArea.Value = num2str(muabg78{end});
                        app.refmuaTextArea.Value = num2str(muabg78a{end});
                        app.intramuspTextArea.Value = num2str(muspbg78{end});
                        app.refmuspTextArea.Value = num2str(muspbg78a{end});
                        
                        
                        plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                        plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                        
                        lines = findobj(gcbf,'Tag','L');
                        delete(lines);
                elseif app.nm808Button.Value
                        name = '808nm';
                        switch selected_axis
                            case app.Intraphase
                                phase_axis = 'cali_axes_phase';
                                ShiftPoints = find(P_m80{end}>min(ybounds) & P_m80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                            case app.Refphase
                                phase_axis = 'fit_axes_phase';
                                ShiftPoints = find(phase80{end}>min(ybounds) & phase80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                        end
                        A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                        Measmnt80(end+1) = Measmnt80(end);
                        sddist80(end+1) = sddist80(end);
                        
                        [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                            muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,ShiftPoints,...
                            p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                            Refl);
                        
                        intraamp = A_m80{end};
                        intraphase = P_m80{end};
                        refamp = amp80_log{end};
                        refphase = phase80{end};
                        sddist = sddist80;
                        app.intramuaTextArea.Value = num2str(muabg80{end});
                        app.refmuaTextArea.Value = num2str(muabg80a{end});
                        app.intramuspTextArea.Value = num2str(muspbg80{end});
                        app.refmuspTextArea.Value = num2str(muspbg80a{end});
                        
                        
                        plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                        plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                        
                        lines = findobj(gcbf,'Tag','L');
                        delete(lines);
                elseif app.nm830Button.Value
                        name = '830nm';
                        switch selected_axis
                            case app.Intraphase
                                phase_axis = 'cali_axes_phase';
                                ShiftPoints = find(P_m83{end}>min(ybounds) & P_m83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                            case app.Refphase
                                phase_axis = 'fit_axes_phase';
                                ShiftPoints = find(phase83{end}>min(ybounds) & phase83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                        end
                        A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                        Measmnt83(end+1) = Measmnt83(end);
                        sddist83(end+1) = sddist83(end);
                        
                        [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                            muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,ShiftPoints,...
                            p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                            s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                            Refl);
                        
                        intraamp = A_m83{end};
                        intraphase = P_m83{end};
                        refamp = amp83_log{end};
                        refphase = phase83{end};
                        sddist = sddist83;
                        app.intramuaTextArea.Value = num2str(muabg83{end});
                        app.refmuaTextArea.Value = num2str(muabg83a{end});
                        app.intramuspTextArea.Value = num2str(muspbg83{end});
                        app.refmuspTextArea.Value = num2str(muspbg83a{end});
                        
                        
                        plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                        plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                        plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                        
                        lines = findobj(gcbf,'Tag','L');
                        delete(lines);
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
        end

        % Button pushed function: UndoAllButton
        function UndoAllButtonPushed(app, event)

            % undo all modifications for current wavelength
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            
            if app.nm740Button.Value
                name = '740nm';
                sddist74 = sddist74(1);
                A_m74 = A_m74(1);
                P_m74 = P_m74(1);
                pairs74 = pairs74(1);
                Measmnt74 = Measmnt74(1);
                muabg74 = muabg74(1);
                muspbg74 = muspbg74(1);
                muabg74a = muabg74a(1);
                muspbg74a = muspbg74a(1);
                phase74 = phase74(1);
                amp74_log = amp74_log(1);
                corr_ki74 = corr_ki74(1);
                corr_kr74 = corr_kr74(1);
                
                intraamp = A_m74{end};
                intraphase = P_m74{end};
                refamp = amp74_log{end};
                refphase = phase74{end};
                sddist = sddist74;
                app.intramuaTextArea.Value = num2str(muabg74{end});
                app.refmuaTextArea.Value = num2str(muabg74a{end});
                app.intramuspTextArea.Value = num2str(muspbg74{end});
                app.refmuspTextArea.Value = num2str(muspbg74a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm780Button.Value
                name = '780nm';
                sddist78 = sddist78(1);
                A_m78 = A_m78(1);
                P_m78 = P_m78(1);
                pairs78 = pairs78(1);
                Measmnt78 = Measmnt78(1);
                muabg78 = muabg78(1);
                muspbg78 = muspbg78(1);
                muabg78a = muabg78a(1);
                muspbg78a = muspbg78a(1);
                phase78 = phase78(1);
                amp78_log = amp78_log(1);
                corr_ki78 = corr_ki78(1);
                corr_kr78 = corr_kr78(1);
                
                intraamp = A_m78{end};
                intraphase = P_m78{end};
                refamp = amp78_log{end};
                refphase = phase78{end};
                sddist = sddist78;
                app.intramuaTextArea.Value = num2str(muabg78{end});
                app.refmuaTextArea.Value = num2str(muabg78a{end});
                app.intramuspTextArea.Value = num2str(muspbg78{end});
                app.refmuspTextArea.Value = num2str(muspbg78a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm808Button.Value
                name = '808nm';
                sddist80 = sddist80(1);
                A_m80 = A_m80(1);
                P_m80 = P_m80(1);
                pairs80 = pairs80(1);
                Measmnt80 = Measmnt80(1);
                muabg80 = muabg80(1);
                muspbg80 = muspbg80(1);
                muabg80a = muabg80a(1);
                muspbg80a = muspbg80a(1);
                phase80 = phase80(1);
                amp80_log = amp80_log(1);
                corr_ki80 = corr_ki80(1);
                corr_kr80 = corr_kr80(1);
                
                intraamp = A_m80{end};
                intraphase = P_m80{end};
                refamp = amp80_log{end};
                refphase = phase80{end};
                sddist = sddist80;
                app.intramuaTextArea.Value = num2str(muabg80{end});
                app.refmuaTextArea.Value = num2str(muabg80a{end});
                app.intramuspTextArea.Value = num2str(muspbg80{end});
                app.refmuspTextArea.Value = num2str(muspbg80a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm830Button.Value
                name = '830nm';
                sddist83 = sddist83(1);
                A_m83 = A_m83(1);
                P_m83 = P_m83(1);
                pairs83 = pairs83(1);
                Measmnt83 = Measmnt83(1);
                muabg83 = muabg83(1);
                muspbg83 = muspbg83(1);
                muabg83a = muabg83a(1);
                muspbg83a = muspbg83a(1);
                phase83 = phase83(1);
                amp83_log = amp83_log(1);
                corr_ki83 = corr_ki83(1);
                corr_kr83 = corr_kr83(1);
                
                intraamp = A_m83{end};
                intraphase = P_m83{end};
                refamp = amp83_log{end};
                refphase = phase83{end};
                sddist = sddist83;
                app.intramuaTextArea.Value = num2str(muabg83{end});
                app.refmuaTextArea.Value = num2str(muabg83a{end});
                app.intramuspTextArea.Value = num2str(muspbg83{end});
                app.refmuspTextArea.Value = num2str(muspbg83a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
        end

        % Button pushed function: UndoLastButton
        function UndoLastButtonPushed(app, event)
            % undo last process for current wavelength
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            
            if app.nm740Button.Value
                name = '740nm';
                if length(sddist74) == 1
                    return
                end
                sddist74 = sddist74(1,1:end-1);
                A_m74 = A_m74(1,1:end-1);
                P_m74 = P_m74(1,1:end-1);
                pairs74 = pairs74(1,1:end-1);
                Measmnt74 = Measmnt74(1,1:end-1);
                muabg74 = muabg74(1,1:end-1);
                muspbg74 = muspbg74(1,1:end-1);
                muabg74a = muabg74a(1,1:end-1);
                muspbg74a = muspbg74a(1,1:end-1);
                phase74 = phase74(1,1:end-1);
                amp74_log = amp74_log(1,1:end-1);
                corr_ki74 = corr_ki74(1,1:end-1);
                corr_kr74 = corr_kr74(1,1:end-1);
                
                intraamp = A_m74{end};
                intraphase = P_m74{end};
                refamp = amp74_log{end};
                refphase = phase74{end};
                sddist = sddist74;
                app.intramuaTextArea.Value = num2str(muabg74{end});
                app.refmuaTextArea.Value = num2str(muabg74a{end});
                app.intramuspTextArea.Value = num2str(muspbg74{end});
                app.refmuspTextArea.Value = num2str(muspbg74a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm780Button.Value
                name = '780nm';
                if length(sddist78) == 1
                    return
                end
                sddist78 = sddist78(1,1:end-1);
                A_m78 = A_m78(1,1:end-1);
                P_m78 = P_m78(1,1:end-1);
                pairs78 = pairs78(1,1:end-1);
                Measmnt78 = Measmnt78(1,1:end-1);
                muabg78 = muabg78(1,1:end-1);
                muspbg78 = muspbg78(1,1:end-1);
                muabg78a = muabg78a(1,1:end-1);
                muspbg78a = muspbg78a(1,1:end-1);
                phase78 = phase78(1,1:end-1);
                amp78_log = amp78_log(1,1:end-1);
                corr_ki78 = corr_ki78(1,1:end-1);
                corr_kr78 = corr_kr78(1,1:end-1);
                
                intraamp = A_m78{end};
                intraphase = P_m78{end};
                refamp = amp78_log{end};
                refphase = phase78{end};
                sddist = sddist78;
                app.intramuaTextArea.Value = num2str(muabg78{end});
                app.refmuaTextArea.Value = num2str(muabg78a{end});
                app.intramuspTextArea.Value = num2str(muspbg78{end});
                app.refmuspTextArea.Value = num2str(muspbg78a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm808Button.Value
                name = '808nm';
                if length(sddist80) == 1
                    return
                end
                sddist80 = sddist80(1,1:end-1);
                A_m80 = A_m80(1,1:end-1);
                P_m80 = P_m80(1,1:end-1);
                pairs80 = pairs80(1,1:end-1);
                Measmnt80 = Measmnt80(1,1:end-1);
                muabg80 = muabg80(1,1:end-1);
                muspbg80 = muspbg80(1,1:end-1);
                muabg80a = muabg80a(1,1:end-1);
                muspbg80a = muspbg80a(1,1:end-1);
                phase80 = phase80(1,1:end-1);
                amp80_log = amp80_log(1,1:end-1);
                corr_ki80 = corr_ki80(1,1:end-1);
                corr_kr80 = corr_kr80(1,1:end-1);
                
                intraamp = A_m80{end};
                intraphase = P_m80{end};
                refamp = amp80_log{end};
                refphase = phase80{end};
                sddist = sddist80;
                app.intramuaTextArea.Value = num2str(muabg80{end});
                app.refmuaTextArea.Value = num2str(muabg80a{end});
                app.intramuspTextArea.Value = num2str(muspbg80{end});
                app.refmuspTextArea.Value = num2str(muspbg80a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            elseif app.nm830Button.Value
                name = '830nm';
                if length(sddist83) == 1
                    return
                end
                sddist83 = sddist83(1,1:end-1);
                A_m83 = A_m83(1,1:end-1);
                P_m83 = P_m83(1,1:end-1);
                pairs83 = pairs83(1,1:end-1);
                Measmnt83 = Measmnt83(1,1:end-1);
                muabg83 = muabg83(1,1:end-1);
                muspbg83 = muspbg83(1,1:end-1);
                muabg83a = muabg83a(1,1:end-1);
                muspbg83a = muspbg83a(1,1:end-1);
                phase83 = phase83(1,1:end-1);
                amp83_log = amp83_log(1,1:end-1);
                corr_ki83 = corr_ki83(1,1:end-1);
                corr_kr83 = corr_kr83(1,1:end-1);
                
                intraamp = A_m83{end};
                intraphase = P_m83{end};
                refamp = amp83_log{end};
                refphase = phase83{end};
                sddist = sddist83;
                app.intramuaTextArea.Value = num2str(muabg83{end});
                app.refmuaTextArea.Value = num2str(muabg83a{end});
                app.intramuspTextArea.Value = num2str(muspbg83{end});
                app.refmuspTextArea.Value = num2str(muspbg83a{end});
                
                
                plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
        end

        % Button pushed function: CreateimagesButton
        function CreateimagesButtonPushed(app, event)
            % if there's no calibrated data, use default ref and intralipid to generate calibrated data 
            if ~isempty(app.cali_data)
               
                
                [app.mua074,app.mua078,app.mua080,app.mua083] = app.cali_data{1:4,2};
                [app.mus074,app.mus078,app.mus080,app.mus083] = app.cali_data{1:4,4};
                if iscell(app.mua074)
                    app.mua074 = app.mua074{end};
                    app.mus074 = app.mus074{end};
                    app.mua078 = app.mua078{end};
                    app.mus078 = app.mus078{end};
                    app.mua080 = app.mua080{end};
                    app.mus080 = app.mus080{end};
                    app.mua083 = app.mua083{end};
                    app.mus083 = app.mus083{end};
                end
            else
                msgbox("You are moving to imaging GUI without processing intralipid data, it may cause some problem in reconstruction.");
                Refl = 0; 
                source = app.Ns;
                detect = app.Nd;
                sddist_matrix = app.sddist_matrix;
                pairs = app.pairs;
                Measmnt = app.Measmnt;
                Nmeas = source*detect;
                sddist = app.sddist;
                s_geom = app.s_geom;
                d_geom = app.d_geom;
                pairs74 = app.pairs74{end}; % {end} only used when following calibration4wvl.m
                pairs78 = app.pairs78{end};
                pairs80 = app.pairs80{end};
                pairs83 = app.pairs83{end};
                sddist74 = app.sddist74{end};
                sddist78 = app.sddist78{end};
                sddist80 = app.sddist80{end};
                sddist83 = app.sddist83{end};
                Measmnt74 = app.Measmnt74{end};
                Measmnt78 = app.Measmnt78{end};
                Measmnt80 = app.Measmnt80{end};
                Measmnt83 = app.Measmnt83{end};
                [ref_size, ~] = size(app.ref_alldata{1});
                total_data = cell(ref_size*3,4);
                for i = 1:ref_size
                    total_data(3*i-2,:) = app.ref_alldata{1}(i,:);
                    total_data(3*i-1,:) = app.ref_alldata{2}(i,:);
                    total_data(3*i,:) = app.ref_alldata{3}(i,:);
                end
                
                aba_r74 = reshape(total_data{1,1}(1:Nmeas),source,detect);
                aba_r78 = reshape(total_data{1,2}(1:Nmeas),source,detect);
                aba_r80 = reshape(total_data{1,3}(1:Nmeas),source,detect);
                aba_r83 = reshape(total_data{1,4}(1:Nmeas),source,detect);
                abp_r74 = reshape(total_data{1,1}(Nmeas+1:end),source,detect);
                abp_r78 = reshape(total_data{1,2}(Nmeas+1:end),source,detect);
                abp_r80 = reshape(total_data{1,3}(Nmeas+1:end),source,detect);
                abp_r83 = reshape(total_data{1,4}(Nmeas+1:end),source,detect);
                
                [intra_size, ~] = size(app.intra_alldata{1});
                intra_total_data = cell(intra_size*3,4);
                for i = 1:intra_size
                    intra_total_data(3*i-2,:) = app.intra_alldata{1}(i,:);
                    intra_total_data(3*i-1,:) = app.intra_alldata{2}(i,:);
                    intra_total_data(3*i,:) = app.intra_alldata{3}(i,:);
                end
                bga74 = reshape(intra_total_data{1,1}(1:Nmeas),source,detect);
                bga78 = reshape(intra_total_data{1,2}(1:Nmeas),source,detect);
                bga80 = reshape(intra_total_data{1,3}(1:Nmeas),source,detect);
                bga83 = reshape(intra_total_data{1,4}(1:Nmeas),source,detect);
                bgp74 = reshape(intra_total_data{1,1}(Nmeas+1:end),source,detect);
                bgp78 = reshape(intra_total_data{1,2}(Nmeas+1:end),source,detect);
                bgp80 = reshape(intra_total_data{1,3}(Nmeas+1:end),source,detect);
                bgp83 = reshape(intra_total_data{1,4}(Nmeas+1:end),source,detect);
                
                %Run normal calibration
                calibration4wvl;
                
                %Run the local fit programs for each source wavelength
                local_fit74;
                local_fit78;
                local_fit80;
                local_fit83;
                
                app.mua074 = muabg74a;
                app.mus074 = muspbg74a;
                app.mua078 = muabg78a;
                app.mus078 = muspbg78a;
                app.mua080 = muabg80a;
                app.mus080 = muspbg80a;
                app.mua083 = muabg83a;
                app.mus083 = muspbg83a; 
                
                app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             {corr_ki74},{corr_kr74},{corr_ki78},{corr_kr78};
                             {corr_ki80},{corr_kr80},{corr_ki83},{corr_kr83}};
                [~, ref_file] = fileparts(app.ref_name);
                app.reference = ref_file(1);
            end
            app.imagingApp = imaging(app);
        end

        % Button pushed function: Unwrap
        function UnwrapButtonPushed(app, event)
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;

            if app.nm740Button.Value
                name = '740nm';
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
            end
            if isempty(app.answer)
                answer = questdlg('Which phase you want to unwrap?', ...
                	'Selection', ...
                	'Intralipid','Reference','Intralipid');
            else
                answer = app.answer;
            end
            % Handle response
            if ~isempty(answer)
                switch answer
                    case 'Intralipid'
                        selected_axis = app.Intraphase;
                        phase_copy = intraphase{end};
                        phase = intraphase{end};
                    case 'Reference'
                        selected_axis = app.Refphase;
                        phase_copy = refphase{end};
                        phase = refphase{end};
                end
            else
                errordlg('Please select the phase.','ERROR!!');
                app.answer = [];
                return
            end
            % find the points needing unwrap
            SD_idx = find(sddist{end} > 4.5);
            phase_copy(SD_idx) = 0;
            phase_copy = phase_copy(phase_copy~=0);
            sddist_copy = sddist{end}(sddist{end} <= 4.5);
            c = polyfit(sddist_copy,phase_copy,1);
            y_est = polyval(c,sddist{end});
            difference = phase - y_est;
            botoutlier = find(difference < -3.2);
            topoutlier = find(difference > 3.2);
            % adjust phase, lower points first, higher second
            if ~isempty(botoutlier)
                p1 = 1; p2 = 0;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                    Measmnt78(end+1) = Measmnt78(end);
                    sddist78(end+1) = sddist78(end);
                    
                    [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                        muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                        Refl);
                    
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                    Measmnt80(end+1) = Measmnt80(end);
                    sddist80(end+1) = sddist80(end);
                    
                    [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                        muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                        Refl);
                    
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                    Measmnt83(end+1) = Measmnt83(end);
                    sddist83(end+1) = sddist83(end);
                    
                    [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                        muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,botoutlier,...
                        p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                        Refl);
                    
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            
            if ~isempty(topoutlier)
                p1 = 0; p2 = 1;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                    Measmnt78(end+1) = Measmnt78(end);
                    sddist78(end+1) = sddist78(end);
                    
                    [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                        muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                        Refl);
                    
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                    Measmnt80(end+1) = Measmnt80(end);
                    sddist80(end+1) = sddist80(end);
                    
                    [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                        muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                        Refl);
                    
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                    Measmnt83(end+1) = Measmnt83(end);
                    sddist83(end+1) = sddist83(end);
                    
                    [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                        muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,topoutlier,...
                        p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                        Refl);
                    
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            app.answer = [];
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;

            if app.nm740Button.Value
                name = '740nm';
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
            end
            if isempty(app.answer)
                answer = questdlg('Which phase you want to unwrap?', ...
                	'Selection', ...
                	'Intralipid','Reference','Intralipid');
            else
                answer = app.answer;
            end
            % Handle response
            if ~isempty(answer)
                switch answer
                    case 'Intralipid'
                        selected_axis = app.Intraphase;
                        phase_copy = intraphase{end};
                        phase = intraphase{end};
                        slope_diff = 0.23949;
                        intersect_diff = -0.48153;
                    case 'Reference'
                        selected_axis = app.Refphase;
                        phase_copy = refphase{end};
                        phase = refphase{end};
                        slope_diff = 0.1959;
                        intersect_diff = 0.2877;
                end
            else
                errordlg('Please select the phase.','ERROR!!');
                app.answer = [];
                return
            end
            % fit a line and removel outliers based on the threshold
            SD_idx = find(sddist{end} > 4.5);
            phase_copy(SD_idx) = 0;
            phase_copy = phase_copy(phase_copy~=0);
            sddist_copy = sddist{end}(sddist{end} <= 4.5);
            c = polyfit(sddist_copy,phase_copy,1);
            y_est = polyval(c,sddist{end});
            difference = phase - y_est;
            upperbound = sddist{end}*slope_diff - intersect_diff;
            lowerbound = sddist{end}*(-slope_diff) + intersect_diff;
            botoutlier = find(difference < lowerbound);
            topoutlier = find(difference > upperbound);
%             figure;plot(sddist{end},difference,'bo');hold on
%             plot(sddist{end},upperbound,'ro')
%             plot(sddist{end},lowerbound,'ro');hold off
            sddist_index = (1:Nmeas);
            sddist_index(botoutlier) = [];
            topoutlier = find(ismember(sddist_index,topoutlier));
            
            % remove outliers, lower outlier first, higher second
            if ~isempty(botoutlier)
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(botoutlier,...
                        sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                    [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                        sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                        corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    app.pairs74 = pairs74;
                    app.sddist74 = sddist74;
                    app.Measmnt74 = Measmnt74;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(botoutlier,...
                        sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                    [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                        sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                        corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    app.pairs78 = pairs78;
                    app.sddist78 = sddist78;
                    app.Measmnt78 = Measmnt78;

                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(botoutlier,...
                        sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                    [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                        sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                        corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    app.pairs80 = pairs80;
                    app.sddist80 = sddist80;
                    app.Measmnt80 = Measmnt80;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                else
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(botoutlier,...
                        sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                    [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                        sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                        corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    app.pairs83 = pairs83;
                    app.sddist83 = sddist83;
                    app.Measmnt83 = Measmnt83;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            
            if ~isempty(topoutlier)
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(topoutlier,...
                        sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                    [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},corr_ki74{end+1},corr_kr74{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                        sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                        corr_ki74{end},corr_kr74{end},bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    app.pairs74 = pairs74;
                    app.sddist74 = sddist74;
                    app.Measmnt74 = Measmnt74;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(topoutlier,...
                        sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                    [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},corr_ki78{end+1},corr_kr78{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                        sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                        corr_ki78{end},corr_kr78{end},bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    app.pairs78 = pairs78;
                    app.sddist78 = sddist78;
                    app.Measmnt78 = Measmnt78;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(topoutlier,...
                        sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                    [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},corr_ki80{end+1},corr_kr80{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                        sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                        corr_ki80{end},corr_kr80{end},bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    app.pairs80 = pairs80;
                    app.sddist80 = sddist80;
                    app.Measmnt80 = Measmnt80;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                else
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                    end
                    [sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(topoutlier,...
                        sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                    [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},corr_ki83{end+1},corr_kr83{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                        sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                        corr_ki83{end},corr_kr83{end},bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    app.pairs83 = pairs83;
                    app.sddist83 = sddist83;
                    app.Measmnt83 = Measmnt83;
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')

                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                                 muabg78,muabg78a,muspbg78,muspbg78a;
                                 muabg80,muabg80a,muspbg80,muspbg80a;
                                 muabg83,muabg83a,muspbg83,muspbg83a;
                                 A_m74,P_m74,amp74_log,phase74;
                                 A_m78,P_m78,amp78_log,phase78;
                                 A_m80,P_m80,amp80_log,phase80;
                                 A_m83,P_m83,amp83_log,phase83;
                                 aba_r74,abp_r74,bga74,bgp74;
                                 aba_r78,abp_r78,bga78,bgp78;
                                 aba_r80,abp_r80,bga80,bgp80;
                                 aba_r83,abp_r83,bga83,bgp83;
                                 corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                                 corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            app.answer = [];
        end

        % Button pushed function: ClusterUnwrapButton
        function ClusterUnwrapButtonPushed(app, event)
            close all
            
            [muabg74,muabg78,muabg80,muabg83,muabg74a,muabg78a,muabg80a,muabg83a,muspbg74,muspbg78,muspbg80,muspbg83,muspbg74a,muspbg78a,muspbg80a,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,A_m78,A_m80,A_m83,P_m74,P_m78,P_m80,P_m83,amp74_log,amp78_log,amp80_log,amp83_log,phase74,phase78,phase80,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,aba_r78,aba_r80,aba_r83,abp_r74,abp_r78,abp_r80,abp_r83,bga74,bga78,bga80,bga83,bgp74,bgp78,bgp80,bgp83] = app.cali_data{9:12,1:4};
            [corr_ki74,corr_ki80,corr_kr74,corr_kr80,corr_ki78,corr_ki83,corr_kr78,corr_kr83] = app.cali_data{13:14,1:4};
            
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;

            if app.nm740Button.Value
                name = '740nm';
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
            end
            if isempty(app.answer)
                answer = questdlg('Which phase you want to unwrap?', ...
                	'Selection', ...
                	'Intralipid','Reference','Intralipid');
            else
                answer = app.answer;
            end
            % Handle response
            if ~isempty(answer)
                switch answer
                    case 'Intralipid'
                        selected_axis = app.Intraphase;
                        phase_copy = intraphase{end};
                        phase = intraphase{end};
                    case 'Reference'
                        selected_axis = app.Refphase;
                        phase_copy = refphase{end};
                        phase = refphase{end};
                end
            else
                errordlg('Please select the phase.','ERROR!!');
                app.answer = [];
                return
            end
            % find two clusters and adjust the phase
            small_idx = find(sddist{end} == min(sddist{end}),1);
            big_idx = find(sddist{end} ==  max(sddist{end}),1);
            difference_phase = phase_copy(big_idx) - phase_copy(small_idx);
            if difference_phase > 2 * Const.pi || difference_phase < - Const.pi
                threshold = abs(difference_phase) / 2 + min(phase_copy(big_idx),phase_copy(small_idx));
                SD_idx = find(phase_copy > threshold);
            else
%                 h = errordlg('Cannot detect two clasters of data points, please mannually adjust the phase.','ERROR!!');
                app.answer = [];
                return
            end
            
            if ~isempty(SD_idx)
                p1 = 0; p2 = 1;
                if app.nm740Button.Value
                    name = '740nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m74(end+1) = A_m74(end); pairs74(end+1) = pairs74(end);
                    Measmnt74(end+1) = Measmnt74(end);
                    sddist74(end+1) = sddist74(end);
                    
                    [P_m74{end+1},phase74{end+1},amp74_log{end+1},corr_ki74{end+1},corr_kr74{end+1},muabg74{end+1},...
                        muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1}] = phase_shift(phase_axis,SD_idx,...
                        p1,P_m74{end},A_m74{end},amp74_log{end},sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga74,bgp74,aba_r74,abp_r74,phase74{end},muabg74{end},muspbg74{end},corr_ki74{end},corr_kr74{end},freq,...
                        Refl);
                    
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muabg74a{end});
                    app.intramuspTextArea.Value = num2str(muspbg74{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['RefPhase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm780Button.Value
                    name = '780nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m78(end+1) = A_m78(end); pairs78(end+1) = pairs78(end);
                    Measmnt78(end+1) = Measmnt78(end);
                    sddist78(end+1) = sddist78(end);
                    
                    [P_m78{end+1},phase78{end+1},amp78_log{end+1},corr_ki78{end+1},corr_kr78{end+1},muabg78{end+1},...
                        muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1}] = phase_shift(phase_axis,SD_idx,...
                        p1,P_m78{end},A_m78{end},amp78_log{end},sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga78,bgp78,aba_r78,abp_r78,phase78{end},muabg78{end},muspbg78{end},corr_ki78{end},corr_kr78{end},freq,...
                        Refl);
                    
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muabg78a{end});
                    app.intramuspTextArea.Value = num2str(muspbg78{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm808Button.Value
                    name = '808nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m80(end+1) = A_m80(end); pairs80(end+1) = pairs80(end);
                    Measmnt80(end+1) = Measmnt80(end);
                    sddist80(end+1) = sddist80(end);
                    
                    [P_m80{end+1},phase80{end+1},amp80_log{end+1},corr_ki80{end+1},corr_kr80{end+1},muabg80{end+1},...
                        muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1}] = phase_shift(phase_axis,SD_idx,...
                        p1,P_m80{end},A_m80{end},amp80_log{end},sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga80,bgp80,aba_r80,abp_r80,phase80{end},muabg80{end},muspbg80{end},corr_ki80{end},corr_kr80{end},freq,...
                        Refl);
                    
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muabg80a{end});
                    app.intramuspTextArea.Value = num2str(muspbg80{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                elseif app.nm830Button.Value
                    name = '830nm';
                    switch selected_axis
                        case app.Intraphase
                            phase_axis = 'cali_axes_phase';
                        case app.Refphase
                            phase_axis = 'fit_axes_phase';
                    end
                    A_m83(end+1) = A_m83(end); pairs83(end+1) = pairs83(end);
                    Measmnt83(end+1) = Measmnt83(end);
                    sddist83(end+1) = sddist83(end);
                    
                    [P_m83{end+1},phase83{end+1},amp83_log{end+1},corr_ki83{end+1},corr_kr83{end+1},muabg83{end+1},...
                        muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1}] = phase_shift(phase_axis,SD_idx,...
                        p1,P_m83{end},A_m83{end},amp83_log{end},sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,...
                        s_geom,d_geom,bga83,bgp83,aba_r83,abp_r83,phase83{end},muabg83{end},muspbg83{end},corr_ki83{end},corr_kr83{end},freq,...
                        Refl);
                    
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muabg83a{end});
                    app.intramuspTextArea.Value = num2str(muspbg83{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Intra Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Intra Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Ref Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Ref Phase vs. Source-Detector Distance', ': ', name],'bo')
                end
            end
            app.cali_data = {muabg74,muabg74a,muspbg74,muspbg74a;
                             muabg78,muabg78a,muspbg78,muspbg78a;
                             muabg80,muabg80a,muspbg80,muspbg80a;
                             muabg83,muabg83a,muspbg83,muspbg83a;
                             A_m74,P_m74,amp74_log,phase74;
                             A_m78,P_m78,amp78_log,phase78;
                             A_m80,P_m80,amp80_log,phase80;
                             A_m83,P_m83,amp83_log,phase83;
                             aba_r74,abp_r74,bga74,bgp74;
                             aba_r78,abp_r78,bga78,bgp78;
                             aba_r80,abp_r80,bga80,bgp80;
                             aba_r83,abp_r83,bga83,bgp83;
                             corr_ki74,corr_kr74,corr_ki78,corr_kr78;
                             corr_ki80,corr_kr80,corr_ki83,corr_kr83};
            app.pairs74 = pairs74;
            app.pairs78 = pairs78;
            app.pairs80 = pairs80;
            app.pairs83 = pairs83;
            app.sddist74 = sddist74;
            app.sddist78 = sddist78;
            app.sddist80 = sddist80;
            app.sddist83 = sddist83;
            app.Measmnt74 = Measmnt74;
            app.Measmnt78 = Measmnt78;
            app.Measmnt80 = Measmnt80;
            app.Measmnt83 = Measmnt83;
            app.answer = [];
        end

        % Button pushed function: AutoUnwrapButton
        function AutoUnwrapButtonPushed(app, event)
            % automated unwrap, smooth first, line-fitting second, cluster
            % last. If all failed, force to do line-fitting.
            m = findall(0,'type','figure','tag','Msgbox_ ');
            delete(m);
            fitting_on = 0;
            cluster_on = 0;
            failed = 0;
            fitting_on740 = 0;
            cluster_on740 = 0;
            failed740 = 0;
            fitting_on780 = 0;
            cluster_on780 = 0;
            failed780 = 0;
            fitting_on808 = 0;
            cluster_on808 = 0;
            failed808 = 0;
            fitting_on830 = 0;
            cluster_on830 = 0;
            failed830 = 0;
            % run all method on each wavelength
            % Run all wavelengthes with Smooth code on intra and ref
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 740 nm..'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Running Smooth code..(SmoothButtonPushed)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            app.answer = 'Intralipid';
            SmoothButtonPushed(app, event)
            app.answer = 'Reference';
            SmoothButtonPushed(app, event)
            [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
            if fitting_error740
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
            end
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
            if ~isempty(intra_not_wrapped740) || ~isempty(ref_not_wrapped740) || fitting_error740 
                app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 740 nm, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on740 = 1;
                cluster_on740 = 0;
            elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on740 = 1;
                cluster_on740 = 0;
            end
            if fitting_on740
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running line-fitting code..(UnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 740nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                UnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
            
                if fitting_error740
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
                if ~isempty(intra_not_wrapped740) || ~isempty(ref_not_wrapped740) || fitting_error740
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 740 nm, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on740 = 0;
                    cluster_on740 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 9)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on740 = 0;
                    cluster_on740 = 1;
                end
            end
            if cluster_on740
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running clustering code..(ClusterUnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 740nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                ClusterUnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                ClusterUnwrapButtonPushed(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
                
                if fitting_error740
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers740, ref_outliers740, intra_not_wrapped740, ref_not_wrapped740, fitting_error740] = find_outliers(app,event);
                
                if ~isempty(intra_not_wrapped740) || ~isempty(ref_not_wrapped740) ||  fitting_error740
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 740 nm, moving to the next method..'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed740 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed740 = 1;
                end
            end
            if failed740
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outliers or phase shifting detected in all methods, please manually check measurements.'];
%                 errordlg('All methods failed in 740 nm, please manually unwrap the phase.','ERROR!!');
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
                for i = 1:5
                    app.answer = 'Intralipid';
                    UnwrapButtonPushed(app, event)
                end
                selected_axis = app.Refphase;
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Forced fitting method is applied in 740 nm.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            else
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removing..(outlier_removal)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')

                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm740Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
%                 outlier_removal(app,intra_outliers740,selected_axis)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                selected_axis = app.Refphase;
                ref_ownoutliers = setdiff(ref_outliers740,intra_outliers740);
                minus_num_store = [];
                for ind = 1:length(ref_ownoutliers)
                    minus_num = sum(intra_outliers740<ref_ownoutliers(ind));
                    minus_num_store = [minus_num_store,minus_num];
                end
%                 outlier_removal(app,ref_ownoutliers-minus_num_store,selected_axis)
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                drawnow;
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removal in 740 nm finished.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            end
             
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 780 nm..'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Running Smooth code..(SmoothButtonPushed)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            app.answer = 'Intralipid';
            SmoothButtonPushed(app, event)
            app.answer = 'Reference';
            SmoothButtonPushed(app, event)
            [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
            if fitting_error780
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
            end
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
            if ~isempty(intra_not_wrapped780) || ~isempty(ref_not_wrapped780) || fitting_error780 
                app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 780 nm, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on780 = 1;
                cluster_on780 = 0;
            elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on780 = 1;
                cluster_on780 = 0;
            end
            if fitting_on780
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running line-fitting code..(UnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 780nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                UnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
            
                if fitting_error780
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
                if ~isempty(intra_not_wrapped780) || ~isempty(ref_not_wrapped780) || fitting_error780
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 780 nm, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on780 = 0;
                    cluster_on780 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 9)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on780 = 0;
                    cluster_on780 = 1;
                end
            end
            if cluster_on780
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running clustering code..(ClusterUnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 780nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                ClusterUnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                ClusterUnwrapButtonPushed(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
                
                if fitting_error780
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers780, ref_outliers780, intra_not_wrapped780, ref_not_wrapped780, fitting_error780] = find_outliers(app,event);
                
                if ~isempty(intra_not_wrapped780) || ~isempty(ref_not_wrapped780) ||  fitting_error780
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 780 nm, moving to the next method..'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed780 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed780 = 1;
                end
            end
            if failed780
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outliers or phase shifting detected in all methods, please manually check measurements.'];
%                 errordlg('All methods failed in 740 nm, please manually unwrap the phase.','ERROR!!');
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
                for i = 1:5
                    app.answer = 'Intralipid';
                    UnwrapButtonPushed(app, event)
                end
                selected_axis = app.Refphase;
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Forced fitting method is applied in 780 nm.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            else
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removing..(outlier_removal)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')

                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm780Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
%                 outlier_removal(app,intra_outliers780,selected_axis)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                selected_axis = app.Refphase;
                ref_ownoutliers = setdiff(ref_outliers780,intra_outliers780);
                minus_num_store = [];
                for ind = 1:length(ref_ownoutliers)
                    minus_num = sum(intra_outliers780<ref_ownoutliers(ind));
                    minus_num_store = [minus_num_store,minus_num];
                end
%                 outlier_removal(app,ref_ownoutliers-minus_num_store,selected_axis)
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                drawnow;
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removal in 780 nm finished.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            end
            
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 808 nm..'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Running Smooth code..(SmoothButtonPushed)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            app.answer = 'Intralipid';
            SmoothButtonPushed(app, event)
            app.answer = 'Reference';
            SmoothButtonPushed(app, event)
            [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
            if fitting_error808
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
            end
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
            if ~isempty(intra_not_wrapped808) || ~isempty(ref_not_wrapped808) || fitting_error808 
                app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 808 nm, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on808 = 1;
                cluster_on808 = 0;
            elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on808 = 1;
                cluster_on808 = 0;
            end
            if fitting_on808
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running line-fitting code..(UnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 808nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                UnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
            
                if fitting_error808
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
                if ~isempty(intra_not_wrapped808) || ~isempty(ref_not_wrapped808) || fitting_error808
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 808 nm, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on808 = 0;
                    cluster_on808 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 9)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on808 = 0;
                    cluster_on808 = 1;
                end
            end
            if cluster_on808
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running clustering code..(ClusterUnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 808nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                ClusterUnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                ClusterUnwrapButtonPushed(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
                
                if fitting_error808
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers808, ref_outliers808, intra_not_wrapped808, ref_not_wrapped808, fitting_error808] = find_outliers(app,event);
                
                if ~isempty(intra_not_wrapped808) || ~isempty(ref_not_wrapped808) ||  fitting_error808
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 808 nm, moving to the next method..'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed808 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed808 = 1;
                end
            end
            if failed808
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outliers or phase shifting detected in all methods, please manually check measurements.'];
%                 errordlg('All methods failed in 740 nm, please manually unwrap the phase.','ERROR!!');
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
                for i = 1:5
                    app.answer = 'Intralipid';
                    UnwrapButtonPushed(app, event)
                end
                selected_axis = app.Refphase;
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Forced fitting method is applied in 808 nm.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            else
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removing..(outlier_removal)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')

                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm808Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
%                 outlier_removal(app,intra_outliers808,selected_axis)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                selected_axis = app.Refphase;
                ref_ownoutliers = setdiff(ref_outliers808,intra_outliers808);
                minus_num_store = [];
                for ind = 1:length(ref_ownoutliers)
                    minus_num = sum(intra_outliers808<ref_ownoutliers(ind));
                    minus_num_store = [minus_num_store,minus_num];
                end
%                 outlier_removal(app,ref_ownoutliers-minus_num_store,selected_axis)
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                drawnow;
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removal in 808 nm finished.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            end
            
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
            app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 830 nm..'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            app.LogTextArea.Value = [app.LogTextArea.Value;'Running Smooth code..(SmoothButtonPushed)'];
            drawnow;
            scroll(app.LogTextArea, 'bottom')
            app.answer = 'Intralipid';
            SmoothButtonPushed(app, event)
            app.answer = 'Reference';
            SmoothButtonPushed(app, event)
            [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
            if fitting_error830
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
            end
            app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
            SourceWavelengthsButtonGroupSelectionChanged(app, event)
            [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
            if ~isempty(intra_not_wrapped830) || ~isempty(ref_not_wrapped830) || fitting_error830 
                app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 830 nm, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on830 = 1;
                cluster_on830 = 0;
            elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                UndoAllButtonPushed(app, event)
                fitting_on830 = 1;
                cluster_on830 = 0;
            end
            if fitting_on830
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running line-fitting code..(UnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 830nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                UnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                UnwrapButtonPushed(app, event)
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
            
                if fitting_error830
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
                if ~isempty(intra_not_wrapped830) || ~isempty(ref_not_wrapped830) || fitting_error830
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 830 nm, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on830 = 0;
                    cluster_on830 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 9)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    fitting_on830 = 0;
                    cluster_on830 = 1;
                end
            end
            if cluster_on830
                app.LogTextArea.Value = [app.LogTextArea.Value;'Running clustering code..(ClusterUnwrapButtonPushed)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Working on 830nm..'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.answer = 'Intralipid';
                ClusterUnwrapButtonPushed(app, event)
                app.answer = 'Reference';
                ClusterUnwrapButtonPushed(app, event)
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
                
                if fitting_error830
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                [intra_outliers830, ref_outliers830, intra_not_wrapped830, ref_not_wrapped830, fitting_error830] = find_outliers(app,event);
                
                if ~isempty(intra_not_wrapped830) || ~isempty(ref_not_wrapped830) ||  fitting_error830
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Failure detected in 830 nm, moving to the next method..'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed830 = 1;
                elseif (str2double(app.refmuaTextArea.Value{1}) < 0) || (str2double(app.refmuaTextArea.Value{1}) > 0.15) || (str2double(app.intramuaTextArea.Value{1}) < 0) || (str2double(app.intramuaTextArea.Value{1}) > 0.04) ||(str2double(app.refmuspTextArea.Value{1}) < 0) || (str2double(app.refmuspTextArea.Value{1}) > 10) || (str2double(app.intramuspTextArea.Value{1}) < 0) || (str2double(app.intramuspTextArea.Value{1}) > 8)
                    app.LogTextArea.Value = [app.LogTextArea.Value;'Abnormal coefficients detected, moving to the next method..(UndoAllButtonPushed)'];
                    drawnow;
                    scroll(app.LogTextArea, 'bottom')
                    app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                    SourceWavelengthsButtonGroupSelectionChanged(app, event)
                    UndoAllButtonPushed(app, event)
                    failed830 = 1;
                end
            end
            if failed830
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outliers or phase shifting detected in all methods, please manually check measurements.'];
%                 errordlg('All methods failed in 740 nm, please manually unwrap the phase.','ERROR!!');
                drawnow;
                scroll(app.LogTextArea, 'bottom')
                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
                for i = 1:5
                    app.answer = 'Intralipid';
                    UnwrapButtonPushed(app, event)
                end
                selected_axis = app.Refphase;
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                app.LogTextArea.Value = [app.LogTextArea.Value;'Forced fitting method is applied in 830 nm.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            else
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removing..(outlier_removal)'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')

                app.SourceWavelengthsButtonGroup.SelectedObject = app.nm830Button;
                SourceWavelengthsButtonGroupSelectionChanged(app, event)
                selected_axis = app.Intraphase;
%                 outlier_removal(app,intra_outliers830,selected_axis)
                app.answer = 'Intralipid';
                DeleteButtonPushed(app, event)
                selected_axis = app.Refphase;
                ref_ownoutliers = setdiff(ref_outliers830,intra_outliers830);
                minus_num_store = [];
                for ind = 1:length(ref_ownoutliers)
                    minus_num = sum(intra_outliers830<ref_ownoutliers(ind));
                    minus_num_store = [minus_num_store,minus_num];
                end
%                 outlier_removal(app,ref_ownoutliers-minus_num_store,selected_axis)
                for i = 1:5
                    app.answer = 'Reference';
                    UnwrapButtonPushed(app, event)
                end
                app.answer = 'Reference';
                DeleteButtonPushed(app, event)
                drawnow;
                app.LogTextArea.Value = [app.LogTextArea.Value;'Outlier removal in 830 nm finished.'];
                drawnow;
                scroll(app.LogTextArea, 'bottom')
            end
            
            
            app.Smooth.Enable = true;
            app.Unwrap.Enable = true;
            app.Delete.Enable = true;
            app.ClusterUnwrapButton.Enable = true;
            app.SelectpointsButton.Enable = true;
            app.RemovepointsButton.Enable = true;
            app.PhaseshiftButton.Enable = true;
            app.UndoLastButton.Enable = true;
            app.UndoAllButton.Enable = true;
        end

        % Value changed function: IntralipidDropDown
        function IntralipidDropDownValueChanged(app, event)

            CalibrationButtonPushed(app, event)
        end

        % Button pushed function: AutopreprocessingButton
        function AutopreprocessingButtonPushed(app, event)
            MotioncheckButtonPushed(app, event)

            MismatchcheckButtonPushed(app, event)

            AutoUnwrapButtonPushed(app, event)
            
            CreateimagesButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadFolder = uibutton(app.UIFigure, 'push');
            app.LoadFolder.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderPushed, true);
            app.LoadFolder.Text = 'Load';
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.GeoDropDown = uidropdown(app.UIFigure);
            app.GeoDropDown.Items = {'UConn', 'WashU', 'FPGA', 'Sys1', 'Sys2', 'Sys3'};
            app.GeoDropDown.ValueChangedFcn = createCallbackFcn(app, @GeoDropDownValueChanged, true);
            app.GeoDropDown.Value = 'UConn';
            app.CurrentfolderTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentfolderTextAreaLabel.Text = 'Current folder';
            app.CurrentfolderTextArea = uitextarea(app.UIFigure);
            app.CurrentfolderTextArea.Editable = 'off';
            app.CurrentgeometryTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentgeometryTextAreaLabel.Text = 'Current geometry';
            app.CurrentgeometryTextArea = uitextarea(app.UIFigure);
            app.CurrentgeometryTextArea.Editable = 'off';
            app.AutopreprocessingButton = uibutton(app.UIFigure, 'push');
            app.AutopreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @AutopreprocessingButtonPushed, true);
            app.AutopreprocessingButton.Text = 'Auto preprocessing';
            app.MotionMismatchcheckPanel = uipanel(app.UIFigure);
            app.MotionMismatchcheckPanel.Title = 'Motion & Mismatch check';
            app.PlotmotionCheckBox = uicheckbox(app.MotionMismatchcheckPanel);
            app.PlotmotionCheckBox.Text = 'Plot motion';
            app.Motioncheck = uibutton(app.MotionMismatchcheckPanel, 'push');
            app.Motioncheck.ButtonPushedFcn = createCallbackFcn(app, @MotioncheckButtonPushed, true);
            app.Motioncheck.Text = 'Motion';
            app.Mismatchcheck = uibutton(app.MotionMismatchcheckPanel, 'push');
            app.Mismatchcheck.ButtonPushedFcn = createCallbackFcn(app, @MismatchcheckButtonPushed, true);
            app.Mismatchcheck.Text = 'Mismatch';
            app.CreatemotionfolderCheckBox = uicheckbox(app.MotionMismatchcheckPanel);
            app.CreatemotionfolderCheckBox.Text = 'Create motion folder';
            app.FilenameDropDownLabel = uilabel(app.MotionMismatchcheckPanel);
            app.FilenameDropDownLabel.Text = 'File name';
            app.FilenameDropDown = uidropdown(app.MotionMismatchcheckPanel);
            app.FilenameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FilenameDropDownOpening, true);
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilenameDropDownValueChanged, true);
            app.PlotindexDropDownLabel = uilabel(app.MotionMismatchcheckPanel);
            app.PlotindexDropDownLabel.Text = 'Plot index';
            app.PlotindexDropDown = uidropdown(app.MotionMismatchcheckPanel);
            app.PlotindexDropDown.Items = {'1 vs. 2', '2 vs. 3'};
            app.PlotindexDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotindexDropDownValueChanged, true);
            app.PlotindexDropDown.Value = '1 vs. 2';
            app.WavelengthDropDownLabel = uilabel(app.MotionMismatchcheckPanel);
            app.WavelengthDropDownLabel.Text = 'Wavelength';
            app.WavelengthDropDown = uidropdown(app.MotionMismatchcheckPanel);
            app.WavelengthDropDown.Items = {'740nm', '780nm', '808nm', '830nm'};
            app.WavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @WavelengthDropDownValueChanged, true);
            app.WavelengthDropDown.Value = '740nm';
            app.OutlierremovalPanel = uipanel(app.UIFigure);
            app.OutlierremovalPanel.Title = 'Outlier removal';
            app.Calibration = uibutton(app.OutlierremovalPanel, 'push');
            app.Calibration.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.Calibration.Text = 'Calibration';
            app.IntralipidDropDownLabel = uilabel(app.OutlierremovalPanel);
            app.IntralipidDropDownLabel.Text = 'Intralipid';
            app.IntralipidDropDown = uidropdown(app.OutlierremovalPanel);
            app.IntralipidDropDown.ValueChangedFcn = createCallbackFcn(app, @IntralipidDropDownValueChanged, true);
            app.SourceWavelengthsButtonGroup = uibuttongroup(app.OutlierremovalPanel);
            app.SourceWavelengthsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceWavelengthsButtonGroupSelectionChanged, true);
            app.SourceWavelengthsButtonGroup.Title = 'Source Wavelengths';
            app.nm740Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm740Button.Text = '740nm';
            app.nm740Button.Value = true;
            app.nm780Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm780Button.Text = '780nm';
            app.nm808Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm808Button.Text = '808nm';
            app.nm830Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm830Button.Text = '830nm';
            app.AbsorptionScatteringCoefficientsLabel = uilabel(app.OutlierremovalPanel);
            app.AbsorptionScatteringCoefficientsLabel.Text = 'Absorption & Scattering Coefficients';
            app.IntralipidLabel = uilabel(app.OutlierremovalPanel);
            app.IntralipidLabel.Text = 'Intralipid';
            app.RefTissueLabel = uilabel(app.OutlierremovalPanel);
            app.RefTissueLabel.Text = 'Ref Tissue';
            app.muaTextAreaLabel = uilabel(app.OutlierremovalPanel);
            app.muaTextAreaLabel.Text = 'mua:';
            app.intramuaTextArea = uitextarea(app.OutlierremovalPanel);
            app.intramuaTextArea.Editable = 'off';
            app.muaTextArea_2Label = uilabel(app.OutlierremovalPanel);
            app.muaTextArea_2Label.Text = 'mua:';
            app.refmuaTextArea = uitextarea(app.OutlierremovalPanel);
            app.refmuaTextArea.Editable = 'off';
            app.muspTextAreaLabel = uilabel(app.OutlierremovalPanel);
            app.muspTextAreaLabel.Text = 'musp:';
            app.intramuspTextArea = uitextarea(app.OutlierremovalPanel);
            app.intramuspTextArea.Editable = 'off';
            app.muspTextArea_2Label = uilabel(app.OutlierremovalPanel);
            app.muspTextArea_2Label.Text = 'musp:';
            app.refmuspTextArea = uitextarea(app.OutlierremovalPanel);
            app.refmuspTextArea.Editable = 'off';
            app.Smooth = uibutton(app.OutlierremovalPanel, 'push');
            app.Smooth.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.Smooth.Text = 'Smooth';
            app.SelectpointsButton = uibutton(app.OutlierremovalPanel, 'push');
            app.SelectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointsButtonPushed, true);
            app.SelectpointsButton.Text = 'Select points';
            app.RemovepointsButton = uibutton(app.OutlierremovalPanel, 'push');
            app.RemovepointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepointsButtonPushed, true);
            app.RemovepointsButton.Text = 'Remove points';
            app.PhaseshiftButton = uibutton(app.OutlierremovalPanel, 'push');
            app.PhaseshiftButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseshiftButtonPushed, true);
            app.PhaseshiftButton.Text = 'Phase shift';
            app.PhaseadjustButtonGroup = uibuttongroup(app.OutlierremovalPanel);
            app.PhaseadjustButtonGroup.Title = 'Phase adjust';
            app.plus2pi = uiradiobutton(app.PhaseadjustButtonGroup);
            app.plus2pi.Text = '+ 2π';
            app.plus2pi.Value = true;
            app.minus2pi = uiradiobutton(app.PhaseadjustButtonGroup);
            app.minus2pi.Text = '- 2π';
            app.UndoAllButton = uibutton(app.OutlierremovalPanel, 'push');
            app.UndoAllButton.ButtonPushedFcn = createCallbackFcn(app, @UndoAllButtonPushed, true);
            app.UndoAllButton.Text = 'Undo All';
            app.UndoLastButton = uibutton(app.OutlierremovalPanel, 'push');
            app.UndoLastButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastButtonPushed, true);
            app.UndoLastButton.Text = 'Undo Last';
            app.Unwrap = uibutton(app.OutlierremovalPanel, 'push');
            app.Unwrap.ButtonPushedFcn = createCallbackFcn(app, @UnwrapButtonPushed, true);
            app.Unwrap.Text = 'Fitting Unwrap';
            app.Delete = uibutton(app.OutlierremovalPanel, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Fitting Delete';
            app.ClusterUnwrapButton = uibutton(app.OutlierremovalPanel, 'push');
            app.ClusterUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterUnwrapButtonPushed, true);
            app.ClusterUnwrapButton.Text = 'Cluster Unwrap';
            app.AutoUnwrapButton = uibutton(app.OutlierremovalPanel, 'push');
            app.AutoUnwrapButton.ButtonPushedFcn = createCallbackFcn(app, @AutoUnwrapButtonPushed, true);
            app.AutoUnwrapButton.Text = 'Auto Unwrap';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.CreateimagesButton = uibutton(app.UIFigure, 'push');
            app.CreateimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateimagesButtonPushed, true);
            app.CreateimagesButton.Text = 'Create images';
            app.MeasurementsdisplayPanel = uipanel(app.UIFigure);
            app.MeasurementsdisplayPanel.Title = 'Measurements display';
            app.Motionplotpha = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Motionplotpha, 'Phase')
            app.Motionplotamp = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Motionplotamp, 'Amplitude')
            app.Refphase = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Refphase, 'Ref Phase vs. Source-Detector Distance')
            app.Intraphase = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Intraphase, 'Intra Phase vs. Source-Detector Distance')
            app.Refamp = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Refamp, 'Ref Apm vs. Source-Detector Distance')
            app.Intraamp = uiaxes(app.MeasurementsdisplayPanel);
            title(app.Intraamp, 'Intra Amp vs. Source-Detector Distance')
            app.RemainingpointsTextAreaLabel = uilabel(app.MeasurementsdisplayPanel);
            app.RemainingpointsTextAreaLabel.Text = 'Remaining points';
            app.RemainingpointsTextArea = uitextarea(app.MeasurementsdisplayPanel);
            app.RemainingpointsTextArea.Editable = 'off';
            app.ReferenceTextAreaLabel = uilabel(app.MeasurementsdisplayPanel);
            app.ReferenceTextAreaLabel.Text = 'Reference';
            app.ReferenceTextArea = uitextarea(app.MeasurementsdisplayPanel);
            app.ReferenceTextArea.Editable = 'off';
            app.IntralipidTextAreaLabel = uilabel(app.MeasurementsdisplayPanel);
            app.IntralipidTextAreaLabel.Text = 'Intralipid';
            app.IntralipidTextArea = uitextarea(app.MeasurementsdisplayPanel);
            app.IntralipidTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end