classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PhaseshiftButton              matlab.ui.control.Button
        RemovepointsButton            matlab.ui.control.Button
        SelectpointsButton            matlab.ui.control.Button
        Smooth                        matlab.ui.control.Button
        RefTissueLabel                matlab.ui.control.Label
        IntralipidLabel               matlab.ui.control.Label
        AbsorptionScatteringCoefficientsLabel  matlab.ui.control.Label
        refmuspTextArea               matlab.ui.control.TextArea
        muspTextArea_2Label           matlab.ui.control.Label
        intramuspTextArea             matlab.ui.control.TextArea
        muspTextAreaLabel             matlab.ui.control.Label
        refmuaTextArea                matlab.ui.control.TextArea
        muaTextArea_2Label            matlab.ui.control.Label
        intramuaTextArea              matlab.ui.control.TextArea
        muaTextAreaLabel              matlab.ui.control.Label
        SourceWavelengthsButtonGroup  matlab.ui.container.ButtonGroup
        nm830Button                   matlab.ui.control.RadioButton
        nm808Button                   matlab.ui.control.RadioButton
        nm780Button                   matlab.ui.control.RadioButton
        nm740Button                   matlab.ui.control.RadioButton
        Calibration                   matlab.ui.control.Button
        IntralipidDropDown            matlab.ui.control.DropDown
        IntralipidDropDownLabel       matlab.ui.control.Label
        WavelengthDropDown            matlab.ui.control.DropDown
        WavelengthDropDownLabel       matlab.ui.control.Label
        PlotindexDropDown             matlab.ui.control.DropDown
        PlotindexDropDownLabel        matlab.ui.control.Label
        FilenameDropDown              matlab.ui.control.DropDown
        FilenameDropDownLabel         matlab.ui.control.Label
        Mismatchcheck                 matlab.ui.control.Button
        GeoDropDown                   matlab.ui.control.DropDown
        GeometryLabel                 matlab.ui.control.Label
        Motioncheck                   matlab.ui.control.Button
        LoadFolder                    matlab.ui.control.Button
        PlotmotionCheckBox            matlab.ui.control.CheckBox
        Motionplotpha                 matlab.ui.control.UIAxes
        Motionplotamp                 matlab.ui.control.UIAxes
        Refphase                      matlab.ui.control.UIAxes
        Intraphase                    matlab.ui.control.UIAxes
        Refamp                        matlab.ui.control.UIAxes
        Intraamp                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mode
        tar_name
        ref_name
        intra_name
        tar_nLesion
        ref_nLesion
        intra_nLesion
        meanTol
        ptDiffTol
        badptsTol
        Ns
        Nd
        s_geom
        d_geom
        Measmnt
        Measmnt74
        Measmnt78
        Measmnt80
        Measmnt83
        pairs
        pairs74
        pairs78
        pairs80
        pairs83
        sddist_matrix
        ref_isMotion
        tar_isMotion
        tar_alldata
        ref_alldata
        intra_alldata
        cali_data
        selected_axis
        xbounds
        ybounds
        sddist74
        sddist78
        sddist80
        sddist83
        sddist
        
    end
    
    methods (Access = private)
        
        function motionplot(app) %plot amplitude and phase in motion checking
            cla(app.Motionplotamp);
            cla(app.Motionplotpha);
            value = app.FilenameDropDown.Value;
            file_name = [app.tar_name , app.ref_name];
            name = strfind(file_name,value(1:end-2));
            file_idx = find(~cellfun(@isempty, name));
            plot_name = file_name(file_idx);
            number=file_idx(end)/3;
            number_of_pair = app.Ns * app.Nd;
            total_Data1 = [app.tar_alldata{1};app.ref_alldata{1}];
            total_Data2 = [app.tar_alldata{2};app.ref_alldata{2}];
            total_Data3 = [app.tar_alldata{3};app.ref_alldata{3}];
            % load the raw data in total_Data
            if app.PlotindexDropDown.Value == '1 vs. 2'
                amp741 = reshape(total_Data1{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp781 = reshape(total_Data1{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp801 = reshape(total_Data1{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp831 = reshape(total_Data1{number,4}(1:number_of_pair),app.Ns,app.Nd);
                amp742 = reshape(total_Data2{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp782 = reshape(total_Data2{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp802 = reshape(total_Data2{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp832 = reshape(total_Data2{number,4}(1:number_of_pair),app.Ns,app.Nd);
                pha741 = reshape(total_Data1{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha781 = reshape(total_Data1{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha801 = reshape(total_Data1{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha831 = reshape(total_Data1{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
                pha742 = reshape(total_Data2{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha782 = reshape(total_Data2{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha802 = reshape(total_Data2{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha832 = reshape(total_Data2{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
            else
                amp741 = reshape(total_Data2{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp781 = reshape(total_Data2{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp801 = reshape(total_Data2{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp831 = reshape(total_Data2{number,4}(1:number_of_pair),app.Ns,app.Nd);
                amp742 = reshape(total_Data3{number,1}(1:number_of_pair),app.Ns,app.Nd);
                amp782 = reshape(total_Data3{number,2}(1:number_of_pair),app.Ns,app.Nd);
                amp802 = reshape(total_Data3{number,3}(1:number_of_pair),app.Ns,app.Nd);
                amp832 = reshape(total_Data3{number,4}(1:number_of_pair),app.Ns,app.Nd);
                pha741 = reshape(total_Data2{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha781 = reshape(total_Data2{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha801 = reshape(total_Data2{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha831 = reshape(total_Data2{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
                pha742 = reshape(total_Data3{number,1}(number_of_pair+1:end),app.Ns,app.Nd);
                pha782 = reshape(total_Data3{number,2}(number_of_pair+1:end),app.Ns,app.Nd);
                pha802 = reshape(total_Data3{number,3}(number_of_pair+1:end),app.Ns,app.Nd);
                pha832 = reshape(total_Data3{number,4}(number_of_pair+1:end),app.Ns,app.Nd);
            end
            
            cali_amp741=log(app.sddist_matrix.^2.*amp741);
            cali_amp781=log(app.sddist_matrix.^2.*amp781);
            cali_amp801=log(app.sddist_matrix.^2.*amp801);
            cali_amp831=log(app.sddist_matrix.^2.*amp831);

            cali_amp742=log(app.sddist_matrix.^2.*amp742);
            cali_amp782=log(app.sddist_matrix.^2.*amp782);
            cali_amp802=log(app.sddist_matrix.^2.*amp802);
            cali_amp832=log(app.sddist_matrix.^2.*amp832);
            
            rawSD_idx = find(app.sddist_matrix<2.8);
            sddist_matrix = app.sddist_matrix;
            if ~isempty(rawSD_idx)
                sddist_matrix(rawSD_idx) = [];
            end
            switch app.WavelengthDropDown.Value
                case '740mm'
                    ampwavelength1 = cali_amp741;
                    ampwavelength2 = cali_amp742;
                    phawavelength1 = pha741;
                    phawavelength2 = pha742;
                case '780mm'
                    ampwavelength1 = cali_amp781;
                    ampwavelength2 = cali_amp782;
                    phawavelength1 = pha781;
                    phawavelength2 = pha782;
                case '800mm'
                    ampwavelength1 = cali_amp801;
                    ampwavelength2 = cali_amp802;
                    phawavelength1 = pha801;
                    phawavelength2 = pha802;
                case '830mm'
                    ampwavelength1 = cali_amp831;
                    ampwavelength2 = cali_amp832;
                    phawavelength1 = pha831;
                    phawavelength2 = pha832;
            end
            sddist_matrix = reshape(sddist_matrix,1,[]);

            ampwavelength1 = reshape(ampwavelength1,1,[]);
            ampwavelength2 = reshape(ampwavelength2,1,[]);
            w1 = plot(app.Motionplotamp,sddist_matrix,ampwavelength1,'r.');
            hold(app.Motionplotamp,'on')
            xlim(app.Motionplotamp,[floor(min(sddist_matrix)) ceil(max(sddist_matrix))])
            w2 = plot(app.Motionplotamp,sddist_matrix,ampwavelength2,'bo');
            legend(app.Motionplotamp,[w1,w2],{'1st amp','2nd amp'});
            title(app.Motionplotamp,[app.PlotindexDropDown.Value, ', ', app.WavelengthDropDown.Value])
            hold(app.Motionplotamp,'off')
            
            phawavelength1 = reshape(phawavelength1,1,[]);
            phawavelength2 = reshape(phawavelength2,1,[]);
            w1 = plot(app.Motionplotpha,sddist_matrix,phawavelength1,'r.');
            hold(app.Motionplotpha,'on')
            xlim(app.Motionplotpha,[floor(min(sddist_matrix)) ceil(max(sddist_matrix))])
            w2 = plot(app.Motionplotpha,sddist_matrix,phawavelength2,'bo');
            legend(app.Motionplotpha,[w1,w2],{'1st amp','2nd amp'});
            title(app.Motionplotpha,[app.PlotindexDropDown.Value, ', ', app.WavelengthDropDown.Value])
            hold(app.Motionplotpha,'off')
        end
        
        function plot_result(app,axes,x,y,axes_title,color)
            plot(axes,x,y,color);
            grid(axes,'on')
            xlim(axes,[floor(min(x)) ceil(max(x))])
            title(axes,axes_title)
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.GeoDropDown.Value;
            switch value
                case 'WashU'
                    filename = 's9d14_new4_3_sysWU2.m';
                    fprintf('WashU')
                case 'UConn'
                    filename = 's9d14_new4_3_sysUCONN.m';
                    fprintf('Uconn')
            end
            run(filename)
            app.Ns = size(s_geom,1);
            app.Nd = size(d_geom,1);
            [~, str] = fileparts(filename);
            if strcmp(str,'s9d14_new4_3_sysWU2')||strcmp(str,'s9d14_new4_3_sysWU3')
                app.mode = 1; 
            elseif strcmp(str,'s9d14_new4_3_sysUCONN')
                app.mode = 2;
            else
                app.mode = 0;
            end
            app.sddist_matrix = sddist_matrix;
            app.pairs = pairs;
            app.s_geom = s_geom;
            app.d_geom = d_geom;
            app.Measmnt = Measmnt;
            app.sddist = sddist;
            app.pairs74 = {pairs74};
            app.pairs78 = {pairs78};
            app.pairs80 = {pairs80};
            app.pairs83 = {pairs83};
            app.sddist74 = {sddist74};
            app.sddist78 = {sddist78};
            app.sddist80 = {sddist80};
            app.sddist83 = {sddist83};
            app.Measmnt74 = {Measmnt74};
            app.Measmnt78 = {Measmnt78};
            app.Measmnt80 = {Measmnt80};
            app.Measmnt83 = {Measmnt83};
            app.FilenameDropDown.Enable = false;
            app.WavelengthDropDown.Enable = false;
            app.PlotindexDropDown.Enable = false;
            app.IntralipidDropDown.Enable = false;
            
            disableDefaultInteractivity(app.Intraamp)
            disableDefaultInteractivity(app.Intraphase)
            disableDefaultInteractivity(app.Refamp)
            disableDefaultInteractivity(app.Refphase)
        end

        % Button pushed function: LoadFolder
        function LoadFolderPushed(app, event)
            % load intralipid, reference and target txt file (maybe binary
            % later) by selecting folder
            app.FilenameDropDown.Enable = false;
            app.WavelengthDropDown.Enable = false;
            app.PlotindexDropDown.Enable = false;
            
            folder_path = uigetdir();
            txt_dir = dir(fullfile(folder_path,'*.txt'));
            txt_name = {txt_dir(:).name};
            
            app.intra_name = txt_name(strncmp(txt_name,'Intralipid', 10));
            app.ref_name = txt_name(strncmp(txt_name,'Reference', 9));
            app.tar_name = txt_name(strncmp(txt_name,'Target', 6));
            [app.intra_name,index] = natsort(app.intra_name);
            [app.ref_name,index] = natsort(app.ref_name);
            [app.tar_name,index] = natsort(app.tar_name);
            
            app.ref_name(:) = fullfile(folder_path, app.ref_name(:));
            app.tar_name(:) = fullfile(folder_path, app.tar_name(:));
            app.intra_name(:) = fullfile(folder_path, app.intra_name(:));
            [~, app.tar_nLesion] = size(app.tar_name);
            [~, app.ref_nLesion] = size(app.ref_name);
            [~, app.intra_nLesion] = size(app.intra_name);
            app.tar_nLesion = app.tar_nLesion/3;
            app.ref_nLesion = app.ref_nLesion/3;
            app.intra_nLesion = app.intra_nLesion/3;
            [~, intra_file] = fileparts(app.intra_name);
            app.IntralipidDropDown.Items = intra_file;
            app.IntralipidDropDown.Enable = true;
            [app.tar_alldata, app.ref_alldata, app.intra_alldata] = data_load(app.mode, app.tar_name, app.ref_name, app.intra_name, app.tar_nLesion, app.ref_nLesion, app.intra_nLesion, app.Ns, app.Nd, app.sddist_matrix);
            
            f = msgbox("Successfully loaded");
        end

        % Value changed function: GeoDropDown
        function GeoDropDownValueChanged(app, event)
            value = app.GeoDropDown.Value;
            switch value
                case 'WashU'
                    filename = 's9d14_new4_3_sysWU2.m';
                    fprintf('WashU')
                case 'UConn'
                    filename = 's9d14_new4_3_sysUCONN.m';
                    fprintf('Uconn')
            end
            run(filename)
            app.Ns = size(s_geom,1);
            app.Nd = size(d_geom,1);
            [~, str] = fileparts(filename);
            if strcmp(str,'s9d14_new4_3_sysWU2')||strcmp(str,'s9d14_new4_3_sysWU3')
                app.mode = 1; 
            elseif strcmp(str,'s9d14_new4_3_sysUCONN')
                app.mode = 2;
            else
                app.mode = 0;
            end
            app.sddist_matrix = sddist_matrix;
            app.pairs = pairs;
            app.s_geom = s_geom;
            app.d_geom = d_geom;
            app.Measmnt = Measmnt;
            app.sddist = sddist;
            app.pairs74 = {pairs74};
            app.pairs78 = {pairs78};
            app.pairs80 = {pairs80};
            app.pairs83 = {pairs83};
            app.sddist74 = {sddist74};
            app.sddist78 = {sddist78};
            app.sddist80 = {sddist80};
            app.sddist83 = {sddist83};
            app.Measmnt74 = {Measmnt74};
            app.Measmnt78 = {Measmnt78};
            app.Measmnt80 = {Measmnt80};
            app.Measmnt83 = {Measmnt83};
        end

        % Button pushed function: Motioncheck
        function MotioncheckButtonPushed(app, event)
            app.meanTol = 0.15; % abs relative difference toleratance
            app.ptDiffTol = 0.025; % abs value difference (not relative)
            app.badptsTol = 7; % How many bad pts can have in one source-all detectors data
            if app.PlotmotionCheckBox.Value == 1
                [app.tar_isMotion, app.ref_isMotion] = motion_detect(app.tar_name, app.ref_name, app.tar_nLesion, app.ref_nLesion, app.meanTol, app.ptDiffTol, app.badptsTol, app.Ns, app.Nd, app.sddist_matrix, app.tar_alldata, app.ref_alldata);
            else
                [app.tar_isMotion, app.ref_isMotion] = motion_detect_noplot(app.tar_name, app.ref_name, app.tar_nLesion, app.ref_nLesion, app.meanTol, app.ptDiffTol, app.badptsTol, app.Ns, app.Nd, app.sddist_matrix, app.tar_alldata, app.ref_alldata);
            end
            file_number = app.tar_nLesion + app.ref_nLesion;
            file_idx=[1:3:file_number*3];
            [~, tar_file] = fileparts(app.tar_name);
            [~, ref_file] = fileparts(app.ref_name);
            files=[tar_file,ref_file];
            app.FilenameDropDown.Items = files(file_idx);
            
            app.FilenameDropDown.Enable = true;
            app.WavelengthDropDown.Enable = true;
            app.PlotindexDropDown.Enable = true;
        end

        % Drop down opening function: FilenameDropDown
        function FilenameDropDownOpening(app, event)
           
        end

        % Value changed function: FilenameDropDown
        function FilenameDropDownValueChanged(app, event)
            motionplot(app)
        end

        % Value changed function: WavelengthDropDown
        function WavelengthDropDownValueChanged(app, event)
            motionplot(app)
        end

        % Value changed function: PlotindexDropDown
        function PlotindexDropDownValueChanged(app, event)
            motionplot(app)
        end

        % Button pushed function: Mismatchcheck
        function MismatchcheckButtonPushed(app, event)
            pertub_Features = mismatch_process(app.ref_isMotion, app.tar_isMotion, app.tar_nLesion, app.ref_nLesion, app.tar_alldata, app.ref_alldata);
        end

        % Button pushed function: Calibration
        function CalibrationButtonPushed(app, event)

            [~, tar_file] = fileparts(app.tar_name);
            [~, ref_file] = fileparts(app.ref_name);
            files=[tar_file, ref_file];
            app.FilenameDropDown.Items = files;
            Refl = 0; % no use now
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            sddist = app.sddist;

            s_geom = app.s_geom;
            d_geom = app.d_geom;
            
            [tar_size, ~] = size(app.tar_alldata{1});
            [ref_size, ~] = size(app.ref_alldata{1});
            total_data = cell(tar_size*3+ref_size*3,4);
            for i = 1:tar_size
                total_data(3*i-2,:) = app.tar_alldata{1}(i,:);
                total_data(3*i-1,:) = app.tar_alldata{2}(i,:);
                total_data(3*i,:) = app.tar_alldata{3}(i,:);
            end
            for i = 1:ref_size
                total_data(3*tar_size+3*i-2,:) = app.ref_alldata{1}(i,:);
                total_data(3*tar_size+3*i-1,:) = app.ref_alldata{2}(i,:);
                total_data(3*tar_size+3*i,:) = app.ref_alldata{3}(i,:);
            end
            
            ref_or_tar_value = app.FilenameDropDown.Value;
            file_name = [app.tar_name , app.ref_name];
            name = strfind(file_name,ref_or_tar_value);
            file_idx = find(~cellfun(@isempty, name));
            
            aba_r74 = reshape(total_data{file_idx,1}(1:Nmeas),source,detect);
            aba_r78 = reshape(total_data{file_idx,2}(1:Nmeas),source,detect);
            aba_r80 = reshape(total_data{file_idx,3}(1:Nmeas),source,detect);
            aba_r83 = reshape(total_data{file_idx,4}(1:Nmeas),source,detect);
            abp_r74 = reshape(total_data{file_idx,1}(Nmeas+1:end),source,detect);
            abp_r78 = reshape(total_data{file_idx,2}(Nmeas+1:end),source,detect);
            abp_r80 = reshape(total_data{file_idx,3}(Nmeas+1:end),source,detect);
            abp_r83 = reshape(total_data{file_idx,4}(Nmeas+1:end),source,detect);
            
            intra_value = app.IntralipidDropDown.Value;
            intra_name = strfind(app.intra_name,intra_value);
            intra_idx = find(~cellfun(@isempty, intra_name));
            [intra_size, ~] = size(app.intra_alldata{1});
            intra_total_data = cell(intra_size*3,4);
            for i = 1:intra_size
                intra_total_data(3*i-2,:) = app.intra_alldata{1}(i,:);
                intra_total_data(3*i-1,:) = app.intra_alldata{2}(i,:);
                intra_total_data(3*i,:) = app.intra_alldata{3}(i,:);
            end
            bga74 = reshape(intra_total_data{intra_idx,1}(1:Nmeas),source,detect);
            bga78 = reshape(intra_total_data{intra_idx,2}(1:Nmeas),source,detect);
            bga80 = reshape(intra_total_data{intra_idx,3}(1:Nmeas),source,detect);
            bga83 = reshape(intra_total_data{intra_idx,4}(1:Nmeas),source,detect);
            bgp74 = reshape(intra_total_data{intra_idx,1}(Nmeas+1:end),source,detect);
            bgp78 = reshape(intra_total_data{intra_idx,2}(Nmeas+1:end),source,detect);
            bgp80 = reshape(intra_total_data{intra_idx,3}(Nmeas+1:end),source,detect);
            bgp83 = reshape(intra_total_data{intra_idx,4}(Nmeas+1:end),source,detect);
            
            %Run normal calibration
            calibration4wvl;
            
            %Run the local fit programs for each source wavelength
            local_fit74;
            local_fit78;
            local_fit80;
            local_fit83;
            
            if app.nm740Button.Value
                name = '740nm';
                app.intramuaTextArea.Value = num2str(muabg74);
                app.refmuaTextArea.Value = num2str(muabg74a);
                app.intramuspTextArea.Value = num2str(muspbg74);
                app.refmuspTextArea.Value = num2str(muspbg74a);
                intraamp = A_m74;
                intraphase = P_m74;
                refamp = amp74_log;
                refphase = phase74;
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                app.intramuaTextArea.Value = num2str(muabg78);
                app.refmuaTextArea.Value = num2str(muabg78a);
                app.intramuspTextArea.Value = num2str(muspbg78);
                app.refmuspTextArea.Value = num2str(muspbg78a);
                intraamp = A_m78;
                intraphase = P_m78;
                refamp = amp78_log;
                refphase = phase78;
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                app.intramuaTextArea.Value = num2str(muabg80);
                app.refmuaTextArea.Value = num2str(muabg80a);
                app.intramuspTextArea.Value = num2str(muspbg80);
                app.refmuspTextArea.Value = num2str(muspbg80a);
                intraamp = A_m80;
                intraphase = P_m80;
                refamp = amp80_log;
                refphase = phase80;
                sddist = app.sddist80;
            else
                name = '830nm';
                app.intramuaTextArea.Value = num2str(muabg83);
                app.refmuaTextArea.Value = num2str(muabg83a);
                app.intramuspTextArea.Value = num2str(muspbg83);
                app.refmuspTextArea.Value = num2str(muspbg83a);
                intraamp = A_m83;
                intraphase = P_m83;
                refamp = amp83_log;
                refphase = phase83;
                sddist = app.sddist83;
            end
            app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             {aba_r74},{abp_r74},{bga74},{bgp74};
                             {aba_r78},{abp_r78},{bga78},{bgp78};
                             {aba_r80},{abp_r80},{bga80},{bgp80};
                             {aba_r83},{abp_r83},{bga83},{bgp83};};
            
            plot_result(app,app.Intraamp,sddist{1},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Intraphase,sddist{1},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Refamp,sddist{1},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
            plot_result(app,app.Refphase,sddist{1},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
        end

        % Selection changed function: SourceWavelengthsButtonGroup
        function SourceWavelengthsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceWavelengthsButtonGroup.SelectedObject;
            sddist_matrix = app.sddist_matrix;
            sddist = app.sddist;

            if app.nm740Button.Value
                name = '740nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{1,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{1,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{1,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{1,4}{end});
                intraamp = app.cali_data{5,1}{end};
                intraphase = app.cali_data{5,2}{end};
                refamp = app.cali_data{5,3}{end};
                refphase = app.cali_data{5,4}{end};
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{2,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{2,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{2,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{2,4}{end});
                intraamp = app.cali_data{6,1}{end};
                intraphase = app.cali_data{6,2}{end};
                refamp = app.cali_data{6,3}{end};
                refphase = app.cali_data{6,4}{end};
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{3,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{3,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{3,2}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{3,2}{end});
                intraamp = app.cali_data{7,1}{end};
                intraphase = app.cali_data{7,2}{end};
                refamp = app.cali_data{7,3}{end};
                refphase = app.cali_data{7,4}{end};
                sddist = app.sddist80;
            else
                name = '830nm';
                app.intramuaTextArea.Value = num2str(app.cali_data{4,1}{end});
                app.refmuaTextArea.Value = num2str(app.cali_data{4,2}{end});
                app.intramuspTextArea.Value = num2str(app.cali_data{4,3}{end});
                app.refmuspTextArea.Value = num2str(app.cali_data{4,4}{end});
                intraamp = app.cali_data{8,1}{end};
                intraphase = app.cali_data{8,2}{end};
                refamp = app.cali_data{8,3}{end};
                refphase = app.cali_data{8,4}{end};
                sddist = app.sddist83;
            end
            
            plot_result(app,app.Intraamp,sddist{end},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Intraphase,sddist{end},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
            plot_result(app,app.Refamp,sddist{end},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
            plot_result(app,app.Refphase,sddist{end},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
        end

        % Button pushed function: Smooth
        function SmoothButtonPushed(app, event)
            sddist_matrix = app.sddist_matrix;
            sddist = app.sddist;
            
            if app.nm740Button.Value
                name = '740nm';
                refphase = app.cali_data{5,4}{end};
                sddist = app.sddist74;
            elseif app.nm780Button.Value
                name = '780nm';
                refphase = app.cali_data{6,4}{end};
                sddist = app.sddist78;
            elseif app.nm808Button.Value
                name = '808nm';
                refphase = app.cali_data{7,4}{end};
                sddist = app.sddist80;
            else
                name = '830nm';
                refphase = app.cali_data{8,4}{end};
                sddist = app.sddist83;
            end
            smo_phase = smooth_array(refphase,sddist{end});
            plot_result(app,app.Refphase,sddist{end},smo_phase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
        end

        % Button pushed function: SelectpointsButton
        function SelectpointsButtonPushed(app, event)
            %Delete box outlining selected points if there is already one there
            lines = findobj(gcbf,'Tag','L');
            delete(lines);
            
            app.UIFigure.HandleVisibility = 'on';
            
            %User selects region from which points should be removed
            k=waitforbuttonpress;
            bound = rbbox;
            
            selected_axis = gca; %Determine which data set is being edited (phase or amplitude for intralipid or ref)
            b = get(selected_axis,'Parent'); %Find parent object of the current axis, i.e the uipanel
            
            %All objects need to be measured in the same units
            set(selected_axis,'Units','pixels');
            set(b,'Units','pixels');
            
            P = get(selected_axis, 'Position'); %Of axes, within the figure window
            M = get(b,'Position');
            
            %Get limits of plot
            xlimit = get(selected_axis, 'Xlim');
            ylimit = get(selected_axis, 'Ylim');
            
            % Get the coordinates of the lower left corner of the box,
            % its height and width, and the X-Y coordinates of the 4 corners
            deltax = xlimit(2)-xlimit(1);
            deltay = ylimit(2)-ylimit(1);
            left_dist = bound(1)-P(1)-M(1); %Distance of rbbox from left boundary of axes
            up_dist = bound(2)-P(2)-M(2);%Distance from lower boundary of axes
            
            %Convert dimensions of rbbox to x and y coordinates
            xlow = xlimit(1)+deltax*left_dist/P(3);
            xhigh = xlimit(1)+deltax*(left_dist+bound(3))/P(3);
            ylow = ylimit(1)+deltay*up_dist/P(4);
            yhigh = ylimit(1)+deltay*(up_dist+bound(4))/P(4);
            
            %X-Y information about the corners of the RBBOX
            xbounds = [xlow, xhigh];
            ybounds = [ylow, yhigh];
            
            %Create lines outlining the selected area
            l1 = line(xbounds(1),ybounds(1):0.01:ybounds(2),'color','k','LineWidth',2); set(l1,'Tag','L');
            l2 = line(xbounds(1):0.01:xbounds(2),ybounds(2),'color','k','LineWidth',2); set(l2,'Tag','L');
            l3 = line(xbounds(2),ybounds(1):0.01:ybounds(2),'color','k','LineWidth',2); set(l3,'Tag','L');
            l4 = line(xbounds(1):0.01:xbounds(2),ybounds(1),'color','k','LineWidth',2); set(l4,'Tag','L');
            
%             app.UIFigure.HandleVisibility = 'off';
            app.selected_axis = selected_axis;
            app.xbounds = xbounds;
            app.ybounds = ybounds;
        end

        % Button pushed function: RemovepointsButton
        function RemovepointsButtonPushed(app, event)
            selected_axis = app.selected_axis;
            xbounds = app.xbounds;
            ybounds = app.ybounds;
            
            [muabg74,muabg74a,muspbg74,muspbg74a,muabg78,muabg78a,muspbg78,muspbg78a,muabg80,muabg80a,muspbg80,muspbg80a,muabg83,muabg83a,muspbg83,muspbg83a] = app.cali_data{1:4,1:4};
            [A_m74,P_m74,amp74_log,phase74,A_m78,P_m78,amp78_log,phase78,A_m80,P_m80,amp80_log,phase80,A_m83,P_m83,amp83_log,phase83] = app.cali_data{5:8,1:4};         
            [aba_r74,abp_r74,bga74,bgp74,aba_r78,abp_r78,bga78,bgp78,aba_r80,abp_r80,bga80,bgp80,aba_r83,abp_r83,bga83,bgp83] = app.cali_data{9:12,1:4};
            source = app.Ns;
            detect = app.Nd;
            sddist_matrix = app.sddist_matrix;
            pairs = app.pairs;
            Measmnt = app.Measmnt;
            Nmeas = source*detect;
            s_geom = app.s_geom;
            d_geom = app.d_geom;
            pairs74 = app.pairs74;
            pairs78 = app.pairs78;
            pairs80 = app.pairs80;
            pairs83 = app.pairs83;
            sddist74 = app.sddist74;
            sddist78 = app.sddist78;
            sddist80 = app.sddist80;
            sddist83 = app.sddist83;
            Measmnt74 = app.Measmnt74;
            Measmnt78 = app.Measmnt78;
            Measmnt80 = app.Measmnt80;
            Measmnt83 = app.Measmnt83;
            freq = 140;
            Refl = 0;
            switch app.WavelengthDropDown.Value
                case '740nm'
                    name = '740nm';
                    switch selected_axis
                        case app.Intraamp
                            axisChoice = 'cali_axes_amp';
                            Badpoints = find(A_m74{end}>min(ybounds) & A_m74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                            Badpoints = find(P_m74{end}>min(ybounds) & P_m74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                        case app.Refamp
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp74_log{end}>min(ybounds) & amp74_log{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase74{end}>min(ybounds) & phase74{end}<max(ybounds) & sddist74{end}>min(xbounds) & sddist74{end}<max(xbounds));
                    end
                    [app.sddist74{end+1},A_m74{end+1},P_m74{end+1},pairs74{end+1},Measmnt74{end+1},phase74{end+1},amp74_log{end+1}] = bp_remove(BadPoints,...
                        sddist74{end},A_m74{end},P_m74{end},Measmnt74{end},pairs74{end},phase74{end},amp74_log{end},source,detect);
                    
                    [muabg74{end+1},muspbg74{end+1},muabg74a{end+1},muspbg74a{end+1},amp74_log{end},phase74{end}] = recal_refit(axisChoice,...
                        sddist74{end},Measmnt74{end},pairs74{end},source,detect,sddist_matrix,s_geom,d_geom,A_m74{end},P_m74{end},amp74_log{end},phase74{end},...
                        bga74,bgp74,aba_r74,abp_r74,freq,Refl);
                    intraamp = A_m74{end};
                    intraphase = P_m74{end};
                    refamp = amp74_log{end};
                    refphase = phase74{end};
                    sddist = sddist74;
                    app.intramuaTextArea.Value = num2str(muabg74{end});
                    app.refmuaTextArea.Value = num2str(muspbg74{end});
                    app.intramuspTextArea.Value = num2str(muabg74a{end});
                    app.refmuspTextArea.Value = num2str(muspbg74a{end});
                    app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             {aba_r74},{abp_r74},{bga74},{bgp74};
                             {aba_r78},{abp_r78},{bga78},{bgp78};
                             {aba_r80},{abp_r80},{bga80},{bgp80};
                             {aba_r83},{abp_r83},{bga83},{bgp83};};
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                case '780nm'
                    name = '780nm';
                    switch selected_axis
                        case app.Intraamp
                            axisChoice = 'cali_axes_amp';
                            Badpoints = find(A_m78{end}>min(ybounds) & A_m78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                            Badpoints = find(P_m78{end}>min(ybounds) & P_m78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                        case app.Refamp
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp78_log{end}>min(ybounds) & amp78_log{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase78{end}>min(ybounds) & phase78{end}<max(ybounds) & sddist78{end}>min(xbounds) & sddist78{end}<max(xbounds));
                    end
                    [app.sddist78{end+1},A_m78{end+1},P_m78{end+1},pairs78{end+1},Measmnt78{end+1},phase78{end+1},amp78_log{end+1}] = bp_remove(BadPoints,...
                        sddist78{end},A_m78{end},P_m78{end},Measmnt78{end},pairs78{end},phase78{end},amp78_log{end},source,detect);
                    
                    [muabg78{end+1},muspbg78{end+1},muabg78a{end+1},muspbg78a{end+1},amp78_log{end},phase78{end}] = recal_refit(axisChoice,...
                        sddist78{end},Measmnt78{end},pairs78{end},source,detect,sddist_matrix,s_geom,d_geom,A_m78{end},P_m78{end},amp78_log{end},phase78{end},...
                        bga78,bgp78,aba_r78,abp_r78,freq,Refl);
                    intraamp = A_m78{end};
                    intraphase = P_m78{end};
                    refamp = amp78_log{end};
                    refphase = phase78{end};
                    sddist = sddist78;
                    app.intramuaTextArea.Value = num2str(muabg78{end});
                    app.refmuaTextArea.Value = num2str(muspbg78{end});
                    app.intramuspTextArea.Value = num2str(muabg78a{end});
                    app.refmuspTextArea.Value = num2str(muspbg78a{end});
                    app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             {aba_r74},{abp_r74},{bga74},{bgp74};
                             {aba_r78},{abp_r78},{bga78},{bgp78};
                             {aba_r80},{abp_r80},{bga80},{bgp80};
                             {aba_r83},{abp_r83},{bga83},{bgp83};};
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                case '808nm'
                    name = '808nm';
                    switch selected_axis
                        case app.Intraamp
                            axisChoice = 'cali_axes_amp';
                            Badpoints = find(A_m80{end}>min(ybounds) & A_m80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                            Badpoints = find(P_m80{end}>min(ybounds) & P_m80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                        case app.Refamp
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp80_log{end}>min(ybounds) & amp80_log{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase80{end}>min(ybounds) & phase80{end}<max(ybounds) & sddist80{end}>min(xbounds) & sddist80{end}<max(xbounds));
                    end
                    [app.sddist80{end+1},A_m80{end+1},P_m80{end+1},pairs80{end+1},Measmnt80{end+1},phase80{end+1},amp80_log{end+1}] = bp_remove(BadPoints,...
                        sddist80{end},A_m80{end},P_m80{end},Measmnt80{end},pairs80{end},phase80{end},amp80_log{end},source,detect);
                    
                    [muabg80{end+1},muspbg80{end+1},muabg80a{end+1},muspbg80a{end+1},amp80_log{end},phase80{end}] = recal_refit(axisChoice,...
                        sddist80{end},Measmnt80{end},pairs80{end},source,detect,sddist_matrix,s_geom,d_geom,A_m80{end},P_m80{end},amp80_log{end},phase80{end},...
                        bga80,bgp80,aba_r80,abp_r80,freq,Refl);
                    intraamp = A_m80{end};
                    intraphase = P_m80{end};
                    refamp = amp80_log{end};
                    refphase = phase80{end};
                    sddist = sddist80;
                    app.intramuaTextArea.Value = num2str(muabg80{end});
                    app.refmuaTextArea.Value = num2str(muspbg80{end});
                    app.intramuspTextArea.Value = num2str(muabg80a{end});
                    app.refmuspTextArea.Value = num2str(muspbg80a{end});
                    app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             {aba_r74},{abp_r74},{bga74},{bgp74};
                             {aba_r78},{abp_r78},{bga78},{bgp78};
                             {aba_r80},{abp_r80},{bga80},{bgp80};
                             {aba_r83},{abp_r83},{bga83},{bgp83};};
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
                case '830nm'
                    name = '830nm';
                    switch selected_axis
                        case app.Intraamp
                            axisChoice = 'cali_axes_amp';
                            Badpoints = find(A_m83{end}>min(ybounds) & A_m83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                        case app.Intraphase
                            axisChoice = 'cali_axes_phase';
                            Badpoints = find(P_m83{end}>min(ybounds) & P_m83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                        case app.Refamp
                            axisChoice = 'fit_axes_amp';
                            Badpoints = find(amp83_log{end}>min(ybounds) & amp83_log{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                        case app.Refphase
                            axisChoice = 'fit_axes_phase';
                            Badpoints = find(phase83{end}>min(ybounds) & phase83{end}<max(ybounds) & sddist83{end}>min(xbounds) & sddist83{end}<max(xbounds));
                    end
                    [app.sddist83{end+1},A_m83{end+1},P_m83{end+1},pairs83{end+1},Measmnt83{end+1},phase83{end+1},amp83_log{end+1}] = bp_remove(BadPoints,...
                        sddist83{end},A_m83{end},P_m83{end},Measmnt83{end},pairs83{end},phase83{end},amp83_log{end},source,detect);
                    
                    [muabg83{end+1},muspbg83{end+1},muabg83a{end+1},muspbg83a{end+1},amp83_log{end},phase83{end}] = recal_refit(axisChoice,...
                        sddist83{end},Measmnt83{end},pairs83{end},source,detect,sddist_matrix,s_geom,d_geom,A_m83{end},P_m83{end},amp83_log{end},phase83{end},...
                        bga83,bgp83,aba_r83,abp_r83,freq,Refl);
                    intraamp = A_m83{end};
                    intraphase = P_m83{end};
                    refamp = amp83_log{end};
                    refphase = phase83{end};
                    sddist = sddist83;
                    app.intramuaTextArea.Value = num2str(muabg83{end});
                    app.refmuaTextArea.Value = num2str(muspbg83{end});
                    app.intramuspTextArea.Value = num2str(muabg83a{end});
                    app.refmuspTextArea.Value = num2str(muspbg83a{end});
                    app.cali_data = {{muabg74},{muabg74a},{muspbg74},{muspbg74a};
                             {muabg78},{muabg78a},{muspbg78},{muspbg78a};
                             {muabg80},{muabg80a},{muspbg80},{muspbg80a};
                             {muabg83},{muabg83a},{muspbg83},{muspbg83a};
                             {A_m74},{P_m74},{amp74_log},{phase74};
                             {A_m78},{P_m78},{amp78_log},{phase78};
                             {A_m80},{P_m80},{amp80_log},{phase80};
                             {A_m83},{P_m83},{amp83_log},{phase83};
                             {aba_r74},{abp_r74},{bga74},{bgp74};
                             {aba_r78},{abp_r78},{bga78},{bgp78};
                             {aba_r80},{abp_r80},{bga80},{bgp80};
                             {aba_r83},{abp_r83},{bga83},{bgp83};};
                    
                    plot_result(app,app.Intraamp,sddist{end},intraamp,['Amp vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Intraphase,sddist{end},intraphase,['Phase vs. Source-Detector Distance', ': ', name],'ro')
                    plot_result(app,app.Refamp,sddist{end},refamp,['Amp vs. Source-Detector Distance', ': ', name],'bo')
                    plot_result(app,app.Refphase,sddist{end},refphase,['Phase vs. Source-Detector Distance', ': ', name],'bo')
                    
                    lines = findobj(gcbf,'Tag','L');
                    delete(lines);
            end
%             app.UIFigure.HandleVisibility = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Intraamp = uiaxes(app.UIFigure);
            title(app.Intraamp, 'Amp vs. Source-Detector Distance')
            app.Refamp = uiaxes(app.UIFigure);
            title(app.Refamp, 'Apm vs. Source-Detector Distance')
            app.Intraphase = uiaxes(app.UIFigure);
            title(app.Intraphase, 'Phase vs. Source-Detector Distance')
            app.Refphase = uiaxes(app.UIFigure);
            title(app.Refphase, 'Phase vs. Source-Detector Distance')
            app.Motionplotamp = uiaxes(app.UIFigure);
            title(app.Motionplotamp, 'Amplitude')
            app.Motionplotpha = uiaxes(app.UIFigure);
            title(app.Motionplotpha, 'Phase')
            app.PlotmotionCheckBox = uicheckbox(app.UIFigure);
            app.PlotmotionCheckBox.Text = 'Plot motion';
            app.LoadFolder = uibutton(app.UIFigure, 'push');
            app.LoadFolder.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderPushed, true);
            app.LoadFolder.Text = 'Load';
            app.Motioncheck = uibutton(app.UIFigure, 'push');
            app.Motioncheck.ButtonPushedFcn = createCallbackFcn(app, @MotioncheckButtonPushed, true);
            app.Motioncheck.Text = 'Motion';
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.GeoDropDown = uidropdown(app.UIFigure);
            app.GeoDropDown.Items = {'WashU', 'UConn', 'sys2', ''};
            app.GeoDropDown.ValueChangedFcn = createCallbackFcn(app, @GeoDropDownValueChanged, true);
            app.GeoDropDown.Value = 'WashU';
            app.Mismatchcheck = uibutton(app.UIFigure, 'push');
            app.Mismatchcheck.ButtonPushedFcn = createCallbackFcn(app, @MismatchcheckButtonPushed, true);
            app.Mismatchcheck.Text = 'Mismatch';
            app.FilenameDropDownLabel = uilabel(app.UIFigure);
            app.FilenameDropDownLabel.Text = 'File name';
            app.FilenameDropDown = uidropdown(app.UIFigure);
            app.FilenameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FilenameDropDownOpening, true);
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilenameDropDownValueChanged, true);
            app.PlotindexDropDownLabel = uilabel(app.UIFigure);
            app.PlotindexDropDownLabel.Text = 'Plot index';
            app.PlotindexDropDown = uidropdown(app.UIFigure);
            app.PlotindexDropDown.Items = {'1 vs. 2', '2 vs. 3'};
            app.PlotindexDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotindexDropDownValueChanged, true);
            app.PlotindexDropDown.Value = '1 vs. 2';
            app.WavelengthDropDownLabel = uilabel(app.UIFigure);
            app.WavelengthDropDownLabel.Text = 'Wavelength';
            app.WavelengthDropDown = uidropdown(app.UIFigure);
            app.WavelengthDropDown.Items = {'740mm', '780mm', '800mm', '830mm'};
            app.WavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @WavelengthDropDownValueChanged, true);
            app.WavelengthDropDown.Value = '740mm';
            app.IntralipidDropDownLabel = uilabel(app.UIFigure);
            app.IntralipidDropDownLabel.Text = 'Intralipid';
            app.IntralipidDropDown = uidropdown(app.UIFigure);
            app.Calibration = uibutton(app.UIFigure, 'push');
            app.Calibration.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.Calibration.Text = 'Calibration';
            app.SourceWavelengthsButtonGroup = uibuttongroup(app.UIFigure);
            app.SourceWavelengthsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceWavelengthsButtonGroupSelectionChanged, true);
            app.SourceWavelengthsButtonGroup.Title = 'Source Wavelengths';
            app.nm740Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm740Button.Text = '740nm';
            app.nm740Button.Value = true;
            app.nm780Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm780Button.Text = '780nm';
            app.nm808Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm808Button.Text = '808nm';
            app.nm830Button = uiradiobutton(app.SourceWavelengthsButtonGroup);
            app.nm830Button.Text = '830nm';
            app.muaTextAreaLabel = uilabel(app.UIFigure);
            app.muaTextAreaLabel.Text = 'mua:';
            app.intramuaTextArea = uitextarea(app.UIFigure);
            app.muaTextArea_2Label = uilabel(app.UIFigure);
            app.muaTextArea_2Label.Text = 'mua:';
            app.refmuaTextArea = uitextarea(app.UIFigure);
            app.muspTextAreaLabel = uilabel(app.UIFigure);
            app.muspTextAreaLabel.Text = 'musp:';
            app.intramuspTextArea = uitextarea(app.UIFigure);
            app.muspTextArea_2Label = uilabel(app.UIFigure);
            app.muspTextArea_2Label.Text = 'musp:';
            app.refmuspTextArea = uitextarea(app.UIFigure);
            app.AbsorptionScatteringCoefficientsLabel = uilabel(app.UIFigure);
            app.AbsorptionScatteringCoefficientsLabel.Text = 'Absorption & Scattering Coefficients';
            app.IntralipidLabel = uilabel(app.UIFigure);
            app.IntralipidLabel.Text = 'Intralipid';
            app.RefTissueLabel = uilabel(app.UIFigure);
            app.RefTissueLabel.Text = 'Ref Tissue';
            app.Smooth = uibutton(app.UIFigure, 'push');
            app.Smooth.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.Smooth.Text = 'Smooth';
            app.SelectpointsButton = uibutton(app.UIFigure, 'push');
            app.SelectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointsButtonPushed, true);
            app.SelectpointsButton.Text = 'Select points';
            app.RemovepointsButton = uibutton(app.UIFigure, 'push');
            app.RemovepointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepointsButtonPushed, true);
            app.RemovepointsButton.Text = 'Remove points';
            app.PhaseshiftButton = uibutton(app.UIFigure, 'push');
            app.PhaseshiftButton.Text = 'Phase shift';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end